classdef AnalysisPipelineBuilder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisPipelineBuilderUIFigure  matlab.ui.Figure
        WorkingDataGroupPanel           matlab.ui.container.Panel
        CellsListBoxLabel               matlab.ui.control.Label
        CellsListBox_W                  matlab.ui.control.ListBox
        SymphonydatasetsListBoxLabel    matlab.ui.control.Label
        SymphonydatasetsListBox_W       matlab.ui.control.ListBox
        OtherdataListBoxLabel           matlab.ui.control.Label
        OtherdataListBox_W              matlab.ui.control.ListBox
        AnimalsListBoxLabel             matlab.ui.control.Label
        AnimalsListBox_W                matlab.ui.control.ListBox
        TotalsPanel_2                   matlab.ui.container.Panel
        animalsEditField_2Label         matlab.ui.control.Label
        animalsEditField_W              matlab.ui.control.EditField
        cellsEditField_3Label           matlab.ui.control.Label
        cellsEditField_W                matlab.ui.control.EditField
        epochsEditField_4Label          matlab.ui.control.Label
        epochsEditField_W               matlab.ui.control.EditField
        eyesEditField_2Label            matlab.ui.control.Label
        eyesEditField_W                 matlab.ui.control.EditField
        datasetsEditField_4Label        matlab.ui.control.Label
        datasetsEditField_W             matlab.ui.control.EditField
        otherfilesEditField_4Label      matlab.ui.control.Label
        otherfilesEditField_W           matlab.ui.control.EditField
        SavetofileButton                matlab.ui.control.Button
        ClearWorkingGroupButton         matlab.ui.control.Button
        AttachDataGrouptopipelineButton  matlab.ui.control.Button
        DitchanimalButton               matlab.ui.control.Button
        DitchcellButton                 matlab.ui.control.Button
        DitchdatasetButton              matlab.ui.control.Button
        DitchotherdataButton            matlab.ui.control.Button
        BuildanalysisPanel              matlab.ui.container.Panel
        FuncTypeDropDown_1              matlab.ui.control.DropDown
        FuncNameDropDown_1              matlab.ui.control.DropDown
        FunctiontypeandnameLabel        matlab.ui.control.Label
        StepsSpinnerLabel               matlab.ui.control.Label
        StepsSpinner                    matlab.ui.control.Spinner
        RunanalysisButton_1             matlab.ui.control.Button
        WritetodatabaseButton_1         matlab.ui.control.Button
        Exporttoh5Button_1              matlab.ui.control.Button
        OverwriteDBCheckBox_1           matlab.ui.control.CheckBox
        SaveButton_1                    matlab.ui.control.Button
        EditButton_1                    matlab.ui.control.Button
        ParametersetLabel               matlab.ui.control.Label
        ParamSetDropDown_1              matlab.ui.control.DropDown
        OverwriteLabel                  matlab.ui.control.Label
        LabelStep_3                     matlab.ui.control.Label
        LabelStep_1                     matlab.ui.control.Label
        ResultloadedLamp_1              matlab.ui.control.Lamp
        LoadfromdatabaseButton_1        matlab.ui.control.Button
        ResultloadedLabel               matlab.ui.control.Label
        ComputeloadLabel                matlab.ui.control.Label
        SaveresultLabel                 matlab.ui.control.Label
        OverwriteH5CheckBox_1           matlab.ui.control.CheckBox
        FuncNameDropDown_2              matlab.ui.control.DropDown
        RunanalysisButton_2             matlab.ui.control.Button
        WritetodatabaseButton_2         matlab.ui.control.Button
        Exporttoh5Button_2              matlab.ui.control.Button
        OverwriteDBCheckBox_2           matlab.ui.control.CheckBox
        SaveButton_2                    matlab.ui.control.Button
        EditButton_2                    matlab.ui.control.Button
        ParamSetDropDown_2              matlab.ui.control.DropDown
        LabelStep_2                     matlab.ui.control.Label
        ResultloadedLamp_2              matlab.ui.control.Lamp
        LoadfromdatabaseButton_2        matlab.ui.control.Button
        OverwriteH5CheckBox_2           matlab.ui.control.CheckBox
        AttachanalysisstepstopipelineButton  matlab.ui.control.Button
        FuncTypeDropDown_3              matlab.ui.control.DropDown
        FuncNameDropDown_3              matlab.ui.control.DropDown
        ParamSetDropDown_3              matlab.ui.control.DropDown
        EditButton_3                    matlab.ui.control.Button
        SaveButton_3                    matlab.ui.control.Button
        RunanalysisButton_3             matlab.ui.control.Button
        LoadfromdatabaseButton_3        matlab.ui.control.Button
        ResultloadedLamp_3              matlab.ui.control.Lamp
        WritetodatabaseButton_3         matlab.ui.control.Button
        Exporttoh5Button_3              matlab.ui.control.Button
        OverwriteDBCheckBox_3           matlab.ui.control.CheckBox
        OverwriteH5CheckBox_3           matlab.ui.control.CheckBox
        LabelStep_4                     matlab.ui.control.Label
        FuncTypeDropDown_4              matlab.ui.control.DropDown
        FuncNameDropDown_4              matlab.ui.control.DropDown
        ParamSetDropDown_4              matlab.ui.control.DropDown
        EditButton_4                    matlab.ui.control.Button
        SaveButton_4                    matlab.ui.control.Button
        RunanalysisButton_4             matlab.ui.control.Button
        LoadfromdatabaseButton_4        matlab.ui.control.Button
        ResultloadedLamp_4              matlab.ui.control.Lamp
        WritetodatabaseButton_4         matlab.ui.control.Button
        Exporttoh5Button_4              matlab.ui.control.Button
        OverwriteDBCheckBox_4           matlab.ui.control.CheckBox
        OverwriteH5CheckBox_4           matlab.ui.control.CheckBox
        LabelStep_5                     matlab.ui.control.Label
        FuncTypeDropDown_5              matlab.ui.control.DropDown
        FuncNameDropDown_5              matlab.ui.control.DropDown
        ParamSetDropDown_5              matlab.ui.control.DropDown
        EditButton_5                    matlab.ui.control.Button
        SaveButton_5                    matlab.ui.control.Button
        RunanalysisButton_5             matlab.ui.control.Button
        LoadfromdatabaseButton_5        matlab.ui.control.Button
        ResultloadedLamp_5              matlab.ui.control.Lamp
        WritetodatabaseButton_5         matlab.ui.control.Button
        Exporttoh5Button_5              matlab.ui.control.Button
        OverwriteDBCheckBox_5           matlab.ui.control.CheckBox
        OverwriteH5CheckBox_5           matlab.ui.control.CheckBox
        LabelStep_6                     matlab.ui.control.Label
        FuncTypeDropDown_6              matlab.ui.control.DropDown
        FuncNameDropDown_6              matlab.ui.control.DropDown
        ParamSetDropDown_6              matlab.ui.control.DropDown
        EditButton_6                    matlab.ui.control.Button
        SaveButton_6                    matlab.ui.control.Button
        RunanalysisButton_6             matlab.ui.control.Button
        LoadfromdatabaseButton_6        matlab.ui.control.Button
        ResultloadedLamp_6              matlab.ui.control.Lamp
        WritetodatabaseButton_6         matlab.ui.control.Button
        Exporttoh5Button_6              matlab.ui.control.Button
        OverwriteDBCheckBox_6           matlab.ui.control.CheckBox
        OverwriteH5CheckBox_6           matlab.ui.control.CheckBox
        LabelStep_7                     matlab.ui.control.Label
        FuncTypeDropDown_7              matlab.ui.control.DropDown
        FuncNameDropDown_7              matlab.ui.control.DropDown
        ParamSetDropDown_7              matlab.ui.control.DropDown
        EditButton_7                    matlab.ui.control.Button
        SaveButton_7                    matlab.ui.control.Button
        RunanalysisButton_7             matlab.ui.control.Button
        LoadfromdatabaseButton_7        matlab.ui.control.Button
        ResultloadedLamp_7              matlab.ui.control.Lamp
        WritetodatabaseButton_7         matlab.ui.control.Button
        Exporttoh5Button_7              matlab.ui.control.Button
        OverwriteDBCheckBox_7           matlab.ui.control.CheckBox
        OverwriteH5CheckBox_7           matlab.ui.control.CheckBox
        LabelStep_8                     matlab.ui.control.Label
        FuncTypeDropDown_8              matlab.ui.control.DropDown
        FuncNameDropDown_8              matlab.ui.control.DropDown
        ParamSetDropDown_8              matlab.ui.control.DropDown
        EditButton_8                    matlab.ui.control.Button
        SaveButton_8                    matlab.ui.control.Button
        RunanalysisButton_8             matlab.ui.control.Button
        LoadfromdatabaseButton_8        matlab.ui.control.Button
        ResultloadedLamp_8              matlab.ui.control.Lamp
        WritetodatabaseButton_8         matlab.ui.control.Button
        Exporttoh5Button_8              matlab.ui.control.Button
        OverwriteDBCheckBox_8           matlab.ui.control.CheckBox
        OverwriteH5CheckBox_8           matlab.ui.control.CheckBox
        LabelStep_9                     matlab.ui.control.Label
        FuncTypeDropDown_9              matlab.ui.control.DropDown
        FuncNameDropDown_9              matlab.ui.control.DropDown
        ParamSetDropDown_9              matlab.ui.control.DropDown
        EditButton_9                    matlab.ui.control.Button
        SaveButton_9                    matlab.ui.control.Button
        RunanalysisButton_9             matlab.ui.control.Button
        LoadfromdatabaseButton_9        matlab.ui.control.Button
        ResultloadedLamp_9              matlab.ui.control.Lamp
        WritetodatabaseButton_9         matlab.ui.control.Button
        Exporttoh5Button_9              matlab.ui.control.Button
        OverwriteDBCheckBox_9           matlab.ui.control.CheckBox
        OverwriteH5CheckBox_9           matlab.ui.control.CheckBox
        FuncTypeDropDown_2              matlab.ui.control.DropDown
        PipelinePanel                   matlab.ui.container.Panel
        SwitchpipelineButton            matlab.ui.control.Button
        RunButton_Full                  matlab.ui.control.Button
        SaveasnewpipelineButton         matlab.ui.control.Button
        LoadFirstButtonGroup            matlab.ui.container.ButtonGroup
        LoadavailableresultsButton      matlab.ui.control.RadioButton
        OverwriteallButton              matlab.ui.control.RadioButton
        LoaddataPanel                   matlab.ui.container.Panel
        SymphonydatasetsListBox_2Label  matlab.ui.control.Label
        SymphonydatasetsListBox_L       matlab.ui.control.ListBox
        OtherdataListBox_2Label         matlab.ui.control.Label
        OtherdataListBox_L              matlab.ui.control.ListBox
        AnimalsListBox_2Label           matlab.ui.control.Label
        AnimalsListBox_L                matlab.ui.control.ListBox
        TotalsPanel_3                   matlab.ui.container.Panel
        animalsEditField_2Label_2       matlab.ui.control.Label
        animalsEditField_L              matlab.ui.control.EditField
        cellsEditField_3Label_2         matlab.ui.control.Label
        cellsEditField_L                matlab.ui.control.EditField
        epochsEditField_4Label_2        matlab.ui.control.Label
        epochsEditField_L               matlab.ui.control.EditField
        eyesEditField_2Label_2          matlab.ui.control.Label
        eyesEditField_L                 matlab.ui.control.EditField
        datasetsEditField_4Label_2      matlab.ui.control.Label
        datasetsEditField_L             matlab.ui.control.EditField
        otherfilesEditField_4Label_2    matlab.ui.control.Label
        otherfilesEditField_L           matlab.ui.control.EditField
        AlltoWGButton                   matlab.ui.control.Button
        ClearLoadedButton               matlab.ui.control.Button
        LoadqueryButton                 matlab.ui.control.Button
        QueryeditorButton               matlab.ui.control.Button
        LoadDataGroupButton             matlab.ui.control.Button
        LoadcellnamesButton             matlab.ui.control.Button
        LoadsinglecellButton            matlab.ui.control.Button
        cell_idEditField                matlab.ui.control.EditField
        AttachotherdataButton_2         matlab.ui.control.Button
        FilterbyepochfunctionButton     matlab.ui.control.Button
        AnimaltoWGButton                matlab.ui.control.Button
        CelltoWGButton                  matlab.ui.control.Button
        DatasettoWGButton               matlab.ui.control.Button
        OtherfilestoWGButton            matlab.ui.control.Button
        MergecompatibleLampLabel        matlab.ui.control.Label
        MergecompatibleLamp             matlab.ui.control.Lamp
        AttachquerytopipelineButton     matlab.ui.control.Button
        QueryTextAreaLabel              matlab.ui.control.Label
        QueryTextArea                   matlab.ui.control.TextArea
        CellsListBox_2Label             matlab.ui.control.Label
        CellsListBox_L                  matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        loadedQuery = []; % current query result for the "loaded" panel
        loadedQueryToDisplay = []; % loaded query further sub-divided by what is clicked in the list box
        workingQuery = []; % same as above but for working panel
        workingQueryToDisplay = []; % same as above but for working panel
        searchTable = []; % joined table on which to run queries
        analysisParams = {}; % param struct for each analysis step (or empty)
        loadedQueryState = []; % QueryState (from query builder) that is currently loaded
        pipeline =[]; % Pipeline that we are currently working on
        exportPreferences = {} % exportState for each analysis step (or empty)
        analysisOutputByStep = {} % cell array of the analysisOutput for each step
        isAnalysisRunning = false; % bool to ccheck whether analysis is currently running
    end
    
    properties (Access = public)
        analysisOutput = []; % struct from last run analysis, including entities and results
    end
    
    methods (Access = private)
        
        function updateLoadedListBoxes(app,caller)
            if isempty(app.loadedQueryToDisplay) || app.loadedQueryToDisplay.count==0
                set(app.AnimalsListBox_L,'items',{});
                set(app.CellsListBox_L,'items',{});
                set(app.SymphonydatasetsListBox_L,'items',{});
                set(app.OtherdataListBox_L,'items',{});
                
                set(app.animalsEditField_L,'value',num2str(0));
                set(app.cellsEditField_L,'value',num2str(0));
                set(app.datasetsEditField_L,'value',num2str(0));
                set(app.eyesEditField_L, 'value', num2str(0));
                set(app.epochsEditField_L, 'value', num2str(0));
            else      
                if isempty(caller)
                    animals = sl.Animal & app.loadedQueryToDisplay.proj;
                    set(app.AnimalsListBox_L,'items',string(num2str(fetchn(animals,'animal_id'))))
                end
                
                if isempty(caller) || strcmp(caller, 'animals')
                    cells = sl.SymphonyRecordedCell & app.loadedQueryToDisplay.proj;
                    set(app.CellsListBox_L,'items',fetchn(cells,'cell_id'));
                end
                
                datasets = sl.Dataset & app.loadedQueryToDisplay.proj;
                set(app.SymphonydatasetsListBox_L,'items',fetchn(datasets,'dataset_name'));  
                
                eyes = sl.Eye & app.loadedQueryToDisplay.proj; %TODO not quite right, includes non-recorded eyes

                %epochs = sl.Epoch & app.loadedQueryToDisplay.proj;
                 
                %set counts only for empty caller
                if isempty(caller)
                    set(app.animalsEditField_L,'value',num2str(animals.count));
                    set(app.cellsEditField_L,'value',num2str(cells.count));
                    set(app.datasetsEditField_L,'value',num2str(datasets.count));
                    set(app.eyesEditField_L, 'value', num2str(eyes.count));
                    ep_count = getEpochsInQuery(app.loadedQuery);
                    set(app.epochsEditField_L, 'value', num2str(ep_count));
                end
            end
        end
        
        function updateWorkingListBoxes(app,caller)
            if isempty(app.workingQueryToDisplay) || app.workingQueryToDisplay.count==0
                set(app.AnimalsListBox_W,'items',{});
                set(app.CellsListBox_W,'items',{});
                set(app.SymphonydatasetsListBox_W,'items',{});
                set(app.OtherdataListBox_W,'items',{});
                
                set(app.animalsEditField_W,'value',num2str(0));
                set(app.cellsEditField_W,'value',num2str(0));
                set(app.datasetsEditField_W,'value',num2str(0));
                set(app.eyesEditField_W, 'value', num2str(0));
                set(app.epochsEditField_W, 'value', num2str(0));
            else     
                if isempty(caller)
                    animals = sl.Animal & app.workingQueryToDisplay.proj;
                    set(app.AnimalsListBox_W,'items',string(num2str(fetchn(animals,'animal_id'))))
                end
                
                if isempty(caller) || strcmp(caller, 'animals')
                    cells = sl.SymphonyRecordedCell & app.workingQueryToDisplay.proj;
                    set(app.CellsListBox_W,'items',fetchn(cells,'cell_id'));
                end
               
                datasets = sl.Dataset & app.workingQueryToDisplay.proj;               
                set(app.SymphonydatasetsListBox_W,'items',fetchn(datasets,'dataset_name'));
                
                eyes = sl.Eye & app.workingQueryToDisplay.proj; %TODO not quite right, includes non-recorded eyes

                %epochs = sl.Epoch & app.workingQueryToDisplay.proj;
                
                if isempty(caller) || contains(caller,'_set')
                    animals = sl.Animal & app.workingQuery.proj;
                    cells = sl.SymphonyRecordedCell & app.workingQuery.proj;

                    set(app.animalsEditField_W,'value',num2str(animals.count));
                    set(app.cellsEditField_W,'value',num2str(cells.count));
                    set(app.datasetsEditField_W,'value',num2str(datasets.count));
                    set(app.eyesEditField_W, 'value', num2str(eyes.count));
                    ep_count = getEpochsInQuery(app.workingQuery);
                    set(app.epochsEditField_W, 'value', num2str(ep_count));
                end
            end
        end
        
        function result = checkMergeCompatibility(app)
            if isempty(app.searchTable)
                result = false;
                set(app.MergecompatibleLamp,'color','white');
            elseif isempty(app.workingQuery) || isequal(app.workingQuery.header, app.searchTable.header)
                result = true;
                set(app.MergecompatibleLamp,'color','green');
            else
                result = false;
                set(app.MergecompatibleLamp,'color','red');
            end
        end
        
        function addToWorkingGroup(app, queryToAdd)
            if app.checkMergeCompatibility()
                if isempty(app.workingQuery)
                    app.workingQuery = queryToAdd;
                else
                    primaryKeyStruct_W = app.workingQuery.fetch();
                    primaryKeyStruct_L = queryToAdd.fetch();
                    mergedStruct = [primaryKeyStruct_W; primaryKeyStruct_L];
                    app.workingQuery = app.searchTable & mergedStruct;
                    disp('Merge success!');
                end
                app.workingQueryToDisplay = app.workingQuery;
                app.updateWorkingListBoxes([]);                
                app.checkMergeCompatibility()
                
                %clear out loaded results
                Nsteps = get(app.StepsSpinner,'value');
                for i=1:Nsteps
                    app.analysisOutputByStep{i} = [];
                    set(app.(['ResultloadedLamp_' num2str(i)]), 'color', 'white');                    
                end
                
            else
                disp('Cannot merge incompatible DataGroups.');
            end
            
        end
        
%         function [ep_count, ep_in_datasets] = getEpochCountInQuery(app, query)
%             ep_q = sl.Epoch & query;
%             dataset_structs = query.fetch('epoch_ids');
%             fullRestrictionStruct = [];
%             for i=1:length(dataset_structs)
%                 epid = dataset_structs(i).epoch_ids;
%                 Nep = length(epid);
%                 thisRestrictionStruct = repmat(dataset_structs(i), Nep, 1);
%                 for n=1:Nep
%                     thisRestrictionStruct(n).epoch_number = epid(n);
%                 end
%                 fullRestrictionStruct = [fullRestrictionStruct; thisRestrictionStruct];
%             end
%             ep_in_datasets = ep_q & fullRestrictionStruct;
%             ep_count = ep_in_datasets.count;
%         end
        
        function updateFunctionsMenu(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            func_menu_name = ['FuncNameDropDown' caller_num];
            
            switch event.Value
                case 'Epoch'
                    d = dir([getenv('user_epoch_functions_folder') '*.m']);
                case 'Dataset'
                    d = dir([getenv('user_dataset_functions_folder') '*.m']);
                case 'Cell'
                    d = dir([getenv('user_cell_functions_folder') '*.m']);
                case 'Multi-cell'
                    d = dir([getenv('user_multicell_functions_folder') '*.m']);                    
            end
            N = length(d);
            fnames = cell(1,N);
            for i=1:N
                fnames{i} = strtok(d(i).name,'.');
            end
            set(app.(func_menu_name), 'items', fnames);  
            app.updateParamsMenu(event);
        end
        
        function runAnalysisFunction(app, event)
            app.isAnalysisRunning = true;
            %TODO - finish this
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            func_menu_name = ['FuncNameDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];

            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            
            %get params
            if length(app.analysisParams) >= caller_num_int && ...
                    ~isempty(app.analysisParams{caller_num_int})
                P = app.analysisParams{caller_num_int};
            else
                P = [];
            end
            
            curTitle = get(app.BuildanalysisPanel, 'title');
            set(app.BuildanalysisPanel, 'title', 'running analysis...');
            drawnow;
                        
            %check if result already stored
            if isequal(get(app.(['ResultloadedLamp' caller_num]), 'color'), [1, 1, 0])
                disp('loading partial results instead of recalculating');
                previousLoad = app.analysisOutputByStep{caller_num_int};
            else
                previousLoad = [];
            end
            
            app.analysisOutput = runAnalysis(app.pipeline, funcType, funcName, P, app.workingQuery, previousLoad);
            
            app.analysisOutputByStep{caller_num_int} = app.analysisOutput;
            set(app.(['ResultloadedLamp' caller_num]), 'color', 'green');
            assignin('base','analysisOutput',app.analysisOutput);            
            set(app.BuildanalysisPanel, 'title', curTitle);
            drawnow;
            app.isAnalysisRunning = false;
        end
        
        function updateParamsMenu(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            func_menu_name = ['FuncNameDropDown' caller_num];
            param_menu_name = ['ParamSetDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];

            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            
            switch funcType
                case 'Epoch'
                    param_folder = [getenv('user_epoch_functions_folder') funcName '_params' filesep];
                case 'Dataset'
                    param_folder = [getenv('user_dataset_functions_folder') funcName '_params' filesep];
                case 'Cell'
                    param_folder = [getenv('user_cell_functions_folder') funcName '_params' filesep];
                case 'Multi-cell'
                    param_folder = [getenv('user_multicell_functions_folder') funcName '_params' filesep];
            end            
            
            if exist(param_folder, 'dir')
                d = dir([param_folder,'*.mat']);
                L = length(d);
                paramSets = cell(1,L);
                for i=1:L
                    paramSets{i} = strtok(d(i).name,'.');
                end
                set(app.(param_menu_name), 'items', paramSets);
            else
                set(app.(param_menu_name), 'items', {});
            end     
            
            app.loadParamSet(event);
        end
        
        function loadParamSet(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            func_menu_name = ['FuncNameDropDown' caller_num];
            param_menu_name = ['ParamSetDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];
           
            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            paramSet_name = get(app.(param_menu_name), 'value');
            
            switch funcType
                case 'Epoch'
                    param_folder = [getenv('user_epoch_functions_folder') funcName '_params' filesep];
                case 'Dataset'
                    param_folder = [getenv('user_dataset_functions_folder') funcName '_params' filesep];
                case 'Cell'
                    param_folder = [getenv('user_cell_functions_folder') funcName '_params' filesep];
                case 'Mulit-cell'
                    param_folder = [getenv('user_multicell_functions_folder') funcName '_params' filesep];
            end
            if ~isempty(paramSet_name)
                if exist(param_folder, 'dir')
                    disp('loading');
                    try
                        load([param_folder paramSet_name '.mat'], 'P'); %structure P stores the params
                        fprintf('Param set %s loaded.\n', paramSet_name);
                        app.analysisParams{caller_num_int} = P;
                    catch ME
                        app.analysisParams{caller_num_int} = []; %set to empty
                        fprintf('Error loading param set %s\n', [param_folder paramSet_name '.mat']);
                        %rethrow(ME);
                    end
                    
                else
                    app.analysisParams{caller_num_int} = []; %set to empty
                    fprintf('No param folder folder %s\n', param_folder);
                end
            end
            %clear stored results and export prefs
            app.analysisOutput = struct;
            app.analysisOutputByStep{caller_num_int} = [];
            app.exportPreferences{caller_num_int} = [];
            set(app.(['ResultloadedLamp' caller_num]), 'color', 'white');
        end
        
        function saveParamSet(app,event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            func_menu_name = ['FuncNameDropDown' caller_num];
            param_menu_name = ['ParamSetDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];
           
            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            paramSet_name = get(app.(param_menu_name), 'value');
            
            switch funcType
                case 'Epoch'
                    param_folder = [getenv('user_epoch_functions_folder') funcName '_params' filesep];
                case 'Dataset'
                    param_folder = [getenv('user_dataset_functions_folder') funcName '_params' filesep];
                case 'Cell'
                    param_folder = [getenv('user_cell_functions_folder') funcName '_params' filesep];
                case 'Mulit-cell'
                    param_folder = [getenv('user_multicell_functions_folder') funcName '_params' filesep];
            end            
            
            if length(app.analysisParams) >= caller_num_int && ~isempty(app.analysisParams{caller_num_int})
                if ~exist(param_folder, 'dir')
                    fprintf('Making new parameter folder for %s\n', funcName);
                    mkdir(param_folder);
                end
                P = app.analysisParams{caller_num_int};
                com.mathworks.mwswing.MJFileChooserPerPlatform.setUseSwingDialog(1);
                %this fixed crash on replacing same param set file
                if isempty(paramSet_name)
                    [fname, fpath] = uiputfile('*.mat','Save new parameter set as...',param_folder);
                    
                else
                    [fname, fpath] = uiputfile('*.mat','Save new parameter set as...',[param_folder paramSet_name]);                    
                end
                drawnow; pause(2);
                %this fixed crash on replacing same param set file
                if fname
                    save([fpath fname], 'P');
                    app.updateParamsMenu(event);
                end
            end           
        end
        
        function editParamsTable(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            
            if length(app.analysisParams) >= caller_num_int && ...
                    ~isempty(app.analysisParams{caller_num_int})
                P = app.analysisParams{caller_num_int};
            else
                P = struct; 
            end
            
            T = table('Size',[10,2],'VariableTypes',{'string','string'});
            T.Properties.VariableNames = {'Parameter','Value'};
            
            if ~isempty(P)
                param_names = fieldnames(P);
                for i=1:length(param_names)
                    if ~isempty(param_names{i})
                        T{i,1} = {num2str(param_names{i})};
                        %test for vector
                        val = P.(param_names{i});
                        if iscell(val)
                            V = '{';
                            for n=1:length(val)
                                tempVal = val{n};
                                if ischar(tempVal)
                                    V = [V '''' tempVal '''', ','];
                                elseif isnumeric(tempVal) && ~isscalar(tempVal)
                                    V = [V mat2str(tempVal), ','];
                                else
                                    V = [V num2str(tempVal) ','];
                                end
                            end
                            V = [V(1:end-1), '}'];
                            T{i,2} = {V};
                        elseif isnumeric(val) && ~isscalar(val)
                            T{i,2} = {mat2str(val)};
                        else
                            T{i,2} = {num2str(val)};
                        end
                    end
                end                
            end
                                                
            h = AppDataHandle;
            paramSetName = get(app.(['ParamSetDropDown' caller_num]), 'value');        
            paramTable_dlg = ParamTable_dlg(T,paramSetName,h);
            waitfor(paramTable_dlg);
            Tnew = h.data.table;
            
            Pnew = struct;
            for i=1:height(Tnew)
                if ~ismissing(Tnew{i,1}) && ~isempty(Tnew{i,1}) ...
                        && ~isempty(Tnew{i,1}) && ~strcmp(Tnew{i,1}, "")
                    try
                        val = evalin("base", Tnew{i,2});
                    catch
                        val = Tnew{i,2};
                    end
                    Pnew.(Tnew{i,1}) = val;
                end
            end
            app.analysisParams{caller_num_int} = Pnew;
            
            
%             if isempty(param_names) %starting from scratch
%                 Ncols = 10; %hopefully this is enough parameters
%                 blank = true;
%             else
%                 Ncols = length(param_names);
%                 blank = false;
%             end
%             T = cell2table(cell(2,10));
%             T.Properties.RowNames = {'Parameter', 'Value'};
%             T.Properties.VariableNames = {};
%             
%             if ~blank
%                 for i=1:Ncols
%                     T{1,i} = {param_names{i}};
%                     T{2,i} = P.(param_names{i});
%                 end
%             end
%             
%             f = uifigure;
%             uitable(f,'Data',T,'ColumnEditable',true);
        end
        
        function updateAnalysisStepVisibility(app)           
            value = app.StepsSpinner.Value;
            N_entries = 9;
            itemList = {'FuncTypeDropDown_' ...
                'FuncNameDropDown_', ...
                'ParamSetDropDown_', ...
                'EditButton_', ...
                'SaveButton_', ...
                'ResultloadedLamp_',...
                'RunanalysisButton_', ...
                'WritetodatabaseButton_', ...
                'LoadfromdatabaseButton_', ...
                'OverwriteDBCheckBox_', ...
                'OverwriteH5CheckBox_', ...
                'Exporttoh5Button_', ...
                'LabelStep_', ...
                };
            for e=1:value
                for i = 1:length(itemList)  
                    set(app.([itemList{i} num2str(e)]), 'Visible', true);
                end
            end
            for e=value+1:N_entries
                for i = 1:length(itemList)  
                    set(app.([itemList{i} num2str(e)]), 'Visible', false);
                end
            end
        end  

        function loadPipeline(app)
           %set panel names
           set(app.LoaddataPanel, 'title', [app.pipeline ': load data']);
           set(app.WorkingDataGroupPanel, 'title', [app.pipeline ': working DataGroup']);
           set(app.BuildanalysisPanel, 'title', [app.pipeline ': build analysis']);
           set(app.PipelinePanel, 'title', [app.pipeline ': pipeline control']);

           pipeline_dir = [getenv('pipelines_folder') app.pipeline filesep];
           if exist(pipeline_dir, 'dir') %load query
               if exist([pipeline_dir 'query.mat'], 'file')
                   load([pipeline_dir 'query.mat'],'queryState');
                   app.loadedQueryState = queryState;
                   app.loadedQuery = parseQueryStruct(queryState);
                   set(app.QueryTextArea, 'value', parseQueryStruct(queryState, true));
                   commandStr = 'app.searchTable=';
                   currentTables = queryState.currentTables;
                   N = length(currentTables);
                   for i=1:N
                       commandStr = [commandStr, 'sl.' currentTables{i} '*'];
                   end
                   %remove trailing *
                   commandStr = [commandStr(1:end-1) ,';'];
                   eval(commandStr);
               else
                   set(app.QueryTextArea, 'value', '');
                   app.loadedQueryState =[];
                   app.loadedQuery = [];                   
               end
               
               if exist([pipeline_dir 'dataGroup.mat'], 'file') %load dataGroup
                    load([pipeline_dir 'dataGroup.mat'],'workingGroup');
                    app.searchTable = workingGroup.searchTable;                    
                    app.workingQuery = workingGroup.searchTable & workingGroup.primaryKeyStruct;
               else
                   app.workingQuery = [];
               end
               
               if exist([pipeline_dir 'analysisSteps.mat'], 'file') %load analysis steps
                   load([pipeline_dir 'analysisSteps.mat'],'analysisSteps', 'exportStates');
                   Nsteps = length(analysisSteps);
                   set(app.StepsSpinner, 'value', Nsteps);                   
                   for i=1:Nsteps
                       set(app.(['FuncTypeDropDown_' num2str(i)]), 'value', analysisSteps(i).funcType);
                       event.Source.Tag = ['FuncTypeDropDown_' num2str(i)];
                       event.Value = get(app.(['FuncTypeDropDown_' num2str(i)]), 'value');
                       app.(['FuncTypeDropDown_' num2str(i) 'ValueChanged'])(event);
                       
                       set(app.(['FuncNameDropDown_' num2str(i)]), 'value', analysisSteps(i).funcName);                       
                       app.(['FuncNameDropDown_' num2str(i) 'ValueChanged'])(event);
                       set(app.(['ParamSetDropDown_' num2str(i)]), 'value', analysisSteps(i).paramSet);
                       app.(['ParamSetDropDown_' num2str(i) 'ValueChanged'])(event);
                       
                       set(app.(['OverwriteDBCheckBox_' num2str(i)]), 'value', analysisSteps(i).overwrite_DB);
                       set(app.(['OverwriteH5CheckBox_' num2str(i)]), 'value', analysisSteps(i).overwrite_H5);
                       
                       if ~isempty(exportStates{i})
                           app.exportPreferences{i} = exportStates{i};
                       end
                   end
               else
                  Nsteps = 1;
                  set(app.StepsSpinner, 'value', Nsteps);
                  event.Source.Tag = 'FuncTypeDropDown_1';
                  event.Value = 'Epoch';
                  app.FuncTypeDropDown_1ValueChanged(event);
                  app.FuncNameDropDown_1ValueChanged(event);
               end
           else
               set(app.QueryTextArea, 'value', '');
               app.loadedQueryState =[];
               app.loadedQuery = [];
               app.workingQuery = [];
           end
           app.loadedQueryToDisplay = app.loadedQuery;
           app.updateLoadedListBoxes([]);
           app.workingQueryToDisplay = app.workingQuery;
           app.updateWorkingListBoxes([]);
           app.checkMergeCompatibility();
           app.updateAnalysisStepVisibility();
        end
        
        function openH5ExportTool(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            
% %             %not run yet
% %             if length(app.analysisOutputByStep) < caller_num_int || isempty(app.analysisOutputByStep{caller_num_int})
% %                 %need to run analysis first
% %                 app.(['RunanalysisButton' caller_num 'Pushed'])(event);            
% %             end
%             
%             pause(0.5);
%             while app.isAnalysisRunning
%                 pause(0.5);
%             end
            
            if length(app.exportPreferences) >= caller_num_int
                loadState = app.exportPreferences{caller_num_int};
            else
                loadState = [];
            end
            overwrite = get(app.(['OverwriteH5CheckBox' caller_num]), 'value' );
            h = AppDataHandle;
            h5app = H5ExportTool(app.pipeline,get(app.(['FuncTypeDropDown' caller_num]), 'value'), ...
                app.analysisOutputByStep{caller_num_int}, h, overwrite, loadState);
            waitfor(h5app);
            app.exportPreferences{caller_num_int} = h.data.exportState;
        end
        
        function writeResultToDatabase(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            func_menu_name = ['FuncNameDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];

            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
          
            %get params
            if length(app.analysisParams) >= caller_num_int && ...
                    ~isempty(app.analysisParams{caller_num_int})
                P = app.analysisParams{caller_num_int};
            else
                P = [];
            end
            analysisOutput = app.analysisOutputByStep{caller_num_int};
            overwrite = get(app.(['OverwriteDBCheckBox' caller_num]), 'value');
            
            writeResult(app.pipeline, funcType, funcName, P, analysisOutput, overwrite);
            
        end
        
        function loadResultFromDatabase(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            func_menu_name = ['FuncNameDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];

            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            
            prevTitle = get(app.BuildanalysisPanel, 'title');
            tic;
            set(app.BuildanalysisPanel, 'title', 'loading results...');
            drawnow;
            [analysisOutput, loaded_some, missed_some] = loadResult(app.pipeline, funcType, funcName, app.workingQuery);
            set(app.BuildanalysisPanel, 'title', prevTitle);
            fprintf('Load time: %.1f seconds\n', toc);
            
            if loaded_some && ~missed_some
                app.analysisOutputByStep{caller_num_int} = analysisOutput;
                set(app.(['ResultloadedLamp' caller_num]), 'color', 'green');
            elseif loaded_some && missed_some
                app.analysisOutputByStep{caller_num_int} = analysisOutput;
                set(app.(['ResultloadedLamp' caller_num]), 'color', 'yellow');
            else
                set(app.(['ResultloadedLamp' caller_num]), 'color', 'white');
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %load pipeline
           h = AppDataHandle;
           loader = PipelineLoader(h);
           waitfor(loader);
           app.pipeline = h.data.pipeline_name;
           
           if isempty(app.pipeline)
               disp('Error: must select a working pipeline')
               delete(app);
           else
              app.loadPipeline(); 
           end
                      
        end

        % Button pushed function: LoadsinglecellButton
        function LoadsinglecellButtonPushed(app, event)
            cell_id = get(app.cell_idEditField, 'value');
            app.searchTable = sl.SymphonyRecordedCell * sl.Dataset;
            app.loadedQuery = app.searchTable & sprintf('cell_id="%s"', cell_id);
            app.loadedQueryToDisplay = app.loadedQuery;
            app.updateLoadedListBoxes([]);
            app.checkMergeCompatibility();
        end

        % Button pushed function: LoadcellnamesButton
        function LoadcellnamesButtonPushed(app, event)
            [fname, fpath] = uigetfile('*.txt','Select plain text file with cell names.');
            if fname
                cell_ids = importdata([fpath, fname]);      
                app.searchTable = sl.SymphonyRecordedCell * sl.Dataset;
                app.loadedQuery = app.searchTable & struct('cell_id', cell_ids);
                app.loadedQueryToDisplay = app.loadedQuery;
                app.updateLoadedListBoxes([]);
                app.checkMergeCompatibility();
            end
        end

        % Value changed function: AnimalsListBox_L
        function AnimalsListBox_LValueChanged(app, event)
            value = app.AnimalsListBox_L.Value;
            app.loadedQueryToDisplay = app.loadedQuery & sprintf('animal_id=%s', value);
            assignin('base', 'selected_animal_id_L', str2double(value));
            app.updateLoadedListBoxes('animals');
        end

        % Value changed function: CellsListBox_L
        function CellsListBox_LValueChanged(app, event)
            value = app.CellsListBox_L.Value;
            app.loadedQueryToDisplay = app.loadedQuery & sprintf('cell_id="%s"', value);
            assignin('base', 'selected_cell_id_L', value);
            app.updateLoadedListBoxes('cells');
        end

        % Button pushed function: LoadqueryButton
        function LoadqueryButtonPushed(app, event)
            [fname, fpath] = uigetfile('.mat','Load query file',getenv('queryFolder'));
            if fname
                load([fpath fname], 'queryState');
                app.loadedQueryState = queryState;
                app.loadedQuery = parseQueryStruct(queryState);
                set(app.QueryTextArea,'value',parseQueryStruct(queryState, true));
                commandStr = 'app.searchTable=';
                currentTables = queryState.currentTables;
                N = length(currentTables);
                for i=1:N
                    commandStr = [commandStr, 'sl.' currentTables{i} '*'];
                end
                %remove trailing *
                commandStr = [commandStr(1:end-1) ,';'];
                eval(commandStr);
                app.loadedQueryToDisplay = app.loadedQuery;
                app.updateLoadedListBoxes([]);
                app.checkMergeCompatibility();
            end
        end

        % Button pushed function: QueryeditorButton
        function QueryeditorButtonPushed(app, event)
            h = AppDataHandle;
            tableStartState.availableTables = {'Animal',...
                'CurrentCellType', ...
                'Dataset', ...
                'Epoch', ...
                'Eye', ...
                'MeasuredCell', ...
                'MeasuredRetinalCell',...
                'SymphonyRecordedCell'...                
                };
            tableStartState.initTables = {'Animal', ...
                'CurrentCellType', ...
                'Dataset',...
                'MeasuredRetinalCell',...
                'MeasuredCell',...
                'SymphonyRecordedCell'...
                };            
            qb_app = QueryBuilder(h, app.loadedQueryState, tableStartState);
            waitfor(qb_app);
            if isfield(h.data, 'queryResult')
                app.loadedQueryState = h.data.queryState;
                app.loadedQuery = h.data.queryResult;
                app.searchTable = h.data.searchTable;
                app.loadedQueryToDisplay = app.loadedQuery;
                set(app.QueryTextArea,'value',parseQueryStruct(app.loadedQueryState, true));
                app.updateLoadedListBoxes([]);
                app.checkMergeCompatibility();
            end
        end

        % Button pushed function: ClearLoadedButton
        function ClearLoadedButtonPushed(app, event)
            app.loadedQuery = [];
            app.loadedQueryToDisplay = app.loadedQuery;
            app.searchTable = [];
            app.updateLoadedListBoxes();
            app.checkMergeCompatibility();
        end

        % Button pushed function: FilterbyepochfunctionButton
        function FilterbyepochfunctionButtonPushed(app, event)
            fname = uigetfile('*.m', 'Select your filtering function', getenv('epochFilterFuncFolder'));
            if fname
                commandName = strtok(fname, '.'); 
                if ~isempty(app.loadedQuery)
                    %ep_query = sl.Epoch & app.loadedQuery.proj;
                    [~, ep_query] = getEpochsInQuery(app, app.loadedQuery);
                    q_struct = ep_query.fetch('protocol_params');
                    fprintf('running filter function on %d epochs\n', length(q_struct));
                    tic;x
                    for i=1:length(q_struct) %check each epoch
                        epochForTest = q_struct(i);
                        epochForQuery = rmfield(q_struct(i), 'protocol_params');                        
                        passed = eval([commandName '(epochForTest);']);
                        thisTempEntry = sl.TempFilter & epochForQuery;                        
                        while thisTempEntry.exists
                            disp('waiting for another user working on this epoch')
                            pause(1);
                            thisTempEntry = sl.TempFilter & epochForTest;
                        end
                        epochForInsert = epochForQuery;
                        epochForInsert.passed = passed;
                        insert(sl.TempFilter,epochForInsert);                                                
                    end
                    fprintf('Time elapsed: %f seconds\n', toc);
                    %now run the filter by using restriction with temp table
                    passed_epochs = sl.TempFilter & 'passed=1';
                    fprintf('%d epochs passed.\n', passed_epochs.count);
                    %app.loadedQuery = app.loadedQuery & passed_epochs;
                    %drop empty datasets
                    datasets = sl.Dataset & proj(app.loadedQuery) & passed_epochs;
                    d_struct = fetch(datasets);
                    %d_struct_clean = rmfield(d_struct, 'passed');
                    d_struct_withEpochNums = fetch(datasets,'epoch_ids');
                    for i=1:length(d_struct)
%                         i
                        theseEpochNums = fetchn(passed_epochs & d_struct(i), 'epoch_number');
                        if isempty(intersect(d_struct_withEpochNums(i).epoch_ids, theseEpochNums))
                            app.loadedQuery = app.loadedQuery - d_struct(i);
                        end
%                         app.loadedQuery 
%                         pause;
                    end
                    app.loadedQueryToDisplay = app.loadedQuery;
                    app.updateLoadedListBoxes([]);
                    %and delete the temp filter entries
                    q_struct = ep_query.fetch();
                    delQuick(sl.TempFilter & q_struct);
                    disp('deleted TempFilter')
                end
            end
        end

        % Button pushed function: AlltoWGButton
        function AlltoWGButtonPushed(app, event)
            app.addToWorkingGroup(app.loadedQuery);
        end

        % Button pushed function: AnimaltoWGButton
        function AnimaltoWGButtonPushed(app, event)
            selected_animal = get(app.AnimalsListBox_L,'value');
            if ~isempty(selected_animal)
                app.addToWorkingGroup(app.loadedQuery & sprintf('animal_id=%s', selected_animal));
            end
        end

        % Button pushed function: CelltoWGButton
        function CelltoWGButtonPushed(app, event)
            selected_cell =  get(app.CellsListBox_L,'value');
            selected_animal = get(app.AnimalsListBox_L,'value');
            if ~isempty(selected_cell) && ~isempty(selected_animal)
                app.addToWorkingGroup(app.loadedQuery ...
                    & sprintf('cell_id="%s"', selected_cell) ...
                    & sprintf('animal_id=%s', selected_animal));
            end
        end

        % Button pushed function: DatasettoWGButton
        function DatasettoWGButtonPushed(app, event)
            selected_dataset = get(app.SymphonydatasetsListBox_L,'value');
            selected_cell =  get(app.CellsListBox_L,'value');
            selected_animal = get(app.AnimalsListBox_L,'value');
            if ~isempty(selected_dataset) && ~isempty(selected_cell) && ~isempty(selected_animal)
                app.addToWorkingGroup(app.loadedQuery ...
                    & sprintf('dataset_name="%s"', selected_dataset) ...
                    & sprintf('cell_id="%s"', selected_cell) ...
                    & sprintf('animal_id=%s', selected_animal))
            end
        end

        % Button pushed function: ClearWorkingGroupButton
        function ClearWorkingGroupButtonPushed(app, event)
            app.workingQuery = [];
            app.workingQueryToDisplay = app.workingQuery;
            app.updateWorkingListBoxes([]);            
            app.checkMergeCompatibility();
            %clear out loaded results
            Nsteps = get(app.StepsSpinner,'value');
            for i=1:Nsteps
                app.analysisOutputByStep{i} = [];
                set(app.(['ResultloadedLamp_' num2str(i)]), 'color', 'white');
            end
        end

        % Value changed function: AnimalsListBox_W
        function AnimalsListBox_WValueChanged(app, event)
            value = app.AnimalsListBox_W.Value;
            app.workingQueryToDisplay = app.workingQuery & sprintf('animal_id=%s', value);
            assignin('base','selected_animal_id_W',value);
            app.updateWorkingListBoxes('animals');
        end

        % Value changed function: CellsListBox_W
        function CellsListBox_WValueChanged(app, event)
            value = app.CellsListBox_W.Value;
            app.workingQueryToDisplay = app.workingQuery & sprintf('cell_id="%s"', value);
            assignin('base','selected_cell_id_W',value);
            app.updateWorkingListBoxes('cells');
        end

        % Button pushed function: DitchanimalButton
        function DitchanimalButtonPushed(app, event)
            value = app.AnimalsListBox_W.Value;
            if ~isempty(value)
                app.workingQuery = app.workingQuery - sprintf('animal_id=%s', value);
                if app.workingQuery.count==0
                    app.ClearWorkingGroupButtonPushed(event);
                else
                    app.workingQueryToDisplay = app.workingQuery;
                    app.updateWorkingListBoxes([]);
                end
            end  
        end

        % Button pushed function: DitchcellButton
        function DitchcellButtonPushed(app, event)
            value = app.CellsListBox_W.Value;
            if ~isempty(value)
                app.workingQuery = app.workingQuery - sprintf('cell_id="%s"', value);
                if app.workingQuery.count==0
                    app.ClearWorkingGroupButtonPushed(event);
                else
                    app.workingQueryToDisplay = app.workingQuery;
                    app.updateWorkingListBoxes('cells_set');
                    app.AnimalsListBox_WValueChanged(event);
                end
            end
        end

        % Button pushed function: DitchdatasetButton
        function DitchdatasetButtonPushed(app, event)
            value = app.SymphonydatasetsListBox_W.Value;
            selected_cell =  get(app.CellsListBox_W,'value');
            selected_animal = get(app.AnimalsListBox_W,'value');
            if ~isempty(value) && ~isempty(selected_cell) && ~isempty(selected_animal)
                subtract_query = app.workingQuery & sprintf('dataset_name="%s"', value) & ...
                    sprintf('cell_id="%s"', selected_cell) & ...
                    sprintf('animal_id=%s', selected_animal);
                
                app.workingQuery = app.workingQuery - subtract_query.proj;
                if app.workingQuery.count==0
                    app.ClearWorkingGroupButtonPushed(event);
                else
                    app.workingQueryToDisplay = app.workingQuery;
                    app.updateWorkingListBoxes([]);
                    %TODO = fix what is displayed after ditching this dataset
                    displayedAnimals = get(app.AnimalsListBox_W,'items');
                    displayedCells= get(app.CellsListBox_W,'items');
                    if ismember(selected_animal, displayedAnimals) && ismember(selected_cell, displayedCells)
                        set(app.AnimalsListBox_W,'value',selected_animal);
                        set(app.CellsListBox_W,'value',selected_cell);
                        app.CellsListBox_WValueChanged(event);
                    end
                    %app.CellsListBox_WValueChanged(event);
                end
            end
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
                [fname, fpath] = uiputfile('*.mat', 'Save DataGroup file...',getenv('workingGroupsFolder'));
                if fname
                    workingGroup.primaryKeyStruct = app.workingQuery.fetch();
                    workingGroup.searchTable = app.searchTable;    
                    save([fpath, fname], 'workingGroup', '-mat');
                end
        end

        % Button pushed function: LoadDataGroupButton
        function LoadDataGroupButtonPushed(app, event)
             [fname, fpath] = uigetfile('*.mat', 'Load DataGroup file...',getenv('workingGroupsFolder'));
                if fname
                    load([fpath, fname], 'workingGroup');
                    app.searchTable = workingGroup.searchTable;                    
                    app.loadedQuery = workingGroup.searchTable & workingGroup.primaryKeyStruct;
                    app.loadedQueryToDisplay = app.loadedQuery;
                    app.updateLoadedListBoxes([]);
                    app.checkMergeCompatibility();
                end
        end

        % Value changed function: FuncTypeDropDown_1
        function FuncTypeDropDown_1ValueChanged(app, event)
            app.updateFunctionsMenu(event);
        end

        % Button pushed function: RunanalysisButton_1
        function RunanalysisButton_1Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: EditButton_1
        function EditButton_1Pushed(app, event)
            app.editParamsTable(event);
        end

        % Value changed function: FuncNameDropDown_1
        function FuncNameDropDown_1ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: ParamSetDropDown_1
        function ParamSetDropDown_1ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Button pushed function: SaveButton_1
        function SaveButton_1Pushed(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: WritetodatabaseButton_1
        function WritetodatabaseButton_1Pushed(app, event)
            app.writeResultToDatabase(event);
        end

        % Button pushed function: AttachquerytopipelineButton
        function AttachquerytopipelineButtonPushed(app, event)
            pipelines_dir = getenv('pipelines_folder');
            if ~isempty(app.pipeline)
                pipeline_name = app.pipeline;
                if ~isempty(app.loadedQueryState)
                    if ~exist([pipelines_dir pipeline_name], 'dir')
                        mkdir([pipelines_dir pipeline_name]);
                    end                    
                    queryState = app.loadedQueryState;
                    save([pipelines_dir pipeline_name filesep 'query.mat'], 'queryState');
                    fprintf('Query attached to pipeline: %s\n', pipeline_name);
                end                    
            end            
        end

        % Button pushed function: SwitchpipelineButton
        function SwitchpipelineButtonPushed(app, event)
           h = AppDataHandle;
           loader = PipelineLoader(h);
           waitfor(loader);
           app.pipeline = h.data.pipeline_name;
           
           if isempty(app.pipeline)
               disp('Error: must select a working pipeline')
               delete(app);
           else
              app.loadPipeline(); 
           end
        end

        % Button pushed function: AttachDataGrouptopipelineButton
        function AttachDataGrouptopipelineButtonPushed(app, event)
            pipelines_dir = getenv('pipelines_folder');
            if ~isempty(app.pipeline)
                pipeline_name = app.pipeline;
                if ~isempty(app.workingQuery)
                    if ~exist([pipelines_dir pipeline_name], 'dir')
                        mkdir([pipelines_dir pipeline_name]);
                    end                    
                    workingGroup.primaryKeyStruct = app.workingQuery.fetch();
                    workingGroup.searchTable = app.searchTable;  
                    save([pipelines_dir pipeline_name filesep 'dataGroup.mat'], 'workingGroup');
                    fprintf('DataGroup attached to pipeline: %s\n', pipeline_name);
                end                    
            end  
        end

        % Callback function
        function EditQueryButtonValueChanged(app, event)
            value = app.EditQueryButton.Value;
            if value
                set(app.QueryEditField, 'editable', true);
            else
                set(app.QueryEditField, 'editable', false);
            end
        end

        % Value changed function: StepsSpinner
        function StepsSpinnerValueChanged(app, event)
            value = event.Value;
            app.updateAnalysisStepVisibility();
            if value > event.PreviousValue
                %initialize this last entry
                ev.Source.Tag = ['FuncTypeDropDown_' num2str(value)];
                ev.Value = 'Epoch';
                app.(['FuncTypeDropDown_' num2str(value) 'ValueChanged'])(ev);
                app.(['FuncNameDropDown_' num2str(value) 'ValueChanged'])(ev);
                set(app.(['ResultloadedLamp_' num2str(value)]), 'color', 'white');    
            end
        end

        % Button pushed function: 
        % AttachanalysisstepstopipelineButton
        function AttachanalysisstepstopipelineButtonPushed(app, event)
            pipelines_dir = getenv('pipelines_folder');
            if ~isempty(app.pipeline)
                pipeline_name = app.pipeline;
                if ~isempty(app.workingQuery)
                    if ~exist([pipelines_dir pipeline_name], 'dir')
                        mkdir([pipelines_dir pipeline_name]);
                    end
                    Nsteps = get(app.StepsSpinner, 'value');
                    s = struct('funcType','','funcName','','paramSet','');
                    analysisSteps = repmat(s,1,Nsteps);
                    exportStates = {};
                    for i=1:Nsteps
                        analysisSteps(i).funcType = get(app.(['FuncTypeDropDown_' num2str(i)]), 'value');
                        analysisSteps(i).funcName = get(app.(['FuncNameDropDown_' num2str(i)]), 'value');
                        analysisSteps(i).paramSet = get(app.(['ParamSetDropDown_' num2str(i)]), 'value');
                        if length(app.analysisParams) >= i
                             analysisSteps(i).P = app.analysisParams{i};
                        else
                             analysisSteps(i).P = [];
                        end                       
                        analysisSteps(i).overwrite_DB = get(app.(['OverwriteDBCheckBox_' num2str(i)]), 'value');
                        analysisSteps(i).overwrite_H5 = get(app.(['OverwriteH5CheckBox_' num2str(i)]), 'value');
                        if length(app.exportPreferences) >= i
                            exportStates{i} = app.exportPreferences{i};
                        else
                            exportStates{i} = [];
                        end
                    end
                    save([pipelines_dir pipeline_name filesep 'analysisSteps.mat'], 'analysisSteps', 'exportStates');
                    fprintf('%d analysis steps attached to pipeline: %s\n', Nsteps, pipeline_name);
                end
            end
        end

        % Callback function
        function FuncTypeDropDown_2ValueChanged(app, event)
            app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_3ValueChanged(app, event)
            app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_4ValueChanged(app, event)
            app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_5ValueChanged(app, event)
            app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_6ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_7ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_8ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_9ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_10ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_11ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_12ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_13ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_14ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_15ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_16ValueChanged(app, event)
          app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_17ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_18ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_19ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncTypeDropDown_20ValueChanged(app, event)
           app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_2ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_3ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_4ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_5ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_6ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_7ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_8ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_9ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_10ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_11ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_12ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_13ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_14ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_15ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_16ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_17ValueChanged(app, event)
           app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_18ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_19ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_20ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Callback function
        function ParamSetDropDown_2ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_3ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_4ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_5ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_6ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_7ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_8ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_9ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_10ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_11ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_12ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_13ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_14ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_15ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_16ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_17ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_18ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_19ValueChanged(app, event)
           app.loadParamSet(event);
        end

        % Callback function
        function ParamSetDropDown_20ValueChanged(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function EditButton_2Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_3Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_4Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_5Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_6Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_7Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_8Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_9Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_10Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_11Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_12Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_13Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_14Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_15Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_16Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_17Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_18Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_19Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function EditButton_20Pushed(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function SaveButton_2Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_3Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_4Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_5Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_6Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_7Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_8Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_9Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_10Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_11Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_12Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_13Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_14Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_15Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_16Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_17Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_18Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_19Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function SaveButton_20Pushed(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function TestButton_2Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_3Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_4Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_5Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_6Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_7Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_8Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_9Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_10Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_11Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_12Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_13Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_14Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_15Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_16Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_17Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_18Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_19Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function TestButton_20Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_2Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_3Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_4Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_5Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_6Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_7Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_8Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_9Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_10Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_11Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_12Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_13Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_14Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_15Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_16Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_17Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_18Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_19Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_20Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function FuncTypeDropDown_2ValueChanged2(app, event)
            app.updateFunctionsMenu(event);
        end

        % Callback function
        function FuncNameDropDown_2ValueChanged2(app, event)
            app.updateParamsMenu(event);
        end

        % Callback function
        function ParamSetDropDown_2ValueChanged2(app, event)
            app.loadParamSet(event);
        end

        % Callback function
        function EditButton_2Pushed2(app, event)
            app.editParamsTable(event);
        end

        % Callback function
        function SaveButton_2Pushed2(app, event)
            app.saveParamSet(event);
        end

        % Callback function
        function TestButton_2Pushed2(app, event)
            app.runAnalysisFunction(event);
        end

        % Callback function
        function WritetodatabaseButton_2Pushed2(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: SaveasnewpipelineButton
        function SaveasnewpipelineButtonPushed(app, event)
            h = AppDataHandle;
            loader = NewPipeline(h);
            waitfor(loader);
            app.pipeline = h.data.pipeline_name;
            app.AttachDataGrouptopipelineButtonPushed(event);
            app.AttachanalysisstepstopipelineButtonPushed(event);
            app.AttachquerytopipelineButtonPushed(event);
            app.loadPipeline();
        end

        % Button pushed function: Exporttoh5Button_1
        function Exporttoh5Button_1Pushed(app, event)
            app.openH5ExportTool(event);
        end

        % Button pushed function: LoadfromdatabaseButton_1
        function LoadfromdatabaseButton_1Pushed(app, event)
            app.loadResultFromDatabase(event);
        end

        % Callback function
        function FuncTypeDropDown_2ValueChanged3(app, event)
            app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncNameDropDown_2
        function FuncNameDropDown_2ValueChanged3(app, event)
             app.updateParamsMenu(event);
        end

        % Value changed function: ParamSetDropDown_2
        function ParamSetDropDown_2ValueChanged3(app, event)
          app.loadParamSet(event);
        end

        % Button pushed function: EditButton_2
        function EditButton_2Pushed3(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed3(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: RunanalysisButton_2
        function RunanalysisButton_2Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: LoadfromdatabaseButton_2
        function LoadfromdatabaseButton_2Pushed(app, event)
            app.loadResultFromDatabase(event);
        end

        % Button pushed function: WritetodatabaseButton_2
        function WritetodatabaseButton_2Pushed3(app, event)
            app.writeResultToDatabase(event);
        end

        % Button pushed function: Exporttoh5Button_2
        function Exporttoh5Button_2Pushed(app, event)
            app.openH5ExportTool(event);
        end

        % Value changed function: FuncTypeDropDown_3
        function FuncTypeDropDown_3ValueChanged2(app, event)
           app.updateFunctionsMenu(event);
        end

        % Value changed function: ParamSetDropDown_3
        function ParamSetDropDown_3ValueChanged2(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: FuncNameDropDown_3
        function FuncNameDropDown_3ValueChanged2(app, event)
           app.updateParamsMenu(event);
        end

        % Button pushed function: EditButton_3
        function EditButton_3Pushed2(app, event)
             app.editParamsTable(event);
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed2(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: RunanalysisButton_3
        function RunanalysisButton_3Pushed(app, event)
              app.runAnalysisFunction(event);
        end

        % Button pushed function: LoadfromdatabaseButton_3
        function LoadfromdatabaseButton_3Pushed(app, event)
             app.loadResultFromDatabase(event);
        end

        % Button pushed function: WritetodatabaseButton_3
        function WritetodatabaseButton_3Pushed2(app, event)
             app.writeResultToDatabase(event);
        end

        % Button pushed function: Exporttoh5Button_3
        function Exporttoh5Button_3Pushed(app, event)
             app.openH5ExportTool(event);
        end

        % Button pushed function: RunButton_Full
        function RunButton_FullPushed(app, event)
            %get the same pieces one could load from the saved files
            %WG
            workingGroup.primaryKeyStruct = app.workingQuery.fetch();
            workingGroup.searchTable = app.searchTable;
            %analysis steps and export states
            Nsteps = get(app.StepsSpinner, 'value');
            s = struct('funcType','','funcName','','paramSet','');
            analysisSteps = repmat(s,1,Nsteps);
            for i=1:Nsteps
                analysisSteps(i).funcType = get(app.(['FuncTypeDropDown_' num2str(i)]), 'value');
                analysisSteps(i).funcName = get(app.(['FuncNameDropDown_' num2str(i)]), 'value');
                analysisSteps(i).paramSet = get(app.(['ParamSetDropDown_' num2str(i)]), 'value');
                if i>length(app.analysisParams)
                    app.analysisParams{i} = [];
                end
                analysisSteps(i).P = app.analysisParams{i};
                analysisSteps(i).overwrite_DB = get(app.(['OverwriteDBCheckBox_' num2str(i)]), 'value');
                analysisSteps(i).overwrite_H5 = get(app.(['OverwriteH5CheckBox_' num2str(i)]), 'value');
            end
            
            %get loading behavior
            if strcmp(get(app.LoadFirstButtonGroup.SelectedObject, 'text'), 'Load available results')
                loadFirst = true;
            else
                loadFirst = false;
            end
            runFullPipeline(app.pipeline, workingGroup, analysisSteps, loadFirst);
        end

        % Value changed function: FuncTypeDropDown_4
        function FuncTypeDropDown_4ValueChanged2(app, event)
            app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_5
        function FuncTypeDropDown_5ValueChanged2(app, event)
            app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_6
        function FuncTypeDropDown_6ValueChanged2(app, event)
           app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_7
        function FuncTypeDropDown_7ValueChanged2(app, event)
            app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_8
        function FuncTypeDropDown_8ValueChanged2(app, event)
            app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncTypeDropDown_9
        function FuncTypeDropDown_9ValueChanged2(app, event)
            app.updateFunctionsMenu(event);
        end

        % Value changed function: FuncNameDropDown_4
        function FuncNameDropDown_4ValueChanged2(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_5
        function FuncNameDropDown_5ValueChanged2(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_6
        function FuncNameDropDown_6ValueChanged2(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_7
        function FuncNameDropDown_7ValueChanged2(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_8
        function FuncNameDropDown_8ValueChanged2(app, event)
           app.updateParamsMenu(event);
        end

        % Value changed function: FuncNameDropDown_9
        function FuncNameDropDown_9ValueChanged2(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: ParamSetDropDown_4
        function ParamSetDropDown_4ValueChanged2(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_5
        function ParamSetDropDown_5ValueChanged2(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_6
        function ParamSetDropDown_6ValueChanged2(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_7
        function ParamSetDropDown_7ValueChanged2(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_8
        function ParamSetDropDown_8ValueChanged2(app, event)
            app.loadParamSet(event);
        end

        % Value changed function: ParamSetDropDown_9
        function ParamSetDropDown_9ValueChanged2(app, event)
            app.loadParamSet(event);
        end

        % Button pushed function: EditButton_4
        function EditButton_4Pushed2(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_5
        function EditButton_5Pushed2(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_6
        function EditButton_6Pushed2(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_7
        function EditButton_7Pushed2(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_8
        function EditButton_8Pushed2(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: EditButton_9
        function EditButton_9Pushed2(app, event)
            app.editParamsTable(event);
        end

        % Button pushed function: SaveButton_4
        function SaveButton_4Pushed2(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_5
        function SaveButton_5Pushed2(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_6
        function SaveButton_6Pushed2(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_7
        function SaveButton_7Pushed2(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_8
        function SaveButton_8Pushed2(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: SaveButton_9
        function SaveButton_9Pushed2(app, event)
            app.saveParamSet(event);
        end

        % Button pushed function: RunanalysisButton_4
        function RunanalysisButton_4Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: RunanalysisButton_5
        function RunanalysisButton_5Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: RunanalysisButton_6
        function RunanalysisButton_6Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: RunanalysisButton_7
        function RunanalysisButton_7Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: RunanalysisButton_8
        function RunanalysisButton_8Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: RunanalysisButton_9
        function RunanalysisButton_9Pushed(app, event)
            app.runAnalysisFunction(event);
        end

        % Button pushed function: LoadfromdatabaseButton_4
        function LoadfromdatabaseButton_4Pushed(app, event)
            app.loadResultFromDatabase(event);
        end

        % Button pushed function: LoadfromdatabaseButton_5
        function LoadfromdatabaseButton_5Pushed(app, event)
            app.loadResultFromDatabase(event);
        end

        % Button pushed function: LoadfromdatabaseButton_6
        function LoadfromdatabaseButton_6Pushed(app, event)
            app.loadResultFromDatabase(event);
        end

        % Button pushed function: LoadfromdatabaseButton_7
        function LoadfromdatabaseButton_7Pushed(app, event)
            app.loadResultFromDatabase(event);
        end

        % Button pushed function: LoadfromdatabaseButton_8
        function LoadfromdatabaseButton_8Pushed(app, event)
            app.loadResultFromDatabase(event);
        end

        % Button pushed function: LoadfromdatabaseButton_9
        function LoadfromdatabaseButton_9Pushed(app, event)
            app.loadResultFromDatabase(event);
        end

        % Button pushed function: WritetodatabaseButton_4
        function WritetodatabaseButton_4Pushed2(app, event)
            app.writeResultToDatabase(event);
        end

        % Button pushed function: WritetodatabaseButton_5
        function WritetodatabaseButton_5Pushed2(app, event)
            app.writeResultToDatabase(event);
        end

        % Button pushed function: WritetodatabaseButton_6
        function WritetodatabaseButton_6Pushed2(app, event)
            app.writeResultToDatabase(event);
        end

        % Button pushed function: WritetodatabaseButton_7
        function WritetodatabaseButton_7Pushed2(app, event)
            app.writeResultToDatabase(event);
        end

        % Button pushed function: WritetodatabaseButton_8
        function WritetodatabaseButton_8Pushed2(app, event)
            app.writeResultToDatabase(event);
        end

        % Button pushed function: WritetodatabaseButton_9
        function WritetodatabaseButton_9Pushed2(app, event)
            app.writeResultToDatabase(event);
        end

        % Button pushed function: Exporttoh5Button_4
        function Exporttoh5Button_4Pushed(app, event)
            app.openH5ExportTool(event);
        end

        % Button pushed function: Exporttoh5Button_5
        function Exporttoh5Button_5Pushed(app, event)
            app.openH5ExportTool(event);
        end

        % Button pushed function: Exporttoh5Button_6
        function Exporttoh5Button_6Pushed(app, event)
            app.openH5ExportTool(event);
        end

        % Button pushed function: Exporttoh5Button_7
        function Exporttoh5Button_7Pushed(app, event)
            app.openH5ExportTool(event);
        end

        % Button pushed function: Exporttoh5Button_8
        function Exporttoh5Button_8Pushed(app, event)
            app.openH5ExportTool(event);
        end

        % Button pushed function: Exporttoh5Button_9
        function Exporttoh5Button_9Pushed(app, event)
            app.openH5ExportTool(event);
        end

        % Value changed function: SymphonydatasetsListBox_L
        function SymphonydatasetsListBox_LValueChanged(app, event)
            value = app.SymphonydatasetsListBox_L.Value;
            assignin('base', 'selected_dataset_L', value);
        end

        % Value changed function: SymphonydatasetsListBox_W
        function SymphonydatasetsListBox_WValueChanged(app, event)
            value = app.SymphonydatasetsListBox_W.Value;
            assignin('base', 'selected_dataset_W', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisPipelineBuilderUIFigure = uifigure('Visible', 'off');
            app.AnalysisPipelineBuilderUIFigure.Name = 'AnalysisPipelineBuilder';
            app.WorkingDataGroupPanel = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.WorkingDataGroupPanel.Title = 'Working DataGroup';
            app.CellsListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.CellsListBoxLabel.Text = 'Cells';
            app.CellsListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.CellsListBox_W.Items = {};
            app.CellsListBox_W.ValueChangedFcn = createCallbackFcn(app, @CellsListBox_WValueChanged, true);
            app.CellsListBox_W.Value = {};
            app.SymphonydatasetsListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.SymphonydatasetsListBoxLabel.Text = 'Symphony datasets';
            app.SymphonydatasetsListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.SymphonydatasetsListBox_W.Items = {};
            app.SymphonydatasetsListBox_W.ValueChangedFcn = createCallbackFcn(app, @SymphonydatasetsListBox_WValueChanged, true);
            app.SymphonydatasetsListBox_W.Value = {};
            app.OtherdataListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.OtherdataListBoxLabel.Text = 'Other data';
            app.OtherdataListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.OtherdataListBox_W.Items = {};
            app.OtherdataListBox_W.Value = {};
            app.AnimalsListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.AnimalsListBoxLabel.Text = 'Animals';
            app.AnimalsListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.AnimalsListBox_W.Items = {};
            app.AnimalsListBox_W.ValueChangedFcn = createCallbackFcn(app, @AnimalsListBox_WValueChanged, true);
            app.AnimalsListBox_W.Value = {};
            app.TotalsPanel_2 = uipanel(app.WorkingDataGroupPanel);
            app.TotalsPanel_2.Title = 'Totals';
            app.animalsEditField_2Label = uilabel(app.TotalsPanel_2);
            app.animalsEditField_2Label.Text = 'animals';
            app.animalsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.animalsEditField_W.Editable = 'off';
            app.cellsEditField_3Label = uilabel(app.TotalsPanel_2);
            app.cellsEditField_3Label.Text = 'cells';
            app.cellsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.cellsEditField_W.Editable = 'off';
            app.epochsEditField_4Label = uilabel(app.TotalsPanel_2);
            app.epochsEditField_4Label.Text = 'epochs';
            app.epochsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.epochsEditField_W.Editable = 'off';
            app.eyesEditField_2Label = uilabel(app.TotalsPanel_2);
            app.eyesEditField_2Label.Text = 'eyes';
            app.eyesEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.eyesEditField_W.Editable = 'off';
            app.datasetsEditField_4Label = uilabel(app.TotalsPanel_2);
            app.datasetsEditField_4Label.Text = 'datasets';
            app.datasetsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.datasetsEditField_W.Editable = 'off';
            app.otherfilesEditField_4Label = uilabel(app.TotalsPanel_2);
            app.otherfilesEditField_4Label.Text = 'other files';
            app.otherfilesEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.otherfilesEditField_W.Editable = 'off';
            app.SavetofileButton = uibutton(app.TotalsPanel_2, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
            app.ClearWorkingGroupButton = uibutton(app.TotalsPanel_2, 'push');
            app.ClearWorkingGroupButton.ButtonPushedFcn = createCallbackFcn(app, @ClearWorkingGroupButtonPushed, true);
            app.ClearWorkingGroupButton.Text = 'Clear';
            app.AttachDataGrouptopipelineButton = uibutton(app.TotalsPanel_2, 'push');
            app.AttachDataGrouptopipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AttachDataGrouptopipelineButtonPushed, true);
            app.AttachDataGrouptopipelineButton.Text = 'Attach DataGroup to pipeline';
            app.DitchanimalButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchanimalButton.ButtonPushedFcn = createCallbackFcn(app, @DitchanimalButtonPushed, true);
            app.DitchanimalButton.Text = 'Ditch animal';
            app.DitchcellButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchcellButton.ButtonPushedFcn = createCallbackFcn(app, @DitchcellButtonPushed, true);
            app.DitchcellButton.Text = 'Ditch cell';
            app.DitchdatasetButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchdatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DitchdatasetButtonPushed, true);
            app.DitchdatasetButton.Text = 'Ditch dataset';
            app.DitchotherdataButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchotherdataButton.Text = 'Ditch other data';
            app.BuildanalysisPanel = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.BuildanalysisPanel.Title = 'Build analysis';
            app.FuncTypeDropDown_1 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_1.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_1.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_1ValueChanged, true);
            app.FuncTypeDropDown_1.Tag = 'FuncTypeDropDown_1';
            app.FuncTypeDropDown_1.Value = 'Epoch';
            app.FuncNameDropDown_1 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_1.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_1ValueChanged, true);
            app.FuncNameDropDown_1.Tag = 'FuncNameDownDown_1';
            app.FunctiontypeandnameLabel = uilabel(app.BuildanalysisPanel);
            app.FunctiontypeandnameLabel.Text = 'Function type and name';
            app.StepsSpinnerLabel = uilabel(app.BuildanalysisPanel);
            app.StepsSpinnerLabel.Text = 'Steps';
            app.StepsSpinner = uispinner(app.BuildanalysisPanel);
            app.StepsSpinner.Limits = [1 9];
            app.StepsSpinner.RoundFractionalValues = 'on';
            app.StepsSpinner.ValueChangedFcn = createCallbackFcn(app, @StepsSpinnerValueChanged, true);
            app.StepsSpinner.Value = 1;
            app.RunanalysisButton_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.RunanalysisButton_1.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_1Pushed, true);
            app.RunanalysisButton_1.Tag = 'testButton_1';
            app.RunanalysisButton_1.Text = 'Run analysis';
            app.WritetodatabaseButton_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_1.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_1Pushed, true);
            app.WritetodatabaseButton_1.Tag = 'WritetodatabaseButton_1';
            app.WritetodatabaseButton_1.Text = 'Write to database';
            app.Exporttoh5Button_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_1.ButtonPushedFcn = createCallbackFcn(app, @Exporttoh5Button_1Pushed, true);
            app.Exporttoh5Button_1.Tag = 'exporttoh5Button_1';
            app.Exporttoh5Button_1.Text = 'Export to .h5';
            app.OverwriteDBCheckBox_1 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteDBCheckBox_1.Tag = 'OverwriteDBCheckBox_1';
            app.OverwriteDBCheckBox_1.Text = '';
            app.SaveButton_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_1.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_1Pushed, true);
            app.SaveButton_1.Tag = 'saveparamsButton_1';
            app.SaveButton_1.Text = 'Save';
            app.EditButton_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_1.ButtonPushedFcn = createCallbackFcn(app, @EditButton_1Pushed, true);
            app.EditButton_1.Tag = 'editparamsButton_1';
            app.EditButton_1.Text = 'Edit';
            app.ParametersetLabel = uilabel(app.BuildanalysisPanel);
            app.ParametersetLabel.Text = 'Parameter set';
            app.ParamSetDropDown_1 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_1.Items = {};
            app.ParamSetDropDown_1.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_1ValueChanged, true);
            app.ParamSetDropDown_1.Tag = 'ParamSetDropDown_1';
            app.ParamSetDropDown_1.Value = {};
            app.OverwriteLabel = uilabel(app.BuildanalysisPanel);
            app.OverwriteLabel.Text = 'Overwrite';
            app.LabelStep_3 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_3.Tag = 'FuncTypeDropDown_3';
            app.LabelStep_3.Text = '3';
            app.LabelStep_1 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_1.Tag = 'FuncTypeDropDown_2';
            app.LabelStep_1.Text = '1';
            app.ResultloadedLamp_1 = uilamp(app.BuildanalysisPanel);
            app.LoadfromdatabaseButton_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.LoadfromdatabaseButton_1.ButtonPushedFcn = createCallbackFcn(app, @LoadfromdatabaseButton_1Pushed, true);
            app.LoadfromdatabaseButton_1.Tag = 'loadfromdatabaseButton_1';
            app.LoadfromdatabaseButton_1.Text = 'Load from database';
            app.ResultloadedLabel = uilabel(app.BuildanalysisPanel);
            app.ResultloadedLabel.Text = 'Result loaded';
            app.ComputeloadLabel = uilabel(app.BuildanalysisPanel);
            app.ComputeloadLabel.Text = 'Compute/load';
            app.SaveresultLabel = uilabel(app.BuildanalysisPanel);
            app.SaveresultLabel.Text = 'Save result';
            app.OverwriteH5CheckBox_1 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteH5CheckBox_1.Tag = 'OverwriteDBCheckBox_1';
            app.OverwriteH5CheckBox_1.Text = '';
            app.FuncNameDropDown_2 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_2ValueChanged3, true);
            app.FuncNameDropDown_2.Tag = 'FuncNameDownDown_2';
            app.RunanalysisButton_2 = uibutton(app.BuildanalysisPanel, 'push');
            app.RunanalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_2Pushed, true);
            app.RunanalysisButton_2.Tag = 'testButton_2';
            app.RunanalysisButton_2.Text = 'Run analysis';
            app.WritetodatabaseButton_2 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_2.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_2Pushed3, true);
            app.WritetodatabaseButton_2.Tag = 'WritetodatabaseButton_2';
            app.WritetodatabaseButton_2.Text = 'Write to database';
            app.Exporttoh5Button_2 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_2.ButtonPushedFcn = createCallbackFcn(app, @Exporttoh5Button_2Pushed, true);
            app.Exporttoh5Button_2.Tag = 'exporttoh5Button_2';
            app.Exporttoh5Button_2.Text = 'Export to .h5';
            app.OverwriteDBCheckBox_2 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteDBCheckBox_2.Tag = 'OverwriteDBCheckBox_2';
            app.OverwriteDBCheckBox_2.Text = '';
            app.SaveButton_2 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed3, true);
            app.SaveButton_2.Tag = 'saveparamsButton_2';
            app.SaveButton_2.Text = 'Save';
            app.EditButton_2 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditButton_2Pushed3, true);
            app.EditButton_2.Tag = 'editparamsButton_2';
            app.EditButton_2.Text = 'Edit';
            app.ParamSetDropDown_2 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_2.Items = {};
            app.ParamSetDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_2ValueChanged3, true);
            app.ParamSetDropDown_2.Tag = 'ParamSetDropDown_2';
            app.ParamSetDropDown_2.Value = {};
            app.LabelStep_2 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_2.Tag = 'FuncTypeDropDown_2';
            app.LabelStep_2.Text = '2';
            app.ResultloadedLamp_2 = uilamp(app.BuildanalysisPanel);
            app.LoadfromdatabaseButton_2 = uibutton(app.BuildanalysisPanel, 'push');
            app.LoadfromdatabaseButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadfromdatabaseButton_2Pushed, true);
            app.LoadfromdatabaseButton_2.Tag = 'loadfromdatabaseButton_2';
            app.LoadfromdatabaseButton_2.Text = 'Load from database';
            app.OverwriteH5CheckBox_2 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteH5CheckBox_2.Tag = 'OverwriteH5CheckBox_2';
            app.OverwriteH5CheckBox_2.Text = '';
            app.AttachanalysisstepstopipelineButton = uibutton(app.BuildanalysisPanel, 'push');
            app.AttachanalysisstepstopipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AttachanalysisstepstopipelineButtonPushed, true);
            app.AttachanalysisstepstopipelineButton.Text = 'Attach analysis steps to pipeline';
            app.FuncTypeDropDown_3 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_3.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_3ValueChanged2, true);
            app.FuncTypeDropDown_3.Tag = 'FuncTypeDropDown_3';
            app.FuncTypeDropDown_3.Value = 'Epoch';
            app.FuncNameDropDown_3 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_3ValueChanged2, true);
            app.FuncNameDropDown_3.Tag = 'FuncNameDownDown_3';
            app.ParamSetDropDown_3 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_3.Items = {};
            app.ParamSetDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_3ValueChanged2, true);
            app.ParamSetDropDown_3.Tag = 'ParamSetDropDown_3';
            app.ParamSetDropDown_3.Value = {};
            app.EditButton_3 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_3.ButtonPushedFcn = createCallbackFcn(app, @EditButton_3Pushed2, true);
            app.EditButton_3.Tag = 'editparamsButton_3';
            app.EditButton_3.Text = 'Edit';
            app.SaveButton_3 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed2, true);
            app.SaveButton_3.Tag = 'saveparamsButton_3';
            app.SaveButton_3.Text = 'Save';
            app.RunanalysisButton_3 = uibutton(app.BuildanalysisPanel, 'push');
            app.RunanalysisButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_3Pushed, true);
            app.RunanalysisButton_3.Tag = 'testButton_3';
            app.RunanalysisButton_3.Text = 'Run analysis';
            app.LoadfromdatabaseButton_3 = uibutton(app.BuildanalysisPanel, 'push');
            app.LoadfromdatabaseButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadfromdatabaseButton_3Pushed, true);
            app.LoadfromdatabaseButton_3.Tag = 'loadfromdatabaseButton_3';
            app.LoadfromdatabaseButton_3.Text = 'Load from database';
            app.ResultloadedLamp_3 = uilamp(app.BuildanalysisPanel);
            app.WritetodatabaseButton_3 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_3.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_3Pushed2, true);
            app.WritetodatabaseButton_3.Tag = 'WritetodatabaseButton_3';
            app.WritetodatabaseButton_3.Text = 'Write to database';
            app.Exporttoh5Button_3 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_3.ButtonPushedFcn = createCallbackFcn(app, @Exporttoh5Button_3Pushed, true);
            app.Exporttoh5Button_3.Tag = 'exporttoh5Button_3';
            app.Exporttoh5Button_3.Text = 'Export to .h5';
            app.OverwriteDBCheckBox_3 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteDBCheckBox_3.Tag = 'OverwriteDBCheckBox_3';
            app.OverwriteDBCheckBox_3.Text = '';
            app.OverwriteH5CheckBox_3 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteH5CheckBox_3.Tag = 'OverwriteH5CheckBox_3';
            app.OverwriteH5CheckBox_3.Text = '';
            app.LabelStep_4 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_4.Tag = 'FuncTypeDropDown_3';
            app.LabelStep_4.Text = '4';
            app.FuncTypeDropDown_4 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_4.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_4.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_4ValueChanged2, true);
            app.FuncTypeDropDown_4.Tag = 'FuncTypeDropDown_4';
            app.FuncTypeDropDown_4.Value = 'Epoch';
            app.FuncNameDropDown_4 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_4.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_4ValueChanged2, true);
            app.FuncNameDropDown_4.Tag = 'FuncNameDownDown_4';
            app.ParamSetDropDown_4 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_4.Items = {};
            app.ParamSetDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_4ValueChanged2, true);
            app.ParamSetDropDown_4.Tag = 'ParamSetDropDown_4';
            app.ParamSetDropDown_4.Value = {};
            app.EditButton_4 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_4.ButtonPushedFcn = createCallbackFcn(app, @EditButton_4Pushed2, true);
            app.EditButton_4.Tag = 'editparamsButton_4';
            app.EditButton_4.Text = 'Edit';
            app.SaveButton_4 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_4Pushed2, true);
            app.SaveButton_4.Tag = 'saveparamsButton_4';
            app.SaveButton_4.Text = 'Save';
            app.RunanalysisButton_4 = uibutton(app.BuildanalysisPanel, 'push');
            app.RunanalysisButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_4Pushed, true);
            app.RunanalysisButton_4.Tag = 'testButton_4';
            app.RunanalysisButton_4.Text = 'Run analysis';
            app.LoadfromdatabaseButton_4 = uibutton(app.BuildanalysisPanel, 'push');
            app.LoadfromdatabaseButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadfromdatabaseButton_4Pushed, true);
            app.LoadfromdatabaseButton_4.Tag = 'loadfromdatabaseButton_4';
            app.LoadfromdatabaseButton_4.Text = 'Load from database';
            app.ResultloadedLamp_4 = uilamp(app.BuildanalysisPanel);
            app.WritetodatabaseButton_4 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_4.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_4Pushed2, true);
            app.WritetodatabaseButton_4.Tag = 'WritetodatabaseButton_4';
            app.WritetodatabaseButton_4.Text = 'Write to database';
            app.Exporttoh5Button_4 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_4.ButtonPushedFcn = createCallbackFcn(app, @Exporttoh5Button_4Pushed, true);
            app.Exporttoh5Button_4.Tag = 'exporttoh5Button_4';
            app.Exporttoh5Button_4.Text = 'Export to .h5';
            app.OverwriteDBCheckBox_4 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteDBCheckBox_4.Tag = 'OverwriteDBCheckBox_3';
            app.OverwriteDBCheckBox_4.Text = '';
            app.OverwriteH5CheckBox_4 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteH5CheckBox_4.Tag = 'OverwriteH5CheckBox_3';
            app.OverwriteH5CheckBox_4.Text = '';
            app.LabelStep_5 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_5.Tag = 'FuncTypeDropDown_3';
            app.LabelStep_5.Text = '5';
            app.FuncTypeDropDown_5 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_5.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_5.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_5ValueChanged2, true);
            app.FuncTypeDropDown_5.Tag = 'FuncTypeDropDown_5';
            app.FuncTypeDropDown_5.Value = 'Epoch';
            app.FuncNameDropDown_5 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_5.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_5ValueChanged2, true);
            app.FuncNameDropDown_5.Tag = 'FuncNameDownDown_5';
            app.ParamSetDropDown_5 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_5.Items = {};
            app.ParamSetDropDown_5.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_5ValueChanged2, true);
            app.ParamSetDropDown_5.Tag = 'ParamSetDropDown_5';
            app.ParamSetDropDown_5.Value = {};
            app.EditButton_5 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_5.ButtonPushedFcn = createCallbackFcn(app, @EditButton_5Pushed2, true);
            app.EditButton_5.Tag = 'editparamsButton_5';
            app.EditButton_5.Text = 'Edit';
            app.SaveButton_5 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_5Pushed2, true);
            app.SaveButton_5.Tag = 'saveparamsButton_5';
            app.SaveButton_5.Text = 'Save';
            app.RunanalysisButton_5 = uibutton(app.BuildanalysisPanel, 'push');
            app.RunanalysisButton_5.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_5Pushed, true);
            app.RunanalysisButton_5.Tag = 'testButton_5';
            app.RunanalysisButton_5.Text = 'Run analysis';
            app.LoadfromdatabaseButton_5 = uibutton(app.BuildanalysisPanel, 'push');
            app.LoadfromdatabaseButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadfromdatabaseButton_5Pushed, true);
            app.LoadfromdatabaseButton_5.Tag = 'loadfromdatabaseButton_5';
            app.LoadfromdatabaseButton_5.Text = 'Load from database';
            app.ResultloadedLamp_5 = uilamp(app.BuildanalysisPanel);
            app.WritetodatabaseButton_5 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_5.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_5Pushed2, true);
            app.WritetodatabaseButton_5.Tag = 'WritetodatabaseButton_5';
            app.WritetodatabaseButton_5.Text = 'Write to database';
            app.Exporttoh5Button_5 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_5.ButtonPushedFcn = createCallbackFcn(app, @Exporttoh5Button_5Pushed, true);
            app.Exporttoh5Button_5.Tag = 'exporttoh5Button_5';
            app.Exporttoh5Button_5.Text = 'Export to .h5';
            app.OverwriteDBCheckBox_5 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteDBCheckBox_5.Tag = 'OverwriteDBCheckBox_3';
            app.OverwriteDBCheckBox_5.Text = '';
            app.OverwriteH5CheckBox_5 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteH5CheckBox_5.Tag = 'OverwriteH5CheckBox_3';
            app.OverwriteH5CheckBox_5.Text = '';
            app.LabelStep_6 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_6.Tag = 'FuncTypeDropDown_3';
            app.LabelStep_6.Text = '6';
            app.FuncTypeDropDown_6 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_6.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_6.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_6ValueChanged2, true);
            app.FuncTypeDropDown_6.Tag = 'FuncTypeDropDown_6';
            app.FuncTypeDropDown_6.Value = 'Epoch';
            app.FuncNameDropDown_6 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_6.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_6ValueChanged2, true);
            app.FuncNameDropDown_6.Tag = 'FuncNameDownDown_6';
            app.ParamSetDropDown_6 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_6.Items = {};
            app.ParamSetDropDown_6.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_6ValueChanged2, true);
            app.ParamSetDropDown_6.Tag = 'ParamSetDropDown_6';
            app.ParamSetDropDown_6.Value = {};
            app.EditButton_6 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_6.ButtonPushedFcn = createCallbackFcn(app, @EditButton_6Pushed2, true);
            app.EditButton_6.Tag = 'editparamsButton_6';
            app.EditButton_6.Text = 'Edit';
            app.SaveButton_6 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_6.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_6Pushed2, true);
            app.SaveButton_6.Tag = 'saveparamsButton_6';
            app.SaveButton_6.Text = 'Save';
            app.RunanalysisButton_6 = uibutton(app.BuildanalysisPanel, 'push');
            app.RunanalysisButton_6.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_6Pushed, true);
            app.RunanalysisButton_6.Tag = 'testButton_6';
            app.RunanalysisButton_6.Text = 'Run analysis';
            app.LoadfromdatabaseButton_6 = uibutton(app.BuildanalysisPanel, 'push');
            app.LoadfromdatabaseButton_6.ButtonPushedFcn = createCallbackFcn(app, @LoadfromdatabaseButton_6Pushed, true);
            app.LoadfromdatabaseButton_6.Tag = 'loadfromdatabaseButton_6';
            app.LoadfromdatabaseButton_6.Text = 'Load from database';
            app.ResultloadedLamp_6 = uilamp(app.BuildanalysisPanel);
            app.WritetodatabaseButton_6 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_6.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_6Pushed2, true);
            app.WritetodatabaseButton_6.Tag = 'WritetodatabaseButton_6';
            app.WritetodatabaseButton_6.Text = 'Write to database';
            app.Exporttoh5Button_6 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_6.ButtonPushedFcn = createCallbackFcn(app, @Exporttoh5Button_6Pushed, true);
            app.Exporttoh5Button_6.Tag = 'exporttoh5Button_6';
            app.Exporttoh5Button_6.Text = 'Export to .h5';
            app.OverwriteDBCheckBox_6 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteDBCheckBox_6.Tag = 'OverwriteDBCheckBox_3';
            app.OverwriteDBCheckBox_6.Text = '';
            app.OverwriteH5CheckBox_6 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteH5CheckBox_6.Tag = 'OverwriteH5CheckBox_3';
            app.OverwriteH5CheckBox_6.Text = '';
            app.LabelStep_7 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_7.Tag = 'FuncTypeDropDown_3';
            app.LabelStep_7.Text = '7';
            app.FuncTypeDropDown_7 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_7.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_7.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_7ValueChanged2, true);
            app.FuncTypeDropDown_7.Tag = 'FuncTypeDropDown_7';
            app.FuncTypeDropDown_7.Value = 'Epoch';
            app.FuncNameDropDown_7 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_7.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_7ValueChanged2, true);
            app.FuncNameDropDown_7.Tag = 'FuncNameDownDown_7';
            app.ParamSetDropDown_7 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_7.Items = {};
            app.ParamSetDropDown_7.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_7ValueChanged2, true);
            app.ParamSetDropDown_7.Tag = 'ParamSetDropDown_7';
            app.ParamSetDropDown_7.Value = {};
            app.EditButton_7 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_7.ButtonPushedFcn = createCallbackFcn(app, @EditButton_7Pushed2, true);
            app.EditButton_7.Tag = 'editparamsButton_7';
            app.EditButton_7.Text = 'Edit';
            app.SaveButton_7 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_7.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_7Pushed2, true);
            app.SaveButton_7.Tag = 'saveparamsButton_7';
            app.SaveButton_7.Text = 'Save';
            app.RunanalysisButton_7 = uibutton(app.BuildanalysisPanel, 'push');
            app.RunanalysisButton_7.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_7Pushed, true);
            app.RunanalysisButton_7.Tag = 'testButton_7';
            app.RunanalysisButton_7.Text = 'Run analysis';
            app.LoadfromdatabaseButton_7 = uibutton(app.BuildanalysisPanel, 'push');
            app.LoadfromdatabaseButton_7.ButtonPushedFcn = createCallbackFcn(app, @LoadfromdatabaseButton_7Pushed, true);
            app.LoadfromdatabaseButton_7.Tag = 'loadfromdatabaseButton_7';
            app.LoadfromdatabaseButton_7.Text = 'Load from database';
            app.ResultloadedLamp_7 = uilamp(app.BuildanalysisPanel);
            app.WritetodatabaseButton_7 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_7.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_7Pushed2, true);
            app.WritetodatabaseButton_7.Tag = 'WritetodatabaseButton_7';
            app.WritetodatabaseButton_7.Text = 'Write to database';
            app.Exporttoh5Button_7 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_7.ButtonPushedFcn = createCallbackFcn(app, @Exporttoh5Button_7Pushed, true);
            app.Exporttoh5Button_7.Tag = 'exporttoh5Button_7';
            app.Exporttoh5Button_7.Text = 'Export to .h5';
            app.OverwriteDBCheckBox_7 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteDBCheckBox_7.Tag = 'OverwriteDBCheckBox_3';
            app.OverwriteDBCheckBox_7.Text = '';
            app.OverwriteH5CheckBox_7 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteH5CheckBox_7.Tag = 'OverwriteH5CheckBox_3';
            app.OverwriteH5CheckBox_7.Text = '';
            app.LabelStep_8 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_8.Tag = 'FuncTypeDropDown_3';
            app.LabelStep_8.Text = '8';
            app.FuncTypeDropDown_8 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_8.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_8.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_8ValueChanged2, true);
            app.FuncTypeDropDown_8.Tag = 'FuncTypeDropDown_8';
            app.FuncTypeDropDown_8.Value = 'Epoch';
            app.FuncNameDropDown_8 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_8.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_8ValueChanged2, true);
            app.FuncNameDropDown_8.Tag = 'FuncNameDownDown_8';
            app.ParamSetDropDown_8 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_8.Items = {};
            app.ParamSetDropDown_8.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_8ValueChanged2, true);
            app.ParamSetDropDown_8.Tag = 'ParamSetDropDown_8';
            app.ParamSetDropDown_8.Value = {};
            app.EditButton_8 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_8.ButtonPushedFcn = createCallbackFcn(app, @EditButton_8Pushed2, true);
            app.EditButton_8.Tag = 'editparamsButton_8';
            app.EditButton_8.Text = 'Edit';
            app.SaveButton_8 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_8.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_8Pushed2, true);
            app.SaveButton_8.Tag = 'saveparamsButton_8';
            app.SaveButton_8.Text = 'Save';
            app.RunanalysisButton_8 = uibutton(app.BuildanalysisPanel, 'push');
            app.RunanalysisButton_8.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_8Pushed, true);
            app.RunanalysisButton_8.Tag = 'testButton_8';
            app.RunanalysisButton_8.Text = 'Run analysis';
            app.LoadfromdatabaseButton_8 = uibutton(app.BuildanalysisPanel, 'push');
            app.LoadfromdatabaseButton_8.ButtonPushedFcn = createCallbackFcn(app, @LoadfromdatabaseButton_8Pushed, true);
            app.LoadfromdatabaseButton_8.Tag = 'loadfromdatabaseButton_8';
            app.LoadfromdatabaseButton_8.Text = 'Load from database';
            app.ResultloadedLamp_8 = uilamp(app.BuildanalysisPanel);
            app.WritetodatabaseButton_8 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_8.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_8Pushed2, true);
            app.WritetodatabaseButton_8.Tag = 'WritetodatabaseButton_8';
            app.WritetodatabaseButton_8.Text = 'Write to database';
            app.Exporttoh5Button_8 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_8.ButtonPushedFcn = createCallbackFcn(app, @Exporttoh5Button_8Pushed, true);
            app.Exporttoh5Button_8.Tag = 'exporttoh5Button_8';
            app.Exporttoh5Button_8.Text = 'Export to .h5';
            app.OverwriteDBCheckBox_8 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteDBCheckBox_8.Tag = 'OverwriteDBCheckBox_3';
            app.OverwriteDBCheckBox_8.Text = '';
            app.OverwriteH5CheckBox_8 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteH5CheckBox_8.Tag = 'OverwriteH5CheckBox_3';
            app.OverwriteH5CheckBox_8.Text = '';
            app.LabelStep_9 = uilabel(app.BuildanalysisPanel);
            app.LabelStep_9.Tag = 'FuncTypeDropDown_3';
            app.LabelStep_9.Text = '9';
            app.FuncTypeDropDown_9 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_9.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_9.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_9ValueChanged2, true);
            app.FuncTypeDropDown_9.Tag = 'FuncTypeDropDown_9';
            app.FuncTypeDropDown_9.Value = 'Epoch';
            app.FuncNameDropDown_9 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_9.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_9ValueChanged2, true);
            app.FuncNameDropDown_9.Tag = 'FuncNameDownDown_9';
            app.ParamSetDropDown_9 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_9.Items = {};
            app.ParamSetDropDown_9.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_9ValueChanged2, true);
            app.ParamSetDropDown_9.Tag = 'ParamSetDropDown_9';
            app.ParamSetDropDown_9.Value = {};
            app.EditButton_9 = uibutton(app.BuildanalysisPanel, 'push');
            app.EditButton_9.ButtonPushedFcn = createCallbackFcn(app, @EditButton_9Pushed2, true);
            app.EditButton_9.Tag = 'editparamsButton_9';
            app.EditButton_9.Text = 'Edit';
            app.SaveButton_9 = uibutton(app.BuildanalysisPanel, 'push');
            app.SaveButton_9.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_9Pushed2, true);
            app.SaveButton_9.Tag = 'saveparamsButton_9';
            app.SaveButton_9.Text = 'Save';
            app.RunanalysisButton_9 = uibutton(app.BuildanalysisPanel, 'push');
            app.RunanalysisButton_9.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_9Pushed, true);
            app.RunanalysisButton_9.Tag = 'testButton_9';
            app.RunanalysisButton_9.Text = 'Run analysis';
            app.LoadfromdatabaseButton_9 = uibutton(app.BuildanalysisPanel, 'push');
            app.LoadfromdatabaseButton_9.ButtonPushedFcn = createCallbackFcn(app, @LoadfromdatabaseButton_9Pushed, true);
            app.LoadfromdatabaseButton_9.Tag = 'loadfromdatabaseButton_9';
            app.LoadfromdatabaseButton_9.Text = 'Load from database';
            app.ResultloadedLamp_9 = uilamp(app.BuildanalysisPanel);
            app.WritetodatabaseButton_9 = uibutton(app.BuildanalysisPanel, 'push');
            app.WritetodatabaseButton_9.ButtonPushedFcn = createCallbackFcn(app, @WritetodatabaseButton_9Pushed2, true);
            app.WritetodatabaseButton_9.Tag = 'WritetodatabaseButton_9';
            app.WritetodatabaseButton_9.Text = 'Write to database';
            app.Exporttoh5Button_9 = uibutton(app.BuildanalysisPanel, 'push');
            app.Exporttoh5Button_9.ButtonPushedFcn = createCallbackFcn(app, @Exporttoh5Button_9Pushed, true);
            app.Exporttoh5Button_9.Tag = 'exporttoh5Button_9';
            app.Exporttoh5Button_9.Text = 'Export to .h5';
            app.OverwriteDBCheckBox_9 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteDBCheckBox_9.Tag = 'OverwriteDBCheckBox_9';
            app.OverwriteDBCheckBox_9.Text = '';
            app.OverwriteH5CheckBox_9 = uicheckbox(app.BuildanalysisPanel);
            app.OverwriteH5CheckBox_9.Tag = 'OverwriteH5CheckBox_9';
            app.OverwriteH5CheckBox_9.Text = '';
            app.FuncTypeDropDown_2 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_2.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_2ValueChanged3, true);
            app.FuncTypeDropDown_2.Tag = 'FuncTypeDropDown_2';
            app.FuncTypeDropDown_2.Value = 'Epoch';
            app.PipelinePanel = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.PipelinePanel.Title = 'Pipeline';
            app.SwitchpipelineButton = uibutton(app.PipelinePanel, 'push');
            app.SwitchpipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchpipelineButtonPushed, true);
            app.SwitchpipelineButton.Text = 'Switch pipeline';
            app.RunButton_Full = uibutton(app.PipelinePanel, 'push');
            app.RunButton_Full.ButtonPushedFcn = createCallbackFcn(app, @RunButton_FullPushed, true);
            app.RunButton_Full.Text = 'Run pipeline';
            app.SaveasnewpipelineButton = uibutton(app.PipelinePanel, 'push');
            app.SaveasnewpipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasnewpipelineButtonPushed, true);
            app.SaveasnewpipelineButton.Text = 'Save as new pipeline';
            app.LoadFirstButtonGroup = uibuttongroup(app.PipelinePanel);
            app.LoadavailableresultsButton = uiradiobutton(app.LoadFirstButtonGroup);
            app.LoadavailableresultsButton.Text = 'Load available results';
            app.LoadavailableresultsButton.Value = true;
            app.OverwriteallButton = uiradiobutton(app.LoadFirstButtonGroup);
            app.OverwriteallButton.Text = 'Overwrite all';
            app.LoaddataPanel = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.LoaddataPanel.Title = 'Load data';
            app.SymphonydatasetsListBox_2Label = uilabel(app.LoaddataPanel);
            app.SymphonydatasetsListBox_2Label.Text = 'Symphony datasets';
            app.SymphonydatasetsListBox_L = uilistbox(app.LoaddataPanel);
            app.SymphonydatasetsListBox_L.Items = {};
            app.SymphonydatasetsListBox_L.ValueChangedFcn = createCallbackFcn(app, @SymphonydatasetsListBox_LValueChanged, true);
            app.SymphonydatasetsListBox_L.Value = {};
            app.OtherdataListBox_2Label = uilabel(app.LoaddataPanel);
            app.OtherdataListBox_2Label.Text = 'Other data';
            app.OtherdataListBox_L = uilistbox(app.LoaddataPanel);
            app.OtherdataListBox_L.Items = {};
            app.OtherdataListBox_L.Value = {};
            app.AnimalsListBox_2Label = uilabel(app.LoaddataPanel);
            app.AnimalsListBox_2Label.Text = 'Animals';
            app.AnimalsListBox_L = uilistbox(app.LoaddataPanel);
            app.AnimalsListBox_L.Items = {};
            app.AnimalsListBox_L.ValueChangedFcn = createCallbackFcn(app, @AnimalsListBox_LValueChanged, true);
            app.AnimalsListBox_L.Value = {};
            app.TotalsPanel_3 = uipanel(app.LoaddataPanel);
            app.TotalsPanel_3.Title = 'Totals';
            app.animalsEditField_2Label_2 = uilabel(app.TotalsPanel_3);
            app.animalsEditField_2Label_2.Text = 'animals';
            app.animalsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.animalsEditField_L.Editable = 'off';
            app.cellsEditField_3Label_2 = uilabel(app.TotalsPanel_3);
            app.cellsEditField_3Label_2.Text = 'cells';
            app.cellsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.cellsEditField_L.Editable = 'off';
            app.epochsEditField_4Label_2 = uilabel(app.TotalsPanel_3);
            app.epochsEditField_4Label_2.Text = 'epochs';
            app.epochsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.epochsEditField_L.Editable = 'off';
            app.eyesEditField_2Label_2 = uilabel(app.TotalsPanel_3);
            app.eyesEditField_2Label_2.Text = 'eyes';
            app.eyesEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.eyesEditField_L.Editable = 'off';
            app.datasetsEditField_4Label_2 = uilabel(app.TotalsPanel_3);
            app.datasetsEditField_4Label_2.Text = 'datasets';
            app.datasetsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.datasetsEditField_L.Editable = 'off';
            app.otherfilesEditField_4Label_2 = uilabel(app.TotalsPanel_3);
            app.otherfilesEditField_4Label_2.Text = 'other files';
            app.otherfilesEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.otherfilesEditField_L.Editable = 'off';
            app.AlltoWGButton = uibutton(app.TotalsPanel_3, 'push');
            app.AlltoWGButton.ButtonPushedFcn = createCallbackFcn(app, @AlltoWGButtonPushed, true);
            app.AlltoWGButton.Text = 'All to WG';
            app.ClearLoadedButton = uibutton(app.TotalsPanel_3, 'push');
            app.ClearLoadedButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLoadedButtonPushed, true);
            app.ClearLoadedButton.Text = 'Clear';
            app.LoadqueryButton = uibutton(app.LoaddataPanel, 'push');
            app.LoadqueryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadqueryButtonPushed, true);
            app.LoadqueryButton.Text = 'Load query';
            app.QueryeditorButton = uibutton(app.LoaddataPanel, 'push');
            app.QueryeditorButton.ButtonPushedFcn = createCallbackFcn(app, @QueryeditorButtonPushed, true);
            app.QueryeditorButton.Text = 'Query editor';
            app.LoadDataGroupButton = uibutton(app.LoaddataPanel, 'push');
            app.LoadDataGroupButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataGroupButtonPushed, true);
            app.LoadDataGroupButton.Text = 'Load DataGroup';
            app.LoadcellnamesButton = uibutton(app.LoaddataPanel, 'push');
            app.LoadcellnamesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcellnamesButtonPushed, true);
            app.LoadcellnamesButton.Text = 'Load cell names';
            app.LoadsinglecellButton = uibutton(app.LoaddataPanel, 'push');
            app.LoadsinglecellButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsinglecellButtonPushed, true);
            app.LoadsinglecellButton.Text = 'Load single cell';
            app.cell_idEditField = uieditfield(app.LoaddataPanel, 'text');
            app.AttachotherdataButton_2 = uibutton(app.LoaddataPanel, 'push');
            app.AttachotherdataButton_2.Text = 'Attach other data';
            app.FilterbyepochfunctionButton = uibutton(app.LoaddataPanel, 'push');
            app.FilterbyepochfunctionButton.ButtonPushedFcn = createCallbackFcn(app, @FilterbyepochfunctionButtonPushed, true);
            app.FilterbyepochfunctionButton.Text = 'Filter by epoch function';
            app.AnimaltoWGButton = uibutton(app.LoaddataPanel, 'push');
            app.AnimaltoWGButton.ButtonPushedFcn = createCallbackFcn(app, @AnimaltoWGButtonPushed, true);
            app.AnimaltoWGButton.Text = 'Animal to WG';
            app.CelltoWGButton = uibutton(app.LoaddataPanel, 'push');
            app.CelltoWGButton.ButtonPushedFcn = createCallbackFcn(app, @CelltoWGButtonPushed, true);
            app.CelltoWGButton.Text = 'Cell to WG';
            app.DatasettoWGButton = uibutton(app.LoaddataPanel, 'push');
            app.DatasettoWGButton.ButtonPushedFcn = createCallbackFcn(app, @DatasettoWGButtonPushed, true);
            app.DatasettoWGButton.Text = 'Dataset to WG';
            app.OtherfilestoWGButton = uibutton(app.LoaddataPanel, 'push');
            app.OtherfilestoWGButton.Text = 'Other files to WG';
            app.MergecompatibleLampLabel = uilabel(app.LoaddataPanel);
            app.MergecompatibleLampLabel.Text = 'Merge compatible';
            app.MergecompatibleLamp = uilamp(app.LoaddataPanel);
            app.AttachquerytopipelineButton = uibutton(app.LoaddataPanel, 'push');
            app.AttachquerytopipelineButton.ButtonPushedFcn = createCallbackFcn(app, @AttachquerytopipelineButtonPushed, true);
            app.AttachquerytopipelineButton.Text = 'Attach query to pipeline';
            app.QueryTextAreaLabel = uilabel(app.LoaddataPanel);
            app.QueryTextAreaLabel.Text = 'Query';
            app.QueryTextArea = uitextarea(app.LoaddataPanel);
            app.CellsListBox_2Label = uilabel(app.LoaddataPanel);
            app.CellsListBox_2Label.Text = 'Cells';
            app.CellsListBox_L = uilistbox(app.LoaddataPanel);
            app.CellsListBox_L.Items = {};
            app.CellsListBox_L.ValueChangedFcn = createCallbackFcn(app, @CellsListBox_LValueChanged, true);
            app.CellsListBox_L.Value = {};
            % Show the figure after all components are created
            app.AnalysisPipelineBuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisPipelineBuilder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalysisPipelineBuilderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisPipelineBuilderUIFigure)
        end
    end
end