classdef AnimalLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnimalLoggerUIFigure           matlab.ui.Figure
        AnimalTable                    matlab.ui.control.Table
        AnimalSelectionPanel           matlab.ui.container.Panel
        SearchButton                   matlab.ui.control.Button
        IDEditFieldLabel               matlab.ui.control.Label
        IDEditField                    matlab.ui.control.NumericEditField
        FilterPanel                    matlab.ui.container.Panel
        GenotypeDropDownLabel          matlab.ui.control.Label
        GenotypeDropDown               matlab.ui.control.DropDown
        StatusDropDownLabel            matlab.ui.control.Label
        StatusDropDown                 matlab.ui.control.DropDown
        AgerangeweeksPanel             matlab.ui.container.Panel
        ageLow_edit                    matlab.ui.control.NumericEditField
        ageHigh_edit                   matlab.ui.control.NumericEditField
        toLabel                        matlab.ui.control.Label
        VieweventlogButton             matlab.ui.control.Button
        AddentriesPanel                matlab.ui.container.Panel
        AddanimalButton                matlab.ui.control.Button
        AddgenotypeButton_2            matlab.ui.control.Button
        AddsubstanceButton             matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        GenotypedTab                   matlab.ui.container.Tab
        ResultsDropDownLabel           matlab.ui.control.Label
        ResultsDropDown                matlab.ui.control.DropDown
        NotesTextAreaLabel             matlab.ui.control.Label
        NotesTextArea                  matlab.ui.control.TextArea
        DeceasedTab                    matlab.ui.container.Tab
        ReasonDropDownLabel            matlab.ui.control.Label
        ReasonDropDown                 matlab.ui.control.DropDown
        NotesTextArea_2Label           matlab.ui.control.Label
        NotesTextArea_2                matlab.ui.control.TextArea
        GavebirthTab                   matlab.ui.container.Tab
        NotesTextArea_3Label           matlab.ui.control.Label
        NotesTextArea_3                matlab.ui.control.TextArea
        NumberofpupsSpinnerLabel       matlab.ui.control.Label
        NumberofpupsSpinner            matlab.ui.control.Spinner
        MovecageTab                    matlab.ui.container.Tab
        ReasonDropDown_2Label          matlab.ui.control.Label
        ReasonDropDown_2               matlab.ui.control.DropDown
        NotesTextArea_4Label           matlab.ui.control.Label
        NotesTextArea_4                matlab.ui.control.TextArea
        FromcageEditFieldLabel         matlab.ui.control.Label
        FromcageEditField              matlab.ui.control.NumericEditField
        TocageEditFieldLabel           matlab.ui.control.Label
        TocageEditField                matlab.ui.control.NumericEditField
        IPinjectionTab                 matlab.ui.container.Tab
        NotesTextArea_5Label           matlab.ui.control.Label
        NotesTextArea_5                matlab.ui.control.TextArea
        SubstanceDropDownLabel         matlab.ui.control.Label
        SubstanceDropDown              matlab.ui.control.DropDown
        ConcentrationmgKgEditFieldLabel  matlab.ui.control.Label
        ConcentrationmgKgEditField     matlab.ui.control.NumericEditField
        Spinner                        matlab.ui.control.Spinner
        DropDown                       matlab.ui.control.DropDown
        DropDown_2                     matlab.ui.control.DropDown
        TimeLabel                      matlab.ui.control.Label
        EyeinjectionTab                matlab.ui.container.Tab
        SubstanceDropDownLabel_2       matlab.ui.control.Label
        SubstanceDropDown_2            matlab.ui.control.DropDown
        NotesTextArea_7Label           matlab.ui.control.Label
        NotesTextArea_7                matlab.ui.control.TextArea
        Spinner_3                      matlab.ui.control.Spinner
        DropDown_5                     matlab.ui.control.DropDown
        DropDown_6                     matlab.ui.control.DropDown
        TimeLabel_2                    matlab.ui.control.Label
        EyeDropDownLabel               matlab.ui.control.Label
        EyeDropDown                    matlab.ui.control.DropDown
        Dilution1XEditFieldLabel       matlab.ui.control.Label
        Dilution1XEditField            matlab.ui.control.NumericEditField
        BraininjectionTab              matlab.ui.container.Tab
        SubstanceDropDown_3Label       matlab.ui.control.Label
        SubstanceDropDown_3            matlab.ui.control.DropDown
        Dilution1XEditField_2Label     matlab.ui.control.Label
        Dilution1XEditField_2          matlab.ui.control.NumericEditField
        NotesTextArea_8Label           matlab.ui.control.Label
        NotesTextArea_8                matlab.ui.control.TextArea
        CoordinatesLabel               matlab.ui.control.Label
        TargetDropDownLabel            matlab.ui.control.Label
        TargetDropDown                 matlab.ui.control.DropDown
        HemisphereDropDownLabel        matlab.ui.control.Label
        HemisphereDropDown             matlab.ui.control.DropDown
        APEditFieldLabel               matlab.ui.control.Label
        APEditField                    matlab.ui.control.NumericEditField
        MLEditFieldLabel               matlab.ui.control.Label
        MLEditField                    matlab.ui.control.NumericEditField
        DVEditFieldLabel               matlab.ui.control.Label
        DVEditField                    matlab.ui.control.NumericEditField
        AngleEditFieldLabel            matlab.ui.control.Label
        AngleEditField                 matlab.ui.control.NumericEditField
        Spinner_4                      matlab.ui.control.Spinner
        DropDown_7                     matlab.ui.control.DropDown
        DropDown_8                     matlab.ui.control.DropDown
        TimeLabel_3                    matlab.ui.control.Label
        HandledTab                     matlab.ui.container.Tab
        NotesTextArea_6Label           matlab.ui.control.Label
        NotesTextArea_6                matlab.ui.control.TextArea
        Spinner_2                      matlab.ui.control.Spinner
        DropDown_3                     matlab.ui.control.DropDown
        DropDown_4                     matlab.ui.control.DropDown
        StarttimeLabel                 matlab.ui.control.Label
        DurationminutesEditFieldLabel  matlab.ui.control.Label
        DurationminutesEditField       matlab.ui.control.NumericEditField
        SocialbehaviorsessionTab       matlab.ui.container.Tab
        NotesTextArea_9Label           matlab.ui.control.Label
        NotesTextArea_9                matlab.ui.control.TextArea
        Spinner_5                      matlab.ui.control.Spinner
        DropDown_9                     matlab.ui.control.DropDown
        DropDown_10                    matlab.ui.control.DropDown
        StarttimeLabel_2               matlab.ui.control.Label
        DurationminsEditFieldLabel     matlab.ui.control.Label
        DurationminsEditField          matlab.ui.control.NumericEditField
        RootfilenameEditFieldLabel     matlab.ui.control.Label
        RootfilenameEditField          matlab.ui.control.EditField
        Switch                         matlab.ui.control.RockerSwitch
        AEditFieldLabel                matlab.ui.control.Label
        AEditField                     matlab.ui.control.NumericEditField
        StimulusanimalIDs0foremptyLabel  matlab.ui.control.Label
        BEditFieldLabel                matlab.ui.control.Label
        BEditField                     matlab.ui.control.NumericEditField
        CEditFieldLabel                matlab.ui.control.Label
        CEditField                     matlab.ui.control.NumericEditField
        Lamp_isRecorded                matlab.ui.control.Lamp
        EventdateDatePickerLabel       matlab.ui.control.Label
        EventdateDatePicker            matlab.ui.control.DatePicker
        SubmitevententryButton         matlab.ui.control.Button
        PersonDropDownLabel            matlab.ui.control.Label
        PersonDropDown                 matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        ax_ageslider % axis for age slider
        l_ageslider  % line for age slider
    end
    
    methods (Access = private)
        % Function that responds to the movement of the sliders
        function lMoving(app, source)
            % Always keep the position rounded to 1 decimal place
            source.Position(1,1) = round(source.Position(1,1),1);
            source.Position(2,1) = round(source.Position(2,1),1);
            % make sure y stays at 0
            source.Position(1,2) = 0;
            source.Position(2,2) = 0;
            %insert numbers into edit boxes
            app.ageLow_edit.Value = source.Position(1,1);
            app.ageHigh_edit.Value = source.Position(2,1);            
            % Add any other action here triggered by the movement of the sliders            
        end
         
        function loadAnimalTable(app, filter_query)
            allAnimals = fetch(sl_test.Animal & sl_test.AnimalLive & filter_query,...
                'sex','is_tagged','dob','genotype_name');
            cageNumbers = fetch(sl_test.AnimalLive,...
                'cage_number');
            N = length(allAnimals);
            set(app.AnimalSelectionPanel, 'Title', ...
                ['Animal Selection: ' num2str(N) ' total']);            
            T = table('Size',[N,5],'VariableTypes', ...
                {'uint32','string','string','string','uint32'});
            for i=1:N
                T{i,1} = allAnimals(i).animal_id;
                T{i,2} = string(allAnimals(i).genotype_name);
                T{i,3} = "TODO";
                T{i,4} = "TODO:age";
                T{i,5} = cageNumbers(i).cage_number;
            end
            set(app.AnimalTable,'Data',T);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            % Create the 1D axes
            app.ax_ageslider = axes( ...
                app.AgerangeweeksPanel,'Color','none','YColor','none','XLim',[0,25],'YTick',[], ...
                'XTick',[0 5 10 15 20 25], ...
                'XTickLabel',{'0','5','10','15','20','25+'}, ...
                'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
                'on','Units','normalized','Position',[.1,.7,0.7,0.8] ...
                );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.ax_ageslider);
            app.ax_ageslider.Toolbar.Visible = 'off';
            % Add the line ROI
            app.l_ageslider = images.roi.Line(app.ax_ageslider ...
                ,'Position',[4,0;8,0]);
            set(app.ageLow_edit, 'Value', 4);
            set(app.ageHigh_edit, 'Value', 8);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.l_ageslider,'MovingROI',@(varargin)lMoving(app,app.l_ageslider));
            
            %TODO make initial filter query
            filter_query = [];
            
            %load animal table
            app.loadAnimalTable(filter_query);
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Recorded')
                app.Lamp_isRecorded.Enable = true;
                app.RootfilenameEditField.Enable = true;
            else
                app.Lamp_isRecorded.Enable = false;
                app.RootfilenameEditField.Value = ' ';
                app.RootfilenameEditField.Enable = false;
            end
        end

        % Value changed function: ageLow_edit
        function ageLow_editValueChanged(app, event)
            value = app.ageLow_edit.Value;
            P = get(app.l_ageslider, 'Position');
            P(1,1) = value;
            if value > P(2,1) %higher limit is below this
                P(2,1) = value;
                set(app.ageHigh_edit, 'Value', value);
            end
            set(app.l_ageslider, 'Position', P);
        end

        % Value changed function: ageHigh_edit
        function ageHigh_editValueChanged(app, event)
            value = app.ageHigh_edit.Value;
            P = get(app.l_ageslider, 'Position');
            P(2,1) = value;
            if value < P(1,1) %lower limit is above this
                P(1,1) = value;
                set(app.ageLow_edit, 'Value', value);
            end
            set(app.l_ageslider, 'Position', P);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnimalLoggerUIFigure = uifigure('Visible', 'off');
            app.AnimalLoggerUIFigure.Name = 'Animal Logger';
            app.AnimalTable = uitable(app.AnimalLoggerUIFigure);
            app.AnimalTable.ColumnName = {'Animal ID'; 'Genotype'; 'Genotype status'; 'Age'; 'Cage #'};
            app.AnimalTable.RowName = {};
            app.AnimalTable.ColumnSortable = true;
            app.AnimalSelectionPanel = uipanel(app.AnimalLoggerUIFigure);
            app.AnimalSelectionPanel.Title = 'Animal Selection';
            app.SearchButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.SearchButton.Text = 'Search';
            app.IDEditFieldLabel = uilabel(app.AnimalSelectionPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.AnimalSelectionPanel, 'numeric');
            app.FilterPanel = uipanel(app.AnimalSelectionPanel);
            app.FilterPanel.Title = 'Filter';
            app.GenotypeDropDownLabel = uilabel(app.FilterPanel);
            app.GenotypeDropDownLabel.Text = 'Genotype';
            app.GenotypeDropDown = uidropdown(app.FilterPanel);
            app.StatusDropDownLabel = uilabel(app.FilterPanel);
            app.StatusDropDownLabel.Text = 'Status';
            app.StatusDropDown = uidropdown(app.FilterPanel);
            app.StatusDropDown.Items = {'all', 'carrier', 'non-carrier', 'unknown', 'het', 'homo'};
            app.StatusDropDown.Value = 'all';
            app.AgerangeweeksPanel = uipanel(app.FilterPanel);
            app.AgerangeweeksPanel.Title = 'Age range (weeks)';
            app.ageLow_edit = uieditfield(app.AgerangeweeksPanel, 'numeric');
            app.ageLow_edit.ValueChangedFcn = createCallbackFcn(app, @ageLow_editValueChanged, true);
            app.ageHigh_edit = uieditfield(app.AgerangeweeksPanel, 'numeric');
            app.ageHigh_edit.ValueChangedFcn = createCallbackFcn(app, @ageHigh_editValueChanged, true);
            app.toLabel = uilabel(app.AgerangeweeksPanel);
            app.toLabel.Text = 'to';
            app.VieweventlogButton = uibutton(app.AnimalSelectionPanel, 'push');
            app.VieweventlogButton.Text = 'View event log';
            app.AddentriesPanel = uipanel(app.AnimalLoggerUIFigure);
            app.AddentriesPanel.Title = 'Add entries';
            app.AddanimalButton = uibutton(app.AddentriesPanel, 'push');
            app.AddanimalButton.Text = 'Add animal';
            app.AddgenotypeButton_2 = uibutton(app.AddentriesPanel, 'push');
            app.AddgenotypeButton_2.Text = 'Add genotype';
            app.AddsubstanceButton = uibutton(app.AddentriesPanel, 'push');
            app.AddsubstanceButton.Text = 'Add substance';
            app.TabGroup = uitabgroup(app.AnimalLoggerUIFigure);
            app.GenotypedTab = uitab(app.TabGroup);
            app.GenotypedTab.Title = 'Genotyped';
            app.ResultsDropDownLabel = uilabel(app.GenotypedTab);
            app.ResultsDropDownLabel.Text = 'Results';
            app.ResultsDropDown = uidropdown(app.GenotypedTab);
            app.ResultsDropDown.Items = {'carrier', 'non-carrier', 'unknown', 'het', 'homo'};
            app.ResultsDropDown.Value = 'carrier';
            app.NotesTextAreaLabel = uilabel(app.GenotypedTab);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.GenotypedTab);
            app.DeceasedTab = uitab(app.TabGroup);
            app.DeceasedTab.Title = 'Deceased';
            app.ReasonDropDownLabel = uilabel(app.DeceasedTab);
            app.ReasonDropDownLabel.Text = 'Reason';
            app.ReasonDropDown = uidropdown(app.DeceasedTab);
            app.ReasonDropDown.Items = {'sacrified for experiment', 'sacrificed because not needed', 'other', 'unknown'};
            app.ReasonDropDown.Value = 'sacrified for experiment';
            app.NotesTextArea_2Label = uilabel(app.DeceasedTab);
            app.NotesTextArea_2Label.Text = 'Notes';
            app.NotesTextArea_2 = uitextarea(app.DeceasedTab);
            app.GavebirthTab = uitab(app.TabGroup);
            app.GavebirthTab.Title = 'Gave birth';
            app.NotesTextArea_3Label = uilabel(app.GavebirthTab);
            app.NotesTextArea_3Label.Text = 'Notes';
            app.NotesTextArea_3 = uitextarea(app.GavebirthTab);
            app.NumberofpupsSpinnerLabel = uilabel(app.GavebirthTab);
            app.NumberofpupsSpinnerLabel.Text = 'Number of pups';
            app.NumberofpupsSpinner = uispinner(app.GavebirthTab);
            app.MovecageTab = uitab(app.TabGroup);
            app.MovecageTab.Title = 'Move cage';
            app.ReasonDropDown_2Label = uilabel(app.MovecageTab);
            app.ReasonDropDown_2Label.Text = 'Reason';
            app.ReasonDropDown_2 = uidropdown(app.MovecageTab);
            app.ReasonDropDown_2.Items = {'weaning', 'experiment', 'set as breeder', 'crowding', 'other', 'unknown'};
            app.ReasonDropDown_2.Value = 'weaning';
            app.NotesTextArea_4Label = uilabel(app.MovecageTab);
            app.NotesTextArea_4Label.Text = 'Notes';
            app.NotesTextArea_4 = uitextarea(app.MovecageTab);
            app.FromcageEditFieldLabel = uilabel(app.MovecageTab);
            app.FromcageEditFieldLabel.Text = 'From cage #';
            app.FromcageEditField = uieditfield(app.MovecageTab, 'numeric');
            app.TocageEditFieldLabel = uilabel(app.MovecageTab);
            app.TocageEditFieldLabel.Text = 'To cage #';
            app.TocageEditField = uieditfield(app.MovecageTab, 'numeric');
            app.IPinjectionTab = uitab(app.TabGroup);
            app.IPinjectionTab.Title = 'IP injection';
            app.NotesTextArea_5Label = uilabel(app.IPinjectionTab);
            app.NotesTextArea_5Label.Text = 'Notes';
            app.NotesTextArea_5 = uitextarea(app.IPinjectionTab);
            app.SubstanceDropDownLabel = uilabel(app.IPinjectionTab);
            app.SubstanceDropDownLabel.Text = 'Substance';
            app.SubstanceDropDown = uidropdown(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel = uilabel(app.IPinjectionTab);
            app.ConcentrationmgKgEditFieldLabel.Text = 'Concentration (mg / Kg)';
            app.ConcentrationmgKgEditField = uieditfield(app.IPinjectionTab, 'numeric');
            app.Spinner = uispinner(app.IPinjectionTab);
            app.Spinner.Limits = [1 12];
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.Value = 10;
            app.DropDown = uidropdown(app.IPinjectionTab);
            app.DropDown.Items = {'AM', 'PM'};
            app.DropDown.Value = 'AM';
            app.DropDown_2 = uidropdown(app.IPinjectionTab);
            app.DropDown_2.Items = {'00', '15', '30', '45'};
            app.DropDown_2.Value = '00';
            app.TimeLabel = uilabel(app.IPinjectionTab);
            app.TimeLabel.Text = 'Time';
            app.EyeinjectionTab = uitab(app.TabGroup);
            app.EyeinjectionTab.Title = 'Eye injection';
            app.SubstanceDropDownLabel_2 = uilabel(app.EyeinjectionTab);
            app.SubstanceDropDownLabel_2.Text = 'Substance';
            app.SubstanceDropDown_2 = uidropdown(app.EyeinjectionTab);
            app.NotesTextArea_7Label = uilabel(app.EyeinjectionTab);
            app.NotesTextArea_7Label.Text = 'Notes';
            app.NotesTextArea_7 = uitextarea(app.EyeinjectionTab);
            app.Spinner_3 = uispinner(app.EyeinjectionTab);
            app.Spinner_3.Limits = [1 12];
            app.Spinner_3.RoundFractionalValues = 'on';
            app.Spinner_3.Value = 10;
            app.DropDown_5 = uidropdown(app.EyeinjectionTab);
            app.DropDown_5.Items = {'AM', 'PM'};
            app.DropDown_5.Value = 'AM';
            app.DropDown_6 = uidropdown(app.EyeinjectionTab);
            app.DropDown_6.Items = {'00', '15', '30', '45'};
            app.DropDown_6.Value = '00';
            app.TimeLabel_2 = uilabel(app.EyeinjectionTab);
            app.TimeLabel_2.Text = 'Time';
            app.EyeDropDownLabel = uilabel(app.EyeinjectionTab);
            app.EyeDropDownLabel.Text = 'Eye';
            app.EyeDropDown = uidropdown(app.EyeinjectionTab);
            app.EyeDropDown.Items = {'Left', 'Right'};
            app.EyeDropDown.Value = 'Left';
            app.Dilution1XEditFieldLabel = uilabel(app.EyeinjectionTab);
            app.Dilution1XEditFieldLabel.Text = 'Dilution (1:X)';
            app.Dilution1XEditField = uieditfield(app.EyeinjectionTab, 'numeric');
            app.Dilution1XEditField.Limits = [1 Inf];
            app.Dilution1XEditField.Value = 1;
            app.BraininjectionTab = uitab(app.TabGroup);
            app.BraininjectionTab.Title = 'Brain injection';
            app.SubstanceDropDown_3Label = uilabel(app.BraininjectionTab);
            app.SubstanceDropDown_3Label.Text = 'Substance';
            app.SubstanceDropDown_3 = uidropdown(app.BraininjectionTab);
            app.Dilution1XEditField_2Label = uilabel(app.BraininjectionTab);
            app.Dilution1XEditField_2Label.Text = 'Dilution (1:X)';
            app.Dilution1XEditField_2 = uieditfield(app.BraininjectionTab, 'numeric');
            app.Dilution1XEditField_2.Limits = [1 Inf];
            app.Dilution1XEditField_2.Value = 1;
            app.NotesTextArea_8Label = uilabel(app.BraininjectionTab);
            app.NotesTextArea_8Label.Text = 'Notes';
            app.NotesTextArea_8 = uitextarea(app.BraininjectionTab);
            app.CoordinatesLabel = uilabel(app.BraininjectionTab);
            app.CoordinatesLabel.Text = 'Coordinates:';
            app.TargetDropDownLabel = uilabel(app.BraininjectionTab);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.BraininjectionTab);
            app.TargetDropDown.Items = {'MeA', 'LHA', 'OPN', 'vLGN', 'dLGN', 'SC'};
            app.TargetDropDown.Value = 'MeA';
            app.HemisphereDropDownLabel = uilabel(app.BraininjectionTab);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.BraininjectionTab);
            app.HemisphereDropDown.Items = {'Left', 'Right'};
            app.HemisphereDropDown.Value = 'Left';
            app.APEditFieldLabel = uilabel(app.BraininjectionTab);
            app.APEditFieldLabel.Text = 'AP';
            app.APEditField = uieditfield(app.BraininjectionTab, 'numeric');
            app.MLEditFieldLabel = uilabel(app.BraininjectionTab);
            app.MLEditFieldLabel.Text = 'ML';
            app.MLEditField = uieditfield(app.BraininjectionTab, 'numeric');
            app.DVEditFieldLabel = uilabel(app.BraininjectionTab);
            app.DVEditFieldLabel.Text = 'DV';
            app.DVEditField = uieditfield(app.BraininjectionTab, 'numeric');
            app.AngleEditFieldLabel = uilabel(app.BraininjectionTab);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.BraininjectionTab, 'numeric');
            app.Spinner_4 = uispinner(app.BraininjectionTab);
            app.Spinner_4.Limits = [1 12];
            app.Spinner_4.RoundFractionalValues = 'on';
            app.Spinner_4.Value = 10;
            app.DropDown_7 = uidropdown(app.BraininjectionTab);
            app.DropDown_7.Items = {'AM', 'PM'};
            app.DropDown_7.Value = 'AM';
            app.DropDown_8 = uidropdown(app.BraininjectionTab);
            app.DropDown_8.Items = {'00', '15', '30', '45'};
            app.DropDown_8.Value = '00';
            app.TimeLabel_3 = uilabel(app.BraininjectionTab);
            app.TimeLabel_3.Text = 'Time';
            app.HandledTab = uitab(app.TabGroup);
            app.HandledTab.Title = 'Handled';
            app.NotesTextArea_6Label = uilabel(app.HandledTab);
            app.NotesTextArea_6Label.Text = 'Notes';
            app.NotesTextArea_6 = uitextarea(app.HandledTab);
            app.Spinner_2 = uispinner(app.HandledTab);
            app.Spinner_2.Limits = [1 12];
            app.Spinner_2.RoundFractionalValues = 'on';
            app.Spinner_2.Value = 10;
            app.DropDown_3 = uidropdown(app.HandledTab);
            app.DropDown_3.Items = {'AM', 'PM'};
            app.DropDown_3.Value = 'AM';
            app.DropDown_4 = uidropdown(app.HandledTab);
            app.DropDown_4.Items = {'00', '15', '30', '45'};
            app.DropDown_4.Value = '00';
            app.StarttimeLabel = uilabel(app.HandledTab);
            app.StarttimeLabel.Text = 'Start time';
            app.DurationminutesEditFieldLabel = uilabel(app.HandledTab);
            app.DurationminutesEditFieldLabel.Text = 'Duration (minutes)';
            app.DurationminutesEditField = uieditfield(app.HandledTab, 'numeric');
            app.DurationminutesEditField.Limits = [0 360];
            app.DurationminutesEditField.RoundFractionalValues = 'on';
            app.SocialbehaviorsessionTab = uitab(app.TabGroup);
            app.SocialbehaviorsessionTab.Title = 'Social behavior session';
            app.NotesTextArea_9Label = uilabel(app.SocialbehaviorsessionTab);
            app.NotesTextArea_9Label.Text = 'Notes';
            app.NotesTextArea_9 = uitextarea(app.SocialbehaviorsessionTab);
            app.Spinner_5 = uispinner(app.SocialbehaviorsessionTab);
            app.Spinner_5.Limits = [1 12];
            app.Spinner_5.RoundFractionalValues = 'on';
            app.Spinner_5.Value = 10;
            app.DropDown_9 = uidropdown(app.SocialbehaviorsessionTab);
            app.DropDown_9.Items = {'AM', 'PM'};
            app.DropDown_9.Value = 'AM';
            app.DropDown_10 = uidropdown(app.SocialbehaviorsessionTab);
            app.DropDown_10.Items = {'00', '15', '30', '45'};
            app.DropDown_10.Value = '00';
            app.StarttimeLabel_2 = uilabel(app.SocialbehaviorsessionTab);
            app.StarttimeLabel_2.Text = 'Start time';
            app.DurationminsEditFieldLabel = uilabel(app.SocialbehaviorsessionTab);
            app.DurationminsEditFieldLabel.Text = 'Duration (mins)';
            app.DurationminsEditField = uieditfield(app.SocialbehaviorsessionTab, 'numeric');
            app.RootfilenameEditFieldLabel = uilabel(app.SocialbehaviorsessionTab);
            app.RootfilenameEditFieldLabel.Text = 'Root filename';
            app.RootfilenameEditField = uieditfield(app.SocialbehaviorsessionTab, 'text');
            app.Switch = uiswitch(app.SocialbehaviorsessionTab, 'rocker');
            app.Switch.Items = {'Not recorded', 'Recorded'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Recorded';
            app.AEditFieldLabel = uilabel(app.SocialbehaviorsessionTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.SocialbehaviorsessionTab, 'numeric');
            app.StimulusanimalIDs0foremptyLabel = uilabel(app.SocialbehaviorsessionTab);
            app.StimulusanimalIDs0foremptyLabel.Text = 'Stimulus animal IDs (0 for empty)';
            app.BEditFieldLabel = uilabel(app.SocialbehaviorsessionTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.SocialbehaviorsessionTab, 'numeric');
            app.CEditFieldLabel = uilabel(app.SocialbehaviorsessionTab);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.SocialbehaviorsessionTab, 'numeric');
            app.Lamp_isRecorded = uilamp(app.SocialbehaviorsessionTab);
            app.EventdateDatePickerLabel = uilabel(app.AnimalLoggerUIFigure);
            app.EventdateDatePickerLabel.Text = 'Event date';
            app.EventdateDatePicker = uidatepicker(app.AnimalLoggerUIFigure);
            app.SubmitevententryButton = uibutton(app.AnimalLoggerUIFigure, 'push');
            app.SubmitevententryButton.Text = 'Submit event entry';
            app.PersonDropDownLabel = uilabel(app.AnimalLoggerUIFigure);
            app.PersonDropDownLabel.Text = 'Person';
            app.PersonDropDown = uidropdown(app.AnimalLoggerUIFigure);
            app.PersonDropDown.Items = {'David', 'Devon', 'Greg', 'Kayla', 'Sam', 'Sophia', 'Susan'};
            app.PersonDropDown.Value = 'David';
            % Show the figure after all components are created
            app.AnimalLoggerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnimalLogger(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnimalLoggerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnimalLoggerUIFigure)
        end
    end
end