classdef Curator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CuratorUIFigure               matlab.ui.Figure
        EpochnumberSlider             matlab.ui.control.Slider
        EpochnumberSliderLabel        matlab.ui.control.Label
        LoadcellButton                matlab.ui.control.Button
        WorkingcellEditField          matlab.ui.control.EditField
        WorkingcellEditFieldLabel     matlab.ui.control.Label
        FilterconstructionLabel       matlab.ui.control.Label
        ParametersforselectedepochLabel  matlab.ui.control.Label
        SelectedValTable              matlab.ui.control.Table
        ParmeterswithmultiplevaluesLabel  matlab.ui.control.Label
        MultiValTable                 matlab.ui.control.Table
        FilterTable                   matlab.ui.control.Table
        FilterpatternstringEditField  matlab.ui.control.EditField
        FilterpatternstringEditFieldLabel  matlab.ui.control.Label
        DatasetsDropDown              matlab.ui.control.DropDown
        DatasetsDropDownLabel         matlab.ui.control.Label
        DeletedatasetButton           matlab.ui.control.Button
        RenamedatasetButton           matlab.ui.control.Button
        SavedatasetButton             matlab.ui.control.Button
        DatachannelDropDown           matlab.ui.control.DropDown
        DatachannelDropDownLabel      matlab.ui.control.Label
        XaxisDropDown                 matlab.ui.control.DropDown
        XaxisDropDownLabel            matlab.ui.control.Label
        YaxisDropDown                 matlab.ui.control.DropDown
        YaxisDropDownLabel            matlab.ui.control.Label
        RawAxes                       matlab.ui.control.UIAxes
        DiaryAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        loaded_cell % query for currently loaded cell
        epoch_ids % all epoch_ids for loaded cell
        loaded_epoch %query for currently loaded epoch
    end
    
    methods (Access = private)
        
        function init(app)
            
        end
        
        function loadCell(app, cellname)
            s.file_name = cellname(1:7);
            s.rig_name = cellname(7);
            s.cell_number = str2double(cellname(9:end));
            q = sln_symphony.Experiment * sln_symphony.ExperimentCell & s;
            if ~q.exists
                errordlg(sprintf('%s not found', cellname));
            elseif q.count > 1
                errordlg(sprintf('%s: more than 1 matching cell', cellname));
            else
                app.loaded_cell = q;
                app.WorkingcellEditField.Value = cellname;
                app.epoch_ids = sort(fetchn(aka.Epoch & app.loaded_cell, 'epoch_id'), 'ascend');
                app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(1));
                app.EpochnumberSlider.Limits = [app.epoch_ids(1), app.epoch_ids(end)];
                app.EpochnumberSlider.Value = app.epoch_ids(1);
                app.EpochnumberSlider.MajorTicks = round(linspace(app.epoch_ids(1), app.epoch_ids(end), 10));

                app.plotCurrentEpoch();
            end
        end
        
        function plotCurrentEpoch(app)
            [timeAxis, data] = sln_symphony.getEpochRawData(app.loaded_epoch);    
            epoch_number = fetch1(app.loaded_epoch, 'epoch_id');
            recording_mode = fetch1(sln_symphony.ExperimentElectrode & app.loaded_epoch, 'recording_mode');
            if strcmp(recording_mode,'Current clamp') %% THIS IS STILL BACKWARDS!
                units = 'pA';
            else
                units = 'mV';
            end
            
            plot(app.RawAxes,timeAxis,data,'k');
            ylabel(app.RawAxes, units);
            title(app.RawAxes, sprintf('Epoch %d', epoch_number));                        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, init_cellname)
            app.init()
            if ~isempty(init_cellname)
                app.loadCell(init_cellname);
            end
        end

        % Button pushed function: LoadcellButton
        function LoadcellButtonPushed(app, event)
            app.loadCell(app.WorkingcellEditField.Value);
        end

        % Value changed function: EpochnumberSlider
        function EpochnumberSliderValueChanged(app, event)
            value = app.EpochnumberSlider.Value;
            d = app.epoch_ids - value;
            [~, ind] = min(abs(d));
            app.loaded_epoch = aka.Epoch & app.loaded_cell & sprintf('epoch_id = %d', app.epoch_ids(ind(1))); 
            app.plotCurrentEpoch;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CuratorUIFigure = uifigure('Visible', 'off');
            app.CuratorUIFigure.Name = 'Curator';
            app.DiaryAxes = uiaxes(app.CuratorUIFigure);
            app.RawAxes = uiaxes(app.CuratorUIFigure);
            xlabel(app.RawAxes, 'Epoch time (s)')
            app.YaxisDropDownLabel = uilabel(app.CuratorUIFigure);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.CuratorUIFigure);
            app.XaxisDropDownLabel = uilabel(app.CuratorUIFigure);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.CuratorUIFigure);
            app.XaxisDropDown.Items = {'Time', 'Epoch number'};
            app.XaxisDropDown.Value = 'Time';
            app.DatachannelDropDownLabel = uilabel(app.CuratorUIFigure);
            app.DatachannelDropDownLabel.Text = 'Data channel';
            app.DatachannelDropDown = uidropdown(app.CuratorUIFigure);
            app.SavedatasetButton = uibutton(app.CuratorUIFigure, 'push');
            app.SavedatasetButton.Text = 'Save dataset';
            app.RenamedatasetButton = uibutton(app.CuratorUIFigure, 'push');
            app.RenamedatasetButton.Text = 'Rename dataset';
            app.DeletedatasetButton = uibutton(app.CuratorUIFigure, 'push');
            app.DeletedatasetButton.Text = 'Delete dataset';
            app.DatasetsDropDownLabel = uilabel(app.CuratorUIFigure);
            app.DatasetsDropDownLabel.Text = 'Data sets';
            app.DatasetsDropDown = uidropdown(app.CuratorUIFigure);
            app.FilterpatternstringEditFieldLabel = uilabel(app.CuratorUIFigure);
            app.FilterpatternstringEditFieldLabel.Text = 'Filter pattern string';
            app.FilterpatternstringEditField = uieditfield(app.CuratorUIFigure, 'text');
            app.FilterTable = uitable(app.CuratorUIFigure);
            app.FilterTable.ColumnName = {'#'; 'Param'; 'Operator'; 'Value'};
            app.FilterTable.RowName = {};
            app.MultiValTable = uitable(app.CuratorUIFigure);
            app.MultiValTable.ColumnName = {'Param'; 'Values'};
            app.MultiValTable.RowName = {};
            app.ParmeterswithmultiplevaluesLabel = uilabel(app.CuratorUIFigure);
            app.ParmeterswithmultiplevaluesLabel.Text = 'Parmeters with multiple values';
            app.SelectedValTable = uitable(app.CuratorUIFigure);
            app.SelectedValTable.ColumnName = {'Param'; 'Value'};
            app.SelectedValTable.RowName = {};
            app.ParametersforselectedepochLabel = uilabel(app.CuratorUIFigure);
            app.ParametersforselectedepochLabel.Text = 'Parameters for selected epoch';
            app.FilterconstructionLabel = uilabel(app.CuratorUIFigure);
            app.FilterconstructionLabel.Text = 'Filter construction';
            app.WorkingcellEditFieldLabel = uilabel(app.CuratorUIFigure);
            app.WorkingcellEditFieldLabel.Text = 'Working cell';
            app.WorkingcellEditField = uieditfield(app.CuratorUIFigure, 'text');
            app.LoadcellButton = uibutton(app.CuratorUIFigure, 'push');
            app.LoadcellButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcellButtonPushed, true);
            app.LoadcellButton.Text = 'Load cell';
            app.EpochnumberSliderLabel = uilabel(app.CuratorUIFigure);
            app.EpochnumberSliderLabel.Text = 'Epoch number';
            app.EpochnumberSlider = uislider(app.CuratorUIFigure);
            app.EpochnumberSlider.ValueChangedFcn = createCallbackFcn(app, @EpochnumberSliderValueChanged, true);
            app.EpochnumberSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.CuratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Curator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CuratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CuratorUIFigure)
        end
    end
end