classdef DataGrouper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataGrouperUIFigure        matlab.ui.Figure
        IncluderawtracesCheckBox   matlab.ui.control.CheckBox
        ExportDataGroupButton      matlab.ui.control.Button
        BrowserPanel               matlab.ui.container.Panel
        CustomLabelEdit            matlab.ui.control.EditField
        CustomAnalysisName         matlab.ui.control.EditField
        LabelDrownDown             matlab.ui.control.DropDown
        NewlabelButton             matlab.ui.control.Button
        OtherButton                matlab.ui.control.Button
        AnalysisDropDown           matlab.ui.control.DropDown
        AddlabelButton             matlab.ui.control.Button
        RunanalysisButton          matlab.ui.control.Button
        DataElementsListBox        matlab.ui.control.ListBox
        NEditField                 matlab.ui.control.NumericEditField
        NLabel                     matlab.ui.control.Label
        ViewlevelDropDown          matlab.ui.control.DropDown
        ViewlevelDropDownLabel     matlab.ui.control.Label
        CommentTextArea            matlab.ui.control.TextArea
        CommentTextAreaLabel       matlab.ui.control.Label
        ResultsPanel               matlab.ui.container.Panel
        LoadqueryButton            matlab.ui.control.Button
        experimentsEditField       matlab.ui.control.EditField
        experimentsEditFieldLabel  matlab.ui.control.Label
        epochsEditField            matlab.ui.control.EditField
        epochsLabel                matlab.ui.control.Label
        SavequeryButton            matlab.ui.control.Button
        datasetsEditField          matlab.ui.control.EditField
        datasetsEditField_3Label   matlab.ui.control.Label
        eyesEditField              matlab.ui.control.EditField
        eyesEditFieldLabel         matlab.ui.control.Label
        cellsEditField             matlab.ui.control.EditField
        cellsEditField_2Label      matlab.ui.control.Label
        animalsEditField           matlab.ui.control.EditField
        animalsEditFieldLabel      matlab.ui.control.Label
        RunButton                  matlab.ui.control.Button
        TypeEditField              matlab.ui.control.EditField
        TypeEditFieldLabel         matlab.ui.control.Label
        JoinedtablesPanel          matlab.ui.container.Panel
        AddButton                  matlab.ui.control.Button
        TablesToJoinDropDown       matlab.ui.control.DropDown
        RemoveButton               matlab.ui.control.Button
        TablestoJoinListBox        matlab.ui.control.ListBox
        LogicaloperatorsPanel      matlab.ui.container.Panel
        clearButton                matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        ANDButton                  matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        ANYOFButton                matlab.ui.control.Button
        NOTButton                  matlab.ui.control.Button
        DeletelastelementButton    matlab.ui.control.Button
        AddconditionwithattributeButton  matlab.ui.control.Button
        QueryconstructionPanel     matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        LogicButton_20             matlab.ui.control.StateButton
        LogicButton_18             matlab.ui.control.StateButton
        LogicButton_16             matlab.ui.control.StateButton
        LogicButton_14             matlab.ui.control.StateButton
        LogicButton_12             matlab.ui.control.StateButton
        LogicButton_10             matlab.ui.control.StateButton
        LogicButton_8              matlab.ui.control.StateButton
        LogicButton_6              matlab.ui.control.StateButton
        LogicButton_4              matlab.ui.control.StateButton
        LogicButton_2              matlab.ui.control.StateButton
        OpDrop_21                  matlab.ui.control.DropDown
        OpDrop_19                  matlab.ui.control.DropDown
        OpDrop_17                  matlab.ui.control.DropDown
        OpDrop_15                  matlab.ui.control.DropDown
        OpDrop_13                  matlab.ui.control.DropDown
        OpDrop_11                  matlab.ui.control.DropDown
        OpDrop_9                   matlab.ui.control.DropDown
        OpDrop_7                   matlab.ui.control.DropDown
        OpDrop_5                   matlab.ui.control.DropDown
        OpDrop_3                   matlab.ui.control.DropDown
        OpDrop_1                   matlab.ui.control.DropDown
        EditField_Q21              matlab.ui.control.EditField
        EditField_Q19              matlab.ui.control.EditField
        EditField_Q17              matlab.ui.control.EditField
        EditField_Q15              matlab.ui.control.EditField
        EditField_Q13              matlab.ui.control.EditField
        EditField_Q11              matlab.ui.control.EditField
        EditField_Q9               matlab.ui.control.EditField
        EditField_Q7               matlab.ui.control.EditField
        EditField_Q5               matlab.ui.control.EditField
        EditField_Q3               matlab.ui.control.EditField
        EditField_Q1               matlab.ui.control.EditField
        AttributesListBox          matlab.ui.control.ListBox
        AttributesListBoxLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        searchTable % joined table for running the query
        connection % DJ connnection
        queryParts % cell array of items in the query
        rowInd = 1; % current row for entering stuff into the GUI panel on the right
        queryEntryObjects % list of handles to the ui elements in column 3
        queryState % full state of the query
        queryResult % result of last run query
        lookup_attributes = {... }% attributes for which we need to use a table lookup and the corresponding tables and props
            'rig_name', 'sl.Rig', 'rig_name'; ...
            'cell_type', 'sl.CellType', 'name_full'; ...
            'experimenter', 'sl.User', 'user_name'; ...
            'genotype_name', 'sl.Genotype', 'genotype_name'; ...
            'pipeline_name', 'sl.Pipeline', 'pipeline_name'; ...
        }
        results_db; % database for looking up results
    end
    
    properties (Access = public)
        dataHandle = []; % for returing stuff like the query result
    end
    
    methods (Access = private)

        function updateSearchTable(app)
            currentTables = get(app.TablestoJoinListBox,'items');
            commandStr = 'app.searchTable=';
            N = length(currentTables);
            for i=1:N
                commandStr = [commandStr, currentTables{i} '*'];
            end
            %remove trailing *
            commandStr = commandStr(1:end-1)
            eval(commandStr);      
            
            %populate attribute list
            set(app.AttributesListBox, 'items', sort(app.searchTable.header.notBlobs));
        end
        
        function entryNuller(app, event)
            value = event.Value;
            [~, rowInd] = strtok(event.Source.Tag,'_');
            rowInd = str2double(rowInd(2:end));
            h = app.queryEntryObjects(rowInd);
            if strcmp(value, 'is NULL')
                set(h,'visible',false);
            else
                set(h,'visible',true);
            end
            
        end
        
        function unselectOtherLogicButtons(app,event)
            [~, rowInd] = strtok(event.Source.Tag,'_');
            rowInd = str2double(rowInd(2:end));
            deactivateList = setdiff(2:2:20, rowInd);
            for i=1:length(deactivateList)
                set(app.(['LogicButton_' num2str(deactivateList(i))]), 'value', false);
            end
        end
        
        function name = getSelectedLogicButtonName(app)
            name = '';
            ind = [];
            for i=2:2:20
                if get(app.(['LogicButton_' num2str(i)]), 'value')
                    ind = [ind i];
                end
            end
            
            if length(ind) == 1
                name = ['LogicButton_' num2str(ind)];
            end
        end
        
        function setLogicButton(app, event)
            txt = get(event.Source, 'text');
            selectedButtonName = app.getSelectedLogicButtonName;
            if ~isempty(selectedButtonName)
                curTxt = get(app.(selectedButtonName),'text');           
                set(app.(selectedButtonName),'text', [curTxt, ' ', txt]);           
            end
        end
        
        function storeQueryState(app)
            colInd = 1:2:21;
            attributeColumn = struct('name','', 'type', ''); 
            operatorColumn = struct('items', {}, 'value', '');
            queryEntryColumn = struct('type','','visible', 1 ,'items', {}, 'value', '');
            logicButtonColumn = struct('visible', 0, 'text', '');
            
            allAttributeNames = {app.searchTable.header.attributes.name};
            
            for i=colInd
                if get(app.(['EditField_Q' num2str(i)]), 'visible')
                    attributeColumn(i).name = get(app.(['EditField_Q' num2str(i)]), 'value');
                    ind = find(strcmp(allAttributeNames,attributeColumn(i).name));
                    attributeColumn(i).type = app.searchTable.header.attributes(ind).type;
                    operatorColumn(i).items = get(app.(['OpDrop_' num2str(i)]), 'items');
                    operatorColumn(i).value = get(app.(['OpDrop_' num2str(i)]), 'value');
                    uiElement = app.queryEntryObjects(i);
                    queryEntryColumn(i).visible = double(get(uiElement, 'visible'));
                    queryEntryColumn(i).type = get(uiElement, 'type');
                    queryEntryColumn(i).value = get(uiElement, 'value');
                    if strcmp(queryEntryColumn(i).type, 'uidropdown')
                        queryEntryColumn(i).items = get(uiElement, 'items');
                    end
                end      
            end
            
            colInd = 2:2:20;
            for i=colInd
                if get(app.(['LogicButton_' num2str(i)]), 'visible')
                    logicButtonColumn(i).visible = 1;
                    logicButtonColumn(i).text = get(app.(['LogicButton_' num2str(i)]), 'text');                    
                end
            end
            
            queryState.results_db = app.results_db;
            queryState.rowInd = app.rowInd;
            queryState.attributeColumn = attributeColumn;
            queryState.operatorColumn = operatorColumn;
            queryState.queryEntryColumn = queryEntryColumn;
            queryState.logicButtonColumn = logicButtonColumn;
            queryState.currentTables = get(app.TablestoJoinListBox,'items');
            
            app.queryState = queryState;
        end

        function loadQueryState(app,queryState)
            app.rowInd = queryState.rowInd;
            if isfield(queryState, 'results_db')
                app.results_db = queryState.results_db;
            end
            set(app.TablestoJoinListBox,'items',queryState.currentTables);
            app.updateSearchTable();
            
            colInd = 1:2:length(queryState.attributeColumn);
            for i=colInd
                if ~isempty(queryState.attributeColumn(i).name)
                    set(app.(['EditField_Q' num2str(i)]), 'visible', true)
                    set(app.(['EditField_Q' num2str(i)]), 'value', queryState.attributeColumn(i).name);
                    set(app.(['OpDrop_' num2str(i)]), 'visible', true);
                    set(app.(['OpDrop_' num2str(i)]), 'items', queryState.operatorColumn(i).items);
                    set(app.(['OpDrop_' num2str(i)]), 'value', queryState.operatorColumn(i).value);
                    switch queryState.queryEntryColumn(i).type
                        case 'uieditfield'
                            app.queryEntryObjects(i) = uieditfield(app.GridLayout,'text');
                        case 'uinumericeditfield'
                            app.queryEntryObjects(i) = uieditfield(app.GridLayout,'numeric');
                        case 'uidropdown'
                            app.queryEntryObjects(i) = uidropdown(app.GridLayout);
                            set(app.queryEntryObjects(i),'items',queryState.queryEntryColumn(i).items);
                        case 'uidatepicker'
                            app.queryEntryObjects(i) = uidatepicker(app.GridLayout,'DisplayFormat','yyyy-MM-dd');
                    end
                    layoutObj = get(app.queryEntryObjects(i), 'Layout');
                    layoutObj.Column = 3;
                    layoutObj.Row = i;
                    set(app.queryEntryObjects(i), 'Layout', layoutObj)
                    set(app.queryEntryObjects(i), 'value', queryState.queryEntryColumn(i).value)
                    set(app.queryEntryObjects(i), 'visible', queryState.queryEntryColumn(i).visible);
                else
                    set(app.(['EditField_Q' num2str(i)]), 'visible', false)
                    set(app.(['OpDrop_' num2str(i)]), 'visible', false);
                end
            end
            
            colInd = 2:2:length(queryState.logicButtonColumn);
            for i=colInd
                if queryState.logicButtonColumn(i).visible
                    set(app.(['LogicButton_' num2str(i)]), 'visible', true);
                    set(app.(['LogicButton_' num2str(i)]), 'text', queryState.logicButtonColumn(i).text);
                else
                    set(app.(['LogicButton_' num2str(i)]), 'visible', false);
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %make initial joined table object
            app.updateSearchTable();
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            selectedTables = get(app.TablestoJoinListBox,'value');
            currentTables = get(app.TablestoJoinListBox,'items');
            if strcmp(selectedTables, 'Dataset')
                disp('Dataset cannot be removed');
            else
                set(app.TablestoJoinListBox, 'items', setdiff(currentTables, selectedTables));
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            selectedTable = get(app.TablesToJoinDropDown, 'value');
            currentTables = get(app.TablestoJoinListBox,'items');
            if ~ismember(selectedTable, currentTables)
                set(app.TablestoJoinListBox, 'items', [currentTables, selectedTable]);
                app.updateSearchTable();
            end
        end

        % Close request function: DataGrouperUIFigure
        function DataGrouperUIFigureCloseRequest(app, event)
            if ~isempty(app.connection) && app.connection.isConnected
                app.connection.close();
            end
            delete(app)  
        end

        % Value changed function: AttributesListBox
        function AttributesListBoxValueChanged(app, event)
            value = app.AttributesListBox.Value;
            allAttributeNames = {app.searchTable.header.attributes.name};
            ind = find(strcmp(allAttributeNames,value));
            set(app.CommentTextArea,'value',app.searchTable.header.attributes(ind).comment);
            set(app.TypeEditField,'value',app.searchTable.header.attributes(ind).type);
        end

        % Button pushed function: AddconditionwithattributeButton
        function AddconditionwithattributeButtonPushed(app, event)
            value = app.AttributesListBox.Value;
            allAttributeNames = {app.searchTable.header.attributes.name};
            ind = find(strcmp(allAttributeNames,value));
            attributeStruct = app.searchTable.header.attributes(ind);
            
            lookup_ind = strmatch(value,app.lookup_attributes(:,1));
            
            %test special case of starting with logical value
            if ~get(app.EditField_Q1,'visible') && ~get(app.EditField_Q3,'visible')
                firstLogicButtonText = strtrim(get(app.LogicButton_2, 'text'));
                if strcmp(firstLogicButtonText, 'NOT') || startsWith(firstLogicButtonText, 'ANY OF')
                    app.rowInd = app.rowInd + 2;
                end
            end
                        
            set(app.(['EditField_Q' num2str(app.rowInd)]), 'value', attributeStruct.name); 
            set(app.(['EditField_Q' num2str(app.rowInd)]), 'visible', true); 
            if app.rowInd>1
                set(app.(['LogicButton_' num2str(app.rowInd-1)]), 'visible', true); 
                if app.rowInd<20
                    set(app.(['LogicButton_' num2str(app.rowInd+1)]), 'visible', true); 
                    set(app.(['LogicButton_' num2str(app.rowInd+1)]), 'text', '');
                end
                if isempty(get(app.(['LogicButton_' num2str(app.rowInd-1)]), 'text'))
                    set(app.(['LogicButton_' num2str(app.rowInd-1)]), 'text', 'AND');
                end
            end
                        
            opField = ['OpDrop_' num2str(app.rowInd)];
            
            if ~isempty(lookup_ind) %special attribute that requires a lookup
                opList = {'=','!=','is NULL'};
                entryType = 'dropdown';
                entryList = unique(fetchn(eval(app.lookup_attributes{lookup_ind,2}), app.lookup_attributes{lookup_ind,3}));                
            elseif startsWith(attributeStruct.type, 'enum')
                opList = {'=','!=','is NULL'};
                entryType = 'dropdown';
                entryList = attributeStruct.type(5:end); %remove 'enum'
                entryList = strrep(entryList, '(', '{');
                entryList = strrep(entryList, ')', '}');
                entryList = eval(entryList);
            elseif strcmp(attributeStruct.type, 'date')
                opList = {'=', '>', '<', '>=', '<=', '!=', 'is NULL'};
                entryType = 'datePicker';
            elseif attributeStruct.isNumeric
                opList = {'=', '>', '<', '>=', '<=', '!=', 'is NULL'};
                entryType = 'numEdit';            
            elseif attributeStruct.isString
                opList = {'is', 'is like', 'is not', 'is NULL'};
                entryType = 'strEdit';
            end
            
            set(app.(opField), 'items', opList);
            set(app.(opField), 'visible', true);
            
            switch entryType
                case 'dropdown'
                    app.queryEntryObjects(app.rowInd) = uidropdown(app.GridLayout);
                    set(app.queryEntryObjects(app.rowInd),'items',sort(entryList));
                case 'numEdit'
                    app.queryEntryObjects(app.rowInd) = uieditfield(app.GridLayout,'numeric');
                case 'strEdit'
                    app.queryEntryObjects(app.rowInd) = uieditfield(app.GridLayout,'text');
                case 'datePicker'
                    app.queryEntryObjects(app.rowInd) = uidatepicker(app.GridLayout,'DisplayFormat','yyyy-MM-dd');
            end
            layoutObj = get(app.queryEntryObjects(app.rowInd), 'Layout');
            layoutObj.Column = 3;
            layoutObj.Row = app.rowInd;
            set(app.queryEntryObjects(app.rowInd), 'Layout', layoutObj)
            
            app.rowInd = app.rowInd + 2;
            
        end

        % Value changed function: TablestoJoinListBox
        function TablestoJoinListBoxValueChanged(app, event)
            app.updateSearchTable();
        end

        % Value changed function: OpDrop_1
        function OpDrop_1ValueChanged(app, event)
            app.entryNuller(event);            
        end

        % Value changed function: OpDrop_3
        function OpDrop_3ValueChanged(app, event)
            app.entryNuller(event);            
        end

        % Value changed function: OpDrop_5
        function OpDrop_5ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_7
        function OpDrop_7ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_9
        function OpDrop_9ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_11
        function OpDrop_11ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_13
        function OpDrop_13ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_15
        function OpDrop_15ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_17
        function OpDrop_17ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_19
        function OpDrop_19ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Value changed function: OpDrop_21
        function OpDrop_21ValueChanged(app, event)
            app.entryNuller(event);
        end

        % Button pushed function: DeletelastelementButton
        function DeletelastelementButtonPushed(app, event)
            if app.rowInd > 2
                app.rowInd = app.rowInd - 2;
                if app.rowInd > 2
                    set(app.(['LogicButton_' num2str(app.rowInd+1)]), 'visible', false);   
                end
                set(app.(['EditField_Q' num2str(app.rowInd)]), 'visible', false);
                set(app.(['OpDrop_' num2str(app.rowInd)]), 'visible', false);
                try
                    set(app.queryEntryObjects(app.rowInd),'visible',false);
                end
            end
        end

        % Value changed function: LogicButton_2
        function LogicButton_2ValueChanged(app, event)
            value = app.LogicButton_2.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_4
        function LogicButton_4ValueChanged(app, event)
            value = app.LogicButton_4.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_6
        function LogicButton_6ValueChanged(app, event)
            value = app.LogicButton_6.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_8
        function LogicButton_8ValueChanged(app, event)
            value = app.LogicButton_8.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_10
        function LogicButton_10ValueChanged(app, event)
            value = app.LogicButton_10.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_12
        function LogicButton_12ValueChanged(app, event)
            value = app.LogicButton_12.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_14
        function LogicButton_14ValueChanged(app, event)
            value = app.LogicButton_14.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_16
        function LogicButton_16ValueChanged(app, event)
            value = app.LogicButton_16.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_18
        function LogicButton_18ValueChanged(app, event)
            value = app.LogicButton_18.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Value changed function: LogicButton_20
        function LogicButton_20ValueChanged(app, event)
            value = app.LogicButton_20.Value;
            if value
                app.unselectOtherLogicButtons(event);
            end
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            selectedButtonName = app.getSelectedLogicButtonName;
            if ~isempty(selectedButtonName)
                set(app.(selectedButtonName), 'text', '');
            end            
        end

        % Button pushed function: NOTButton
        function NOTButtonPushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: ANYOFButton
        function ANYOFButtonPushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: ANDButton
        function ANDButtonPushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.setLogicButton(event);
        end

        % Button pushed function: SavequeryButton
        function SavequeryButtonPushed(app, event)
            app.storeQueryState();
            queryState = app.queryState;
            [fname, fpath] = uiputfile('.mat','Save query as...',getenv('queryFolder'));
            if fname
                save([fpath fname], 'queryState', '-mat');
            end            
        end

        % Button pushed function: LoadqueryButton
        function LoadqueryButtonPushed(app, event)
            [fname, fpath] = uigetfile('.mat','Load query file',getenv('queryFolder'));
            if fname
                load([fpath fname], 'queryState');
                app.loadQueryState(queryState);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.storeQueryState();
            app.queryResult = parseQueryStructV2(app.queryState);
            if ischar(app.queryResult) && isempty(app.queryResult)
                set(app.datasetsEditField, 'value', 'err');
            else
                animals = sl.Animal & app.queryResult.proj;
                set(app.animalsEditField, 'value', num2str(animals.count));
                eyes = sl.Eye & proj(sl.MeasuredRetinalCell) & app.queryResult.proj;
                set(app.eyesEditField, 'value', num2str(eyes.count));
                cells = sl.MeasuredCell & app.queryResult.proj;
                set(app.cellsEditField, 'value', num2str(cells.count));
                datasets = sl.Dataset & app.queryResult.proj;
                set(app.datasetsEditField, 'value', num2str(datasets.count));
                
                if ismember('epoch_ids', get(app.AttributesListBox,'items'))
                    ep_count = getEpochsInQuery(app.queryResult);
                    set(app.epochsEditField, 'value', num2str(ep_count));
                end
                
                if ~isempty(app.dataHandle)
                    app.dataHandle.data.queryState = app.queryState;
                    app.dataHandle.data.queryResult = app.queryResult;
                    app.dataHandle.data.searchTable = app.searchTable;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataGrouperUIFigure = uifigure('Visible', 'off');
            app.DataGrouperUIFigure.Name = 'DataGrouper';
            app.DataGrouperUIFigure.CloseRequestFcn = createCallbackFcn(app, @DataGrouperUIFigureCloseRequest, true);
            app.AttributesListBoxLabel = uilabel(app.DataGrouperUIFigure);
            app.AttributesListBoxLabel.Text = 'Attributes';
            app.AttributesListBox = uilistbox(app.DataGrouperUIFigure);
            app.AttributesListBox.ValueChangedFcn = createCallbackFcn(app, @AttributesListBoxValueChanged, true);
            app.QueryconstructionPanel = uipanel(app.DataGrouperUIFigure);
            app.QueryconstructionPanel.Title = 'Query construction';
            app.GridLayout = uigridlayout(app.QueryconstructionPanel);
            app.EditField_Q1 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q1.Editable = 'off';
            app.EditField_Q1.Visible = 'off';
            app.EditField_Q3 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q3.Editable = 'off';
            app.EditField_Q3.Visible = 'off';
            app.EditField_Q5 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q5.Editable = 'off';
            app.EditField_Q5.Visible = 'off';
            app.EditField_Q7 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q7.Editable = 'off';
            app.EditField_Q7.Visible = 'off';
            app.EditField_Q9 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q9.Editable = 'off';
            app.EditField_Q9.Visible = 'off';
            app.EditField_Q11 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q11.Editable = 'off';
            app.EditField_Q11.Visible = 'off';
            app.EditField_Q13 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q13.Editable = 'off';
            app.EditField_Q13.Visible = 'off';
            app.EditField_Q15 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q15.Editable = 'off';
            app.EditField_Q15.Visible = 'off';
            app.EditField_Q17 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q17.Editable = 'off';
            app.EditField_Q17.Visible = 'off';
            app.EditField_Q19 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q19.Editable = 'off';
            app.EditField_Q19.Visible = 'off';
            app.EditField_Q21 = uieditfield(app.GridLayout, 'text');
            app.EditField_Q21.Editable = 'off';
            app.EditField_Q21.Visible = 'off';
            app.OpDrop_1 = uidropdown(app.GridLayout);
            app.OpDrop_1.ValueChangedFcn = createCallbackFcn(app, @OpDrop_1ValueChanged, true);
            app.OpDrop_1.Tag = 'OpDrop_1';
            app.OpDrop_1.Visible = 'off';
            app.OpDrop_3 = uidropdown(app.GridLayout);
            app.OpDrop_3.ValueChangedFcn = createCallbackFcn(app, @OpDrop_3ValueChanged, true);
            app.OpDrop_3.Tag = 'OpDrop_3';
            app.OpDrop_3.Visible = 'off';
            app.OpDrop_5 = uidropdown(app.GridLayout);
            app.OpDrop_5.ValueChangedFcn = createCallbackFcn(app, @OpDrop_5ValueChanged, true);
            app.OpDrop_5.Tag = 'OpDrop_5';
            app.OpDrop_5.Visible = 'off';
            app.OpDrop_7 = uidropdown(app.GridLayout);
            app.OpDrop_7.ValueChangedFcn = createCallbackFcn(app, @OpDrop_7ValueChanged, true);
            app.OpDrop_7.Tag = 'OpDrop_7';
            app.OpDrop_7.Visible = 'off';
            app.OpDrop_9 = uidropdown(app.GridLayout);
            app.OpDrop_9.ValueChangedFcn = createCallbackFcn(app, @OpDrop_9ValueChanged, true);
            app.OpDrop_9.Tag = 'OpDrop_9';
            app.OpDrop_9.Visible = 'off';
            app.OpDrop_11 = uidropdown(app.GridLayout);
            app.OpDrop_11.ValueChangedFcn = createCallbackFcn(app, @OpDrop_11ValueChanged, true);
            app.OpDrop_11.Tag = 'OpDrop_11';
            app.OpDrop_11.Visible = 'off';
            app.OpDrop_13 = uidropdown(app.GridLayout);
            app.OpDrop_13.ValueChangedFcn = createCallbackFcn(app, @OpDrop_13ValueChanged, true);
            app.OpDrop_13.Tag = 'OpDrop_13';
            app.OpDrop_13.Visible = 'off';
            app.OpDrop_15 = uidropdown(app.GridLayout);
            app.OpDrop_15.ValueChangedFcn = createCallbackFcn(app, @OpDrop_15ValueChanged, true);
            app.OpDrop_15.Tag = 'OpDrop_15';
            app.OpDrop_15.Visible = 'off';
            app.OpDrop_17 = uidropdown(app.GridLayout);
            app.OpDrop_17.ValueChangedFcn = createCallbackFcn(app, @OpDrop_17ValueChanged, true);
            app.OpDrop_17.Tag = 'OpDrop_17';
            app.OpDrop_17.Visible = 'off';
            app.OpDrop_19 = uidropdown(app.GridLayout);
            app.OpDrop_19.ValueChangedFcn = createCallbackFcn(app, @OpDrop_19ValueChanged, true);
            app.OpDrop_19.Tag = 'OpDrop_19';
            app.OpDrop_19.Visible = 'off';
            app.OpDrop_21 = uidropdown(app.GridLayout);
            app.OpDrop_21.ValueChangedFcn = createCallbackFcn(app, @OpDrop_21ValueChanged, true);
            app.OpDrop_21.Tag = 'OpDrop_21';
            app.OpDrop_21.Visible = 'off';
            app.LogicButton_2 = uibutton(app.GridLayout, 'state');
            app.LogicButton_2.ValueChangedFcn = createCallbackFcn(app, @LogicButton_2ValueChanged, true);
            app.LogicButton_2.Tag = 'LogicButton_2';
            app.LogicButton_2.Text = '';
            app.LogicButton_4 = uibutton(app.GridLayout, 'state');
            app.LogicButton_4.ValueChangedFcn = createCallbackFcn(app, @LogicButton_4ValueChanged, true);
            app.LogicButton_4.Tag = 'LogicButton_4';
            app.LogicButton_4.Visible = 'off';
            app.LogicButton_4.Text = 'AND';
            app.LogicButton_6 = uibutton(app.GridLayout, 'state');
            app.LogicButton_6.ValueChangedFcn = createCallbackFcn(app, @LogicButton_6ValueChanged, true);
            app.LogicButton_6.Tag = 'LogicButton_6';
            app.LogicButton_6.Visible = 'off';
            app.LogicButton_6.Text = 'AND';
            app.LogicButton_8 = uibutton(app.GridLayout, 'state');
            app.LogicButton_8.ValueChangedFcn = createCallbackFcn(app, @LogicButton_8ValueChanged, true);
            app.LogicButton_8.Tag = 'LogicButton_8';
            app.LogicButton_8.Visible = 'off';
            app.LogicButton_8.Text = 'AND';
            app.LogicButton_10 = uibutton(app.GridLayout, 'state');
            app.LogicButton_10.ValueChangedFcn = createCallbackFcn(app, @LogicButton_10ValueChanged, true);
            app.LogicButton_10.Tag = 'LogicButton_10';
            app.LogicButton_10.Visible = 'off';
            app.LogicButton_10.Text = 'AND';
            app.LogicButton_12 = uibutton(app.GridLayout, 'state');
            app.LogicButton_12.ValueChangedFcn = createCallbackFcn(app, @LogicButton_12ValueChanged, true);
            app.LogicButton_12.Tag = 'LogicButton_12';
            app.LogicButton_12.Visible = 'off';
            app.LogicButton_12.Text = 'AND';
            app.LogicButton_14 = uibutton(app.GridLayout, 'state');
            app.LogicButton_14.ValueChangedFcn = createCallbackFcn(app, @LogicButton_14ValueChanged, true);
            app.LogicButton_14.Tag = 'LogicButton_14';
            app.LogicButton_14.Visible = 'off';
            app.LogicButton_14.Text = 'AND';
            app.LogicButton_16 = uibutton(app.GridLayout, 'state');
            app.LogicButton_16.ValueChangedFcn = createCallbackFcn(app, @LogicButton_16ValueChanged, true);
            app.LogicButton_16.Tag = 'LogicButton_16';
            app.LogicButton_16.Visible = 'off';
            app.LogicButton_16.Text = 'AND';
            app.LogicButton_18 = uibutton(app.GridLayout, 'state');
            app.LogicButton_18.ValueChangedFcn = createCallbackFcn(app, @LogicButton_18ValueChanged, true);
            app.LogicButton_18.Tag = 'LogicButton_18';
            app.LogicButton_18.Visible = 'off';
            app.LogicButton_18.Text = 'AND';
            app.LogicButton_20 = uibutton(app.GridLayout, 'state');
            app.LogicButton_20.ValueChangedFcn = createCallbackFcn(app, @LogicButton_20ValueChanged, true);
            app.LogicButton_20.Tag = 'LogicButton_20';
            app.LogicButton_20.Visible = 'off';
            app.LogicButton_20.Text = 'AND';
            app.AddconditionwithattributeButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.AddconditionwithattributeButton.ButtonPushedFcn = createCallbackFcn(app, @AddconditionwithattributeButtonPushed, true);
            app.AddconditionwithattributeButton.Text = 'Add condition with attribute';
            app.DeletelastelementButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.DeletelastelementButton.ButtonPushedFcn = createCallbackFcn(app, @DeletelastelementButtonPushed, true);
            app.DeletelastelementButton.Text = 'Delete last element';
            app.LogicaloperatorsPanel = uipanel(app.DataGrouperUIFigure);
            app.LogicaloperatorsPanel.Title = 'Logical operators';
            app.NOTButton = uibutton(app.LogicaloperatorsPanel, 'push');
            app.NOTButton.ButtonPushedFcn = createCallbackFcn(app, @NOTButtonPushed, true);
            app.NOTButton.Text = 'NOT';
            app.ANYOFButton = uibutton(app.LogicaloperatorsPanel, 'push');
            app.ANYOFButton.ButtonPushedFcn = createCallbackFcn(app, @ANYOFButtonPushed, true);
            app.ANYOFButton.Text = 'ANY OF (';
            app.Button_2 = uibutton(app.LogicaloperatorsPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = ')';
            app.Button_3 = uibutton(app.LogicaloperatorsPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '(';
            app.ANDButton = uibutton(app.LogicaloperatorsPanel, 'push');
            app.ANDButton.ButtonPushedFcn = createCallbackFcn(app, @ANDButtonPushed, true);
            app.ANDButton.Text = 'AND';
            app.Button_4 = uibutton(app.LogicaloperatorsPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = ',';
            app.clearButton = uibutton(app.LogicaloperatorsPanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.JoinedtablesPanel = uipanel(app.DataGrouperUIFigure);
            app.JoinedtablesPanel.Title = 'Joined tables';
            app.TablestoJoinListBox = uilistbox(app.JoinedtablesPanel);
            app.TablestoJoinListBox.Items = {};
            app.TablestoJoinListBox.ValueChangedFcn = createCallbackFcn(app, @TablestoJoinListBoxValueChanged, true);
            app.TablestoJoinListBox.Value = {};
            app.RemoveButton = uibutton(app.JoinedtablesPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.TablesToJoinDropDown = uidropdown(app.JoinedtablesPanel);
            app.TablesToJoinDropDown.Items = {'sln_symphony.Experiment', 'aka.Cell', 'aka.CellPair', 'aka.Dataset', 'aka.Epoch', 'sln_symphony.SpikeTrain', 'sln_symphony.ExperimentRetina', 'sln_symphony.ExperimentEpochGroup'};
            app.TablesToJoinDropDown.Value = 'sln_symphony.Experiment';
            app.AddButton = uibutton(app.JoinedtablesPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.TypeEditFieldLabel = uilabel(app.DataGrouperUIFigure);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.DataGrouperUIFigure, 'text');
            app.TypeEditField.Editable = 'off';
            app.RunButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResultsPanel = uipanel(app.DataGrouperUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.animalsEditFieldLabel = uilabel(app.ResultsPanel);
            app.animalsEditFieldLabel.Text = 'animals';
            app.animalsEditField = uieditfield(app.ResultsPanel, 'text');
            app.animalsEditField.Editable = 'off';
            app.cellsEditField_2Label = uilabel(app.ResultsPanel);
            app.cellsEditField_2Label.Text = 'cells';
            app.cellsEditField = uieditfield(app.ResultsPanel, 'text');
            app.eyesEditFieldLabel = uilabel(app.ResultsPanel);
            app.eyesEditFieldLabel.Text = 'eyes';
            app.eyesEditField = uieditfield(app.ResultsPanel, 'text');
            app.eyesEditField.Editable = 'off';
            app.datasetsEditField_3Label = uilabel(app.ResultsPanel);
            app.datasetsEditField_3Label.Text = 'datasets';
            app.datasetsEditField = uieditfield(app.ResultsPanel, 'text');
            app.SavequeryButton = uibutton(app.ResultsPanel, 'push');
            app.SavequeryButton.ButtonPushedFcn = createCallbackFcn(app, @SavequeryButtonPushed, true);
            app.SavequeryButton.Text = 'Save query';
            app.epochsLabel = uilabel(app.ResultsPanel);
            app.epochsLabel.Text = 'epochs';
            app.epochsEditField = uieditfield(app.ResultsPanel, 'text');
            app.experimentsEditFieldLabel = uilabel(app.ResultsPanel);
            app.experimentsEditFieldLabel.Text = 'experiments';
            app.experimentsEditField = uieditfield(app.ResultsPanel, 'text');
            app.experimentsEditField.Editable = 'off';
            app.LoadqueryButton = uibutton(app.ResultsPanel, 'push');
            app.LoadqueryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadqueryButtonPushed, true);
            app.LoadqueryButton.Text = 'Load query';
            app.CommentTextAreaLabel = uilabel(app.DataGrouperUIFigure);
            app.CommentTextAreaLabel.Text = 'Comment';
            app.CommentTextArea = uitextarea(app.DataGrouperUIFigure);
            app.CommentTextArea.Editable = 'off';
            app.BrowserPanel = uipanel(app.DataGrouperUIFigure);
            app.BrowserPanel.Title = 'Browser';
            app.ViewlevelDropDownLabel = uilabel(app.BrowserPanel);
            app.ViewlevelDropDownLabel.Text = 'View level';
            app.ViewlevelDropDown = uidropdown(app.BrowserPanel);
            app.ViewlevelDropDown.Items = {'Experiment', 'Animal', 'Cell', 'Dataset', 'Epoch'};
            app.ViewlevelDropDown.Value = 'Experiment';
            app.NLabel = uilabel(app.BrowserPanel);
            app.NLabel.Text = 'N =';
            app.NEditField = uieditfield(app.BrowserPanel, 'numeric');
            app.NEditField.Editable = 'off';
            app.DataElementsListBox = uilistbox(app.BrowserPanel);
            app.RunanalysisButton = uibutton(app.BrowserPanel, 'push');
            app.RunanalysisButton.Text = 'Run analysis';
            app.AddlabelButton = uibutton(app.BrowserPanel, 'push');
            app.AddlabelButton.Text = 'Add label';
            app.AnalysisDropDown = uidropdown(app.BrowserPanel);
            app.OtherButton = uibutton(app.BrowserPanel, 'push');
            app.OtherButton.Text = 'Other';
            app.NewlabelButton = uibutton(app.BrowserPanel, 'push');
            app.NewlabelButton.Text = 'New label';
            app.LabelDrownDown = uidropdown(app.BrowserPanel);
            app.CustomAnalysisName = uieditfield(app.BrowserPanel, 'text');
            app.CustomAnalysisName.Editable = 'off';
            app.CustomLabelEdit = uieditfield(app.BrowserPanel, 'text');
            app.ExportDataGroupButton = uibutton(app.DataGrouperUIFigure, 'push');
            app.ExportDataGroupButton.Text = 'Export DataGroup';
            app.IncluderawtracesCheckBox = uicheckbox(app.DataGrouperUIFigure);
            app.IncluderawtracesCheckBox.Text = 'Include raw traces';
            % Show the figure after all components are created
            app.DataGrouperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataGrouper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataGrouperUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataGrouperUIFigure)
        end
    end
end