classdef ROI_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIToolUIFigure                matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadimageMenu                  matlab.ui.container.Menu
        LoadROIsetMenu                 matlab.ui.container.Menu
        SaveROIsMenu                   matlab.ui.container.Menu
        SelectMenu                     matlab.ui.container.Menu
        EllipseMenu                    matlab.ui.container.Menu
        RectangleMenu                  matlab.ui.container.Menu
        FreedrawMenu                   matlab.ui.container.Menu
        UpdateButton                   matlab.ui.control.Button
        EndFrameEditField              matlab.ui.control.NumericEditField
        FrameEditFieldLabel_3          matlab.ui.control.Label
        EndTimeEditField               matlab.ui.control.NumericEditField
        StartFrameEditField            matlab.ui.control.NumericEditField
        FrameEditFieldLabel_2          matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.NumericEditField
        CurTimeEditField               matlab.ui.control.NumericEditField
        CurFrameEditField              matlab.ui.control.NumericEditField
        FrameEditFieldLabel            matlab.ui.control.Label
        ProcessingstepsPanel           matlab.ui.container.Panel
        ClearlastButton                matlab.ui.control.Button
        StepsTextArea                  matlab.ui.control.TextArea
        ClearallButton                 matlab.ui.control.Button
        CorrelationsPanel              matlab.ui.container.Panel
        ProjectionsPanel               matlab.ui.container.Panel
        LimittotimeslidersSwitch       matlab.ui.control.Switch
        LimittotimeslidersSwitchLabel  matlab.ui.control.Label
        StdProjButton                  matlab.ui.control.Button
        MeanProjButton                 matlab.ui.control.Button
        MaxProjButton                  matlab.ui.control.Button
        EndtimesSlider                 matlab.ui.control.Slider
        EndtimesSliderLabel            matlab.ui.control.Label
        StarttimesSlider               matlab.ui.control.Slider
        StarttimesSliderLabel          matlab.ui.control.Label
        TemporalfilteringPanel         matlab.ui.container.Panel
        HighpassHzEditField            matlab.ui.control.NumericEditField
        HighpassHzEditFieldLabel       matlab.ui.control.Label
        ImagingruninformationPanel     matlab.ui.container.Panel
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        HeightmEditField               matlab.ui.control.NumericEditField
        HeightmEditFieldLabel          matlab.ui.control.Label
        WidthmEditField                matlab.ui.control.NumericEditField
        WidthmEditFieldLabel           matlab.ui.control.Label
        NumberofROIsEditField          matlab.ui.control.NumericEditField
        NumberofROIsEditFieldLabel     matlab.ui.control.Label
        YresolutionmpixelEditField     matlab.ui.control.NumericEditField
        YresolutionmpixelEditFieldLabel  matlab.ui.control.Label
        XresolutionmpixelEditField     matlab.ui.control.NumericEditField
        XresolutionmpixelEditFieldLabel  matlab.ui.control.Label
        DurationsEditField             matlab.ui.control.NumericEditField
        DurationsEditFieldLabel        matlab.ui.control.Label
        FramerateHzEditField           matlab.ui.control.NumericEditField
        FramerateHzEditFieldLabel      matlab.ui.control.Label
        TimesSlider                    matlab.ui.control.Slider
        TimesSliderLabel               matlab.ui.control.Label
        TemporalProfileAxis            matlab.ui.control.UIAxes
        rois_axis                      matlab.ui.control.UIAxes
        working_axis                   matlab.ui.control.UIAxes
        original_axis                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imageVolume % The full original image volume
        n_frames
        duration %seconds
        width %pixels
        height %pixels
        res_x %µm/pixel
        res_y %µm/pixel
        width_um %µm
        height_um %µm 
        frame_rate %Hz
        image_fname
        cur_frame
        selected_mask % mask of current roi selection
        selected_roi = [];% roi object
        trace % currently shown profile trace (full time)
        cur_frame_start
        cur_frame_end
    end
    
    methods (Access = private)
        
        function loadImageSequence(app, fname, meta_fname)
            app.imageVolume = tiffreadVolume(fname);
            info = imfinfo(fname);
            load(meta_fname,'SI');
            app.frame_rate = SI.hRoiManager.scanFrameRate;
            app.n_frames = length(info);
            app.width = info(1).Width;
            app.height = info(1).Height;
            app.res_x = 1./info(1).XResolution;
            app.res_y = 1./info(1).YResolution;
            app.width_um = app.width * app.res_y;
            app.height_um = app.height * app.res_x;
            [fpath, image_fname, ~] = fileparts(fname);
            app.image_fname = image_fname;
            app.duration = app.n_frames / app.frame_rate;

            app.FramerateHzEditField.Value = app.frame_rate;
            app.DurationsEditField.Value = app.duration;
            app.XresolutionmpixelEditField.Value = app.res_x;
            app.YresolutionmpixelEditField.Value = app.res_y;
            app.WidthmEditField.Value = app.width_um;
            app.HeightmEditField.Value = app.height_um;
            app.FilenameEditField.Value = app.image_fname;

            app.setSliders;
            app.cur_frame = 1;
            app.cur_frame_start = 1;
            app.cur_frame_end = app.n_frames;
            app.StartFrameEditField.Value = 1;
            app.EndFrameEditField.Value = app.n_frames;
            app.StartTimeEditField.Value = 1./app.frame_rate;
            app.EndTimeEditField.Value = app.n_frames./app.frame_rate;
            app.StarttimesSlider.Value = 1./app.frame_rate;
            app.EndtimesSlider.Value = app.n_frames./app.frame_rate;        
            if ~isempty(app.selected_roi)
                delete(app.selected_roi);
            end
            app.updateImageFrame;
        end
        
        function setSliders(app)
            app.TimesSlider.Limits = [1/app.frame_rate app.duration];
            app.TimesSlider.MajorTicks = floor(linspace(1, app.duration, 5));
            app.CurTimeEditField.Limits = [1/app.frame_rate app.duration];
            app.CurFrameEditField.Limits = [1 app.n_frames];

            app.StarttimesSlider.Limits = [1/app.frame_rate app.duration];
            app.EndtimesSlider.Limits = [1/app.frame_rate app.duration];  

            app.TimesSlider.Value = 1/app.frame_rate;
            app.CurTimeEditField.Value = 1/app.frame_rate;
            app.CurFrameEditField.Value = 1;

            app.StarttimesSlider.Value = 1/app.frame_rate;
            app.EndtimesSlider.Value = app.duration;
        end
        
        function updateImageFrame(app)
            cur_im = squeeze(app.imageVolume(:,:,app.cur_frame));
            imshow(cur_im,[min(cur_im, [], 'all'), max(cur_im, [], 'all')],'Parent',app.original_axis);
        end
        
        function updateProfileTrace(app)
            app.trace = squeeze(mean(double(app.imageVolume).*repmat(app.selected_mask,[1, 1, app.n_frames]),[1 2]));
            plot(app.TemporalProfileAxis,[1:app.n_frames]./app.frame_rate,...
                app.trace,...
                'Color','k','LineWidth',2);            
        end
        
        function updateProfileTraceTime(app)
            plot(app.TemporalProfileAxis,[app.cur_frame_start:app.cur_frame_end]./app.frame_rate, ...
                app.trace(app.cur_frame_start:app.cur_frame_end),...
                'Color','k','LineWidth',2); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadimageMenu
        function LoadimageMenuSelected(app, event)
            [fname, fpath] = uigetfile('*.tif','Select functional imaging file',...
                [getenv('Func_imaging_folder') filesep 'SingleOrPairedCell']);
            meta_fname = uigetfile('*.mat','Select metadata file', fpath);
            app.loadImageSequence([fpath, fname], [fpath meta_fname]);
        end

        % Value changed function: TimesSlider
        function TimesSliderValueChanged(app, event)
            value = app.TimesSlider.Value;
            app.cur_frame = round(value * app.frame_rate);
            app.CurTimeEditField.Value = value;
            app.CurFrameEditField.Value = app.cur_frame;
            app.updateImageFrame;
        end

        % Value changed function: CurFrameEditField
        function CurFrameEditFieldValueChanged(app, event)
            value = app.CurFrameEditField.Value;
            app.cur_frame = value;
            time_val = value / app.frame_rate;
            app.TimesSlider.Value = time_val;
            app.CurTimeEditField.Value = time_val;
            app.updateImageFrame;
        end

        % Value changed function: CurTimeEditField
        function CurTimeEditFieldValueChanged(app, event)
            value = app.CurTimeEditField.Value;
            app.cur_frame = round(value * app.frame_rate);
            app.CurFrameEditField.Value = app.cur_frame;
            app.TimesSlider.Value = value;
            app.updateImageFrame; 
        end

        % Menu selected function: FreedrawMenu
        function FreedrawMenuSelected(app, event)
            if ~isempty(app.selected_roi)
                delete(app.selected_roi);
            end
            app.selected_roi = drawpolygon(app.original_axis);            
            app.selected_mask = createMask(app.selected_roi);
            app.updateProfileTrace();
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.selected_mask = createMask(app.selected_roi);
            app.updateProfileTrace();
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged(app, event)
            value = app.StartTimeEditField.Value;
            app.cur_frame_start = round(value * app.frame_rate);
            app.StartFrameEditField.Value = app.cur_frame_start;
            app.StarttimesSlider.Value = value;
            app.EndFrameEditField.Limits = [app.cur_frame_start+1, app.n_frames];
            app.EndtimesSlider.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.EndTimeEditField.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Value changed function: StartFrameEditField
        function StartFrameEditFieldValueChanged(app, event)
            value = app.StartFrameEditField.Value;
            app.cur_frame_start = value;
            time_val = value / app.frame_rate;
            app.StarttimesSlider.Value = time_val;
            app.StartTimeEditField.Value = time_val;
            app.EndFrameEditField.Limits = [app.cur_frame_start+1, app.n_frames];
            app.EndtimesSlider.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.EndTimeEditField.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Value changed function: EndTimeEditField
        function EndTimeEditFieldValueChanged(app, event)
            value = app.EndTimeEditField.Value;
            app.cur_frame_end = round(value * app.frame_rate);
            app.EndFrameEditField.Value = app.cur_frame_end;
            app.EndtimesSlider.Value = value;
            app.StartFrameEditField.Limits = [1, app.cur_frame_end-1];
            app.StarttimesSlider.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.StartTimeEditField.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Value changed function: EndFrameEditField
        function EndFrameEditFieldValueChanged(app, event)
            value = app.EndFrameEditField.Value;
            app.cur_frame_end = value;
            time_val = value / app.frame_rate;
            app.EndtimesSlider.Value = time_val;
            app.EndTimeEditField.Value = time_val;
            app.StartFrameEditField.Limits = [1, app.cur_frame_end-1];
            app.StarttimesSlider.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.StartTimeEditField.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Value changed function: StarttimesSlider
        function StarttimesSliderValueChanged(app, event)
            value = app.StarttimesSlider.Value;
            app.cur_frame_start = round(value * app.frame_rate);
            app.StartTimeEditField.Value = value;
            app.StartFrameEditField.Value = app.cur_frame_start;
            app.EndFrameEditField.Limits = [app.cur_frame_start+1, app.n_frames];
            app.EndtimesSlider.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.EndTimeEditField.Limits = [app.cur_frame_start+1, app.n_frames]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Value changed function: EndtimesSlider
        function EndtimesSliderValueChanged(app, event)
            value = app.EndtimesSlider.Value;
            app.cur_frame_end = round(value * app.frame_rate);
            app.EndTimeEditField.Value = value;
            app.EndFrameEditField.Value = app.cur_frame_end;
            app.StartFrameEditField.Limits = [1, app.cur_frame_end-1];
            app.StarttimesSlider.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.StartTimeEditField.Limits = [1, app.cur_frame_end-1]./app.frame_rate;
            app.updateProfileTraceTime();
        end

        % Button pushed function: MaxProjButton
        function MaxProjButtonPushed(app, event)
            if strcmp(app.LimittotimeslidersSwitch.Value, 'N')
                frame_range = [1:app.n_frames];
            else
                frame_range = [app.cur_frame_start:app.cur_frame_end];                
            end
            proj = squeeze(max(double(app.imageVolume(:,:,frame_range)),[],3));
            imshow(proj,[min(proj, [], 'all'), max(proj, [], 'all')],'Parent',app.working_axis);
        end

        % Button pushed function: MeanProjButton
        function MeanProjButtonPushed(app, event)
            if strcmp(app.LimittotimeslidersSwitch.Value, 'N')
                frame_range = [1:app.n_frames];
            else
                frame_range = [app.cur_frame_start:app.cur_frame_end];                
            end
            proj = squeeze(mean(double(app.imageVolume(:,:,frame_range)),3));
            imshow(proj,[min(proj, [], 'all'), max(proj, [], 'all')],'Parent',app.working_axis);
        end

        % Button pushed function: StdProjButton
        function StdProjButtonPushed(app, event)
            if strcmp(app.LimittotimeslidersSwitch.Value, 'N')
                frame_range = [1:app.n_frames];
            else
                frame_range = [app.cur_frame_start:app.cur_frame_end];                
            end
            proj = squeeze(std(double(app.imageVolume(:,:,frame_range)),[],3));
            imshow(proj,[min(proj, [], 'all'), max(proj, [], 'all')],'Parent',app.working_axis);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIToolUIFigure = uifigure('Visible', 'off');
            app.ROIToolUIFigure.Name = 'ROI Tool';
            app.FileMenu = uimenu(app.ROIToolUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadimageMenu = uimenu(app.FileMenu);
            app.LoadimageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadimageMenuSelected, true);
            app.LoadimageMenu.Accelerator = 'L';
            app.LoadimageMenu.Text = 'Load image';
            app.LoadROIsetMenu = uimenu(app.FileMenu);
            app.LoadROIsetMenu.Accelerator = 'R';
            app.LoadROIsetMenu.Text = 'Load ROI set';
            app.SaveROIsMenu = uimenu(app.FileMenu);
            app.SaveROIsMenu.Accelerator = 'S';
            app.SaveROIsMenu.Text = 'Save ROIs';
            app.SelectMenu = uimenu(app.ROIToolUIFigure);
            app.SelectMenu.Text = 'Select';
            app.EllipseMenu = uimenu(app.SelectMenu);
            app.EllipseMenu.Text = 'Ellipse';
            app.RectangleMenu = uimenu(app.SelectMenu);
            app.RectangleMenu.Text = 'Rectangle';
            app.FreedrawMenu = uimenu(app.SelectMenu);
            app.FreedrawMenu.MenuSelectedFcn = createCallbackFcn(app, @FreedrawMenuSelected, true);
            app.FreedrawMenu.Accelerator = 'D';
            app.FreedrawMenu.Text = 'Free draw';
            app.original_axis = uiaxes(app.ROIToolUIFigure);
            title(app.original_axis, 'Original')
            app.original_axis.XTick = [];
            app.original_axis.YTick = [];
            app.working_axis = uiaxes(app.ROIToolUIFigure);
            title(app.working_axis, 'Working')
            app.working_axis.XTick = [];
            app.working_axis.YTick = [];
            app.rois_axis = uiaxes(app.ROIToolUIFigure);
            title(app.rois_axis, 'ROIs')
            app.rois_axis.XTick = [];
            app.rois_axis.YTick = [];
            app.TemporalProfileAxis = uiaxes(app.ROIToolUIFigure);
            title(app.TemporalProfileAxis, 'Selection temporal profile')
            xlabel(app.TemporalProfileAxis, 'Time (s)')
            app.TimesSliderLabel = uilabel(app.ROIToolUIFigure);
            app.TimesSliderLabel.Text = 'Time (s)';
            app.TimesSlider = uislider(app.ROIToolUIFigure);
            app.TimesSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TimesSlider.ValueChangedFcn = createCallbackFcn(app, @TimesSliderValueChanged, true);
            app.TimesSlider.MinorTicks = [];
            app.ImagingruninformationPanel = uipanel(app.ROIToolUIFigure);
            app.ImagingruninformationPanel.Title = 'Imaging run information';
            app.FramerateHzEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.FramerateHzEditFieldLabel.Text = 'Frame rate (Hz)';
            app.FramerateHzEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.FramerateHzEditField.Editable = 'off';
            app.DurationsEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.DurationsEditField.Editable = 'off';
            app.XresolutionmpixelEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.XresolutionmpixelEditFieldLabel.Text = 'X resolution (µm/pixel)';
            app.XresolutionmpixelEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.XresolutionmpixelEditField.Editable = 'off';
            app.YresolutionmpixelEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.YresolutionmpixelEditFieldLabel.Text = 'Y resolution (µm/pixel)';
            app.YresolutionmpixelEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.YresolutionmpixelEditField.Editable = 'off';
            app.NumberofROIsEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.NumberofROIsEditFieldLabel.Text = 'Number of ROIs';
            app.NumberofROIsEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.NumberofROIsEditField.Editable = 'off';
            app.WidthmEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.WidthmEditFieldLabel.Text = 'Width (µm)';
            app.WidthmEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.WidthmEditField.Editable = 'off';
            app.HeightmEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.HeightmEditFieldLabel.Text = 'Height (µm)';
            app.HeightmEditField = uieditfield(app.ImagingruninformationPanel, 'numeric');
            app.HeightmEditField.Editable = 'off';
            app.FilenameEditFieldLabel = uilabel(app.ImagingruninformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.ImagingruninformationPanel, 'text');
            app.TemporalfilteringPanel = uipanel(app.ROIToolUIFigure);
            app.TemporalfilteringPanel.Title = 'Temporal filtering';
            app.HighpassHzEditFieldLabel = uilabel(app.TemporalfilteringPanel);
            app.HighpassHzEditFieldLabel.Text = 'High pass (Hz)';
            app.HighpassHzEditField = uieditfield(app.TemporalfilteringPanel, 'numeric');
            app.StarttimesSliderLabel = uilabel(app.ROIToolUIFigure);
            app.StarttimesSliderLabel.Text = 'Start time (s)';
            app.StarttimesSlider = uislider(app.ROIToolUIFigure);
            app.StarttimesSlider.ValueChangedFcn = createCallbackFcn(app, @StarttimesSliderValueChanged, true);
            app.StarttimesSlider.MinorTicks = [];
            app.EndtimesSliderLabel = uilabel(app.ROIToolUIFigure);
            app.EndtimesSliderLabel.Text = 'End time (s)';
            app.EndtimesSlider = uislider(app.ROIToolUIFigure);
            app.EndtimesSlider.ValueChangedFcn = createCallbackFcn(app, @EndtimesSliderValueChanged, true);
            app.EndtimesSlider.MinorTicks = [];
            app.ProjectionsPanel = uipanel(app.ROIToolUIFigure);
            app.ProjectionsPanel.Title = 'Projections';
            app.MaxProjButton = uibutton(app.ProjectionsPanel, 'push');
            app.MaxProjButton.ButtonPushedFcn = createCallbackFcn(app, @MaxProjButtonPushed, true);
            app.MaxProjButton.Text = 'Max';
            app.MeanProjButton = uibutton(app.ProjectionsPanel, 'push');
            app.MeanProjButton.ButtonPushedFcn = createCallbackFcn(app, @MeanProjButtonPushed, true);
            app.MeanProjButton.Text = 'Mean';
            app.StdProjButton = uibutton(app.ProjectionsPanel, 'push');
            app.StdProjButton.ButtonPushedFcn = createCallbackFcn(app, @StdProjButtonPushed, true);
            app.StdProjButton.Text = 'Std';
            app.LimittotimeslidersSwitchLabel = uilabel(app.ProjectionsPanel);
            app.LimittotimeslidersSwitchLabel.Text = 'Limit to time sliders';
            app.LimittotimeslidersSwitch = uiswitch(app.ProjectionsPanel, 'slider');
            app.LimittotimeslidersSwitch.Items = {'N', 'Y'};
            app.LimittotimeslidersSwitch.Value = 'N';
            app.CorrelationsPanel = uipanel(app.ROIToolUIFigure);
            app.CorrelationsPanel.Title = 'Correlations';
            app.ProcessingstepsPanel = uipanel(app.ROIToolUIFigure);
            app.ProcessingstepsPanel.Title = 'Processing steps';
            app.ClearallButton = uibutton(app.ProcessingstepsPanel, 'push');
            app.ClearallButton.Text = 'Clear all';
            app.StepsTextArea = uitextarea(app.ProcessingstepsPanel);
            app.ClearlastButton = uibutton(app.ProcessingstepsPanel, 'push');
            app.ClearlastButton.Text = 'Clear last';
            app.FrameEditFieldLabel = uilabel(app.ROIToolUIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.CurFrameEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.CurFrameEditField.ValueChangedFcn = createCallbackFcn(app, @CurFrameEditFieldValueChanged, true);
            app.CurTimeEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.CurTimeEditField.ValueChangedFcn = createCallbackFcn(app, @CurTimeEditFieldValueChanged, true);
            app.StartTimeEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged, true);
            app.FrameEditFieldLabel_2 = uilabel(app.ROIToolUIFigure);
            app.FrameEditFieldLabel_2.Text = 'Frame';
            app.StartFrameEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.StartFrameEditField.ValueChangedFcn = createCallbackFcn(app, @StartFrameEditFieldValueChanged, true);
            app.EndTimeEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.EndTimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeEditFieldValueChanged, true);
            app.FrameEditFieldLabel_3 = uilabel(app.ROIToolUIFigure);
            app.FrameEditFieldLabel_3.Text = 'Frame';
            app.EndFrameEditField = uieditfield(app.ROIToolUIFigure, 'numeric');
            app.EndFrameEditField.ValueChangedFcn = createCallbackFcn(app, @EndFrameEditFieldValueChanged, true);
            app.UpdateButton = uibutton(app.ROIToolUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.ROIToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROI_Tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROIToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIToolUIFigure)
        end
    end
end