classdef TissueDB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TissueDBUIFigure                matlab.ui.Figure
        TaskTable                       matlab.ui.control.Table
        CreatenewtaskPanel              matlab.ui.container.Panel
        TasknameEditFieldLabel          matlab.ui.control.Label
        TasknameEditField               matlab.ui.control.EditField
        TasktypeDropDownLabel           matlab.ui.control.Label
        TasktypeDropDown                matlab.ui.control.DropDown
        Reagent2secondaryDropDownLabel  matlab.ui.control.Label
        Reagent2secondaryDropDown       matlab.ui.control.DropDown
        ScopeDropDownLabel              matlab.ui.control.Label
        ScopeDropDown                   matlab.ui.control.DropDown
        CreateButton                    matlab.ui.control.Button
        AnalysisoutputsLabel            matlab.ui.control.Label
        AnalysisoutputsTextArea         matlab.ui.control.TextArea
        FortissuetypeDropDownLabel      matlab.ui.control.Label
        FortissuetypeDropDown           matlab.ui.control.DropDown
        ColorchannelDropDownLabel       matlab.ui.control.Label
        ColorchannelDropDown            matlab.ui.control.DropDown
        ThicknessmEditFieldLabel        matlab.ui.control.Label
        ThicknessmEditField             matlab.ui.control.NumericEditField
        Reagent1primaryDropDownLabel    matlab.ui.control.Label
        Reagent1primaryDropDown         matlab.ui.control.DropDown
        BrainareaDropDownLabel          matlab.ui.control.Label
        BrainareaDropDown               matlab.ui.control.DropDown
        CellnumberEditFieldLabel        matlab.ui.control.Label
        CellnumberEditField             matlab.ui.control.NumericEditField
        StatusLamp                      matlab.ui.control.Lamp
        StatusEdit                      matlab.ui.control.EditField
        AddtaskButton                   matlab.ui.control.Button
        CompletetaskButton              matlab.ui.control.Button
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesTextArea_CompleteOrAbort   matlab.ui.control.TextArea
        ChangeusertoDropDownLabel       matlab.ui.control.Label
        ChangeusertoDropDown            matlab.ui.control.DropDown
        UserEditFieldLabel              matlab.ui.control.Label
        UserEditField                   matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
        LoginButton                     matlab.ui.control.Button
        BrainOrRetnaTasksDropDown       matlab.ui.control.DropDown
        LogoutButton                    matlab.ui.control.Button
        AddtissueButton                 matlab.ui.control.Button
        TissueTypeDropDown              matlab.ui.control.DropDown
        AnimalDropDownLabel             matlab.ui.control.Label
        AnimalDropDown                  matlab.ui.control.DropDown
        OtherAnimalEditField            matlab.ui.control.NumericEditField
        ReloadButton                    matlab.ui.control.Button
        TaskTree                        matlab.ui.container.Tree
        MoveupButton                    matlab.ui.control.Button
        MovedownButton                  matlab.ui.control.Button
        AborttaskButton                 matlab.ui.control.Button
        OwnerDropDownLabel              matlab.ui.control.Label
        OwnerDropDown                   matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        current_user %user currently logged in
        inactivity_timeout = 300; % seconds until you are logged out
        logout_timer = timer; % timer object for logout
    end
    
    methods (Access = private)
        
        function init(app)
              app.current_user = 'viewer';
              set(app.UserEditField,'value',app.current_user);
              app.load_users();
              set(app.ChangeusertoDropDown,'value','viewer');
              app.load_recently_deceased_animals();
              app.set_permissions();

              %initial state
              set(app.OtherAnimalEditField,'visible',false);
              set(app.StatusLamp,'color','white');
              set(app.StatusEdit,'value','');

              app.load_scopes();      
              app.load_brain_areas();
              app.load_color_channels();
              app.load_tissue_table();
              app.load_tasks();

              app.TasktypeDropDownValueChanged([]);
              app.reset_logout_timer();              
        end

        function load_users(app)
            users = fetchn(sln_lab.ActiveUser,'user_name');
            users = setdiff(users, 'CCM staff');
            items = ['viewer'; users];
            set(app.ChangeusertoDropDown,'items',items);
            set(app.OwnerDropDown,'items',items);
        end

        function load_recently_deceased_animals(app)
            recent_dead = sln_animal.Deceased * sln_animal.AnimalEvent & 'cause = "sacrificed for experiment"' & 'LIMIT 6 PER cause ORDER BY date DESC';
            recent_dead_struct = fetch(recent_dead,'animal_id','user_name','date','notes');
            items = cell(7,1);
            for i=1:length(recent_dead_struct)
                items{i} = sprintf('%d: %s. Entered by %s. %s', ...
                    recent_dead_struct(i).animal_id, recent_dead_struct(i).date, recent_dead_struct(i).user_name, recent_dead_struct(i).notes);                
            end
            items{end} = 'other';            

            set(app.AnimalDropDown,'items',items);            
        end

        function set_permissions(app)
            if strcmp(app.current_user,'viewer')
                set(app.AddtissueButton,'enable',false);
                set(app.AddtaskButton,'enable',false);
                set(app.CompletetaskButton,'enable',false);
                set(app.AborttaskButton,'enable',false);
                set(app.CreateButton,'enable',false);
            else
                set(app.AddtissueButton,'enable',true);
                set(app.AddtaskButton,'enable',true);
                set(app.CompletetaskButton,'enable',true);
                set(app.AborttaskButton,'enable',true);
                set(app.CreateButton,'enable',true);
            end
            
        end
        
        function reset_logout_timer(app)
            if isvalid(app.logout_timer)
                app.logout_timer.stop();
                delete(app.logout_timer);
            end
            app.logout_timer = timer('TimerFcn',@(~,~)app.LogoutButtonPushed());
            app.logout_timer.StartDelay = app.inactivity_timeout;
            app.logout_timer.start();
            %disp('timer reset');
        end
        
        function load_tissue_table(app)
            %TODO: only unfinished tasks
            tasks = sln_tissue.Task & sln_tissue.TissueActive;
            N = tasks.count;

            tasks_struct = fetch(tasks,'*');
            tissue_ids = fetchn(sln_tissue.TissueActive, 'tissue_id');
            N_tissues = length(tissue_ids);

            T = table('size',[N_tissues, 6],...
            'VariableNames',...
                {'Date', ...
                'Type', ...
                'Side', ...
                'Animal_ID', ...
                'Owner', ...
                'Tasks'}, ...
                'VariableTypes',...
            {'string',...
                'string',...
                'string',...
                'int32',...
                'string', ...
                'string'});

            for i=1:N_tissues
                tasks_for_this_tissue = tasks_struct([tasks_struct.tissue_id] == tissue_ids(i));
                if ~isempty(tasks_for_this_tissue)
                    T.Tasks(i) = strjoin(cellstr(strvcat(tasks_for_this_tissue.task_name)), ', ');
                    T.Date(i) = datestr(min(datetime(strvcat(tasks_for_this_tissue.task_entry_time))),'yyyy-mm-dd');
                end
                thisBrain = sln_tissue.Brain * sln_tissue.Tissue & sprintf('tissue_id=%d',tissue_ids(i));
                thisRetina = sln_tissue.Retina * sln_tissue.Tissue & sprintf('tissue_id=%d',tissue_ids(i));
                if thisBrain.exists
                    T.Type(i) = 'Brain';
                    T.Animal_ID(i) = fetch1(thisBrain,'animal_id');
                    T.Side(i) = '';
                    T.Owner(i) = fetch1(thisBrain, 'owner');
                elseif thisRetina.exists
                    T.Type(i) = 'Retina';
                    T.Animal_ID(i) = fetch1(thisRetina,'animal_id');
                    T.Side(i) = fetch1(thisRetina,'side');
                    T.Owner(i) = fetch1(thisRetina, 'owner');
                else
                    error('Tissue ID %d not found in Brain or Retina tables', tissue_ids(i));
                end
                
            end

            set(app.TaskTable,'Data',T);
        end
        
        function load_tasks(app)
            tissue_selection =  get(app.TaskTable,'selection');
            if ~isempty(tissue_selection)
                whichRow = tissue_selection(1);
            else
                whichRow = nan;
            end
            tissue_table_data = get(app.TaskTable,'Data');
            
            if whichRow <= height(tissue_table_data)
                tissue_type = tissue_table_data.Type(tissue_selection(1));
            else
                tissue_type = 'none';
            end
                                
            if strcmp(tissue_type,'Brain')
                generic_tissue_id = fetch1(sln_tissue.GenericBrain,'tissue_id');
            elseif strcmp(tissue_type,'Retina')
                generic_tissue_id = fetch1(sln_tissue.GenericRetina,'tissue_id');
            else
                generic_tissue_id = 0;
            end
            
            relevant_tasks = sln_tissue.Task & sprintf('tissue_id=%d', generic_tissue_id);
            if relevant_tasks.exists
                items = fetchn(relevant_tasks,'task_name');
            else
                items = {'None found'};
            end
            set(app.BrainOrRetnaTasksDropDown,'items',items);            
        end
        
        function load_scopes(app)
            set(app.ScopeDropDown,'items',fetchn(sln_lab.Scope,'scope_name'));            
        end
        
        function load_color_channels(app)
            set(app.ColorchannelDropDown,'items',fetchn(sln_tissue.ColorChannel,'color_channel_name'));
        end
        
        function load_brain_areas(app)
            set(app.BrainareaDropDown,'items',fetchn(sln_animal.BrainArea,'target'));
        end

        function update_task_tree(app)
              ch = get(app.TaskTree,'children');
              for i=1:length(ch)
                  delete(ch(i));
              end

              tissue_id = app.get_selected_tissue_id();
              if ~isempty(tissue_id)
                  completed_tasks = sln_tissue.Task & sprintf('tissue_id=%d',tissue_id) & sln_tissue.TaskCompleted;
                  aborted_tasks = sln_tissue.Task & sprintf('tissue_id=%d',tissue_id) & sln_tissue.TaskAborted;
                  tasks = (sln_tissue.Task & sprintf('tissue_id=%d',tissue_id)) - sln_tissue.TaskCompleted - sln_tissue.TaskAborted;
                  if tasks.exists
                      N = tasks.count;
                      tasks_struct = fetch(tasks,'*');
                      for i=1:N
                          uitreenode(app.TaskTree,'Text',tasks_struct(i).task_name);
                      end
                      app.load_tissue_table();
                  elseif completed_tasks.exists || aborted_tasks.exists %deactivate tissue because everything is complete
                      thisTissue = sln_tissue.Tissue & sprintf('tissue_id=%d',tissue_id);
                      thisTissue.deactivate();
                      app.load_tissue_table();
                  end
              end
        end
        
        function [tissue_id, generic_id] = get_selected_tissue_id(app)
            tissue_id = [];
            generic_id = [];
            tissue_selection =  get(app.TaskTable,'selection');
              if ~isempty(tissue_selection)
                  whichRow = tissue_selection(1);
              else
                  whichRow = nan;
              end
              tissue_table_data = get(app.TaskTable,'Data');

              if whichRow <= height(tissue_table_data)
                  tissue_type = tissue_table_data.Type(tissue_selection(1));
                  key.animal_id = tissue_table_data.Animal_ID(tissue_selection(1));
                  if strcmp(tissue_type,'Brain')
                    tissue_id = fetch1(sln_tissue.Tissue * sln_tissue.Brain & key, 'tissue_id');
                    generic_id = fetch1(sln_tissue.Tissue * sln_tissue.GenericBrain, 'tissue_id');
                  elseif strcmp(tissue_type,'Retina')
                      key.side = char(tissue_table_data.Side(tissue_selection(1)));
                      tissue_id = fetch1(sln_tissue.Tissue * sln_tissue.Retina & key, 'tissue_id');
                      generic_id = fetch1(sln_tissue.Tissue * sln_tissue.GenericRetina, 'tissue_id');
                  end
              else
                  disp('No tissue selected');
                  return
              end
        end
        
        function siblings = get_siblings(app, node)
            siblings = [];
            siblings_plus_me = get(get(node,'parent'),'children');
            z = 1;
            for i=1:length(siblings_plus_me)
                if ~isequal(siblings_plus_me(i),node)
                    siblings(z) = siblings_plus_me(i);
                    z=z+1;
                end
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %datajoint setup
            setenv('DJ_USER', 'sl_user')
            if isdeployed
                setenv('DJ_HOST', '127.0.0.1')
            else
                setenv('DJ_HOST', 'vfsmdatajoint01.fsm.northwestern.edu')
            end
            setenv('DJ_PASS', 'r0zsNB0xk6wc5BgA!')
            dj.conn();
            
            app.init();
        end

        % Value changed function: AnimalDropDown
        function AnimalDropDownValueChanged(app, event)
            value = app.AnimalDropDown.Value;
            if strcmp(value,'other')
                set(app.OtherAnimalEditField,'visible',true);
            else
                set(app.OtherAnimalEditField,'visible',false);
            end
        end

        % Value changed function: BrainOrRetnaTasksDropDown
        function BrainOrRetnaTasksDropDownValueChanged(app, event)
            value = app.BrainOrRetnaTasksDropDown.Value;   
        end

        % Button pushed function: AddtissueButton
        function AddtissueButtonPushed(app, event)
            selected_animal = app.AnimalDropDown.Value;
            if strcmp(selected_animal,'other')
                key.animal_id = get(app.OtherAnimalEditField,'value');
            else
                key.animal_id = str2double(extractBefore(selected_animal, ':'));
            end
            key.owner = get(app.OwnerDropDown,'value');
            
            selected_tissue_type = get(app.TissueTypeDropDown,'value');
            switch selected_tissue_type
                case 'Brain'
                    tissue_id = sln_tissue.add_tissue(key, 'Brain');
                case 'Left retina'
                    key.side = 'Left';
                    tissue_id = sln_tissue.add_tissue(key, 'Retina');
                case 'Right retina'
                    key.side = 'Right';
                    tissue_id = sln_tissue.add_tissue(key, 'Retina');
                case 'Unknown retina'
                    disp('Not implemented');
                    %TODO: this is a pain. Will need to add new eyes and
                    %such
                    %key.side = 'UnknownA';
                    %tissue_id = add_tissue(key, 'Retina');
                    tissue_id = 0;
                otherwise
                    tissue_id = 0;
            end
            if tissue_id
                thisTissue = sln_tissue.Tissue & sprintf('tissue_id=%d',tissue_id);
                thisTissue.activate();
            end
            app.load_tissue_table();
            app.load_tasks();
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            %TODO: password part
            login_success = true;
            if login_success
                app.current_user = get(app.ChangeusertoDropDown,'value');
                set(app.UserEditField, 'value', app.current_user);
                set(app.OwnerDropDown,'value',app.current_user);
                app.set_permissions();
            end
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            app.current_user = 'viewer';
            set(app.UserEditField, 'value', app.current_user);
            set(app.ChangeusertoDropDown,'value','viewer');
            app.set_permissions();
        end

        % Button pushed function: AddtaskButton
        function AddtaskButtonPushed(app, event)
              [tissue_id, generic_id] = app.get_selected_tissue_id();
              task_name = get(app.BrainOrRetnaTasksDropDown,'value');
              
              thisGenericTask = sln_tissue.Task & sprintf('task_name="%s"',task_name) & sprintf('tissue_id="%d"',generic_id);
              task_type = fetch1(thisGenericTask,'task_type');
              key = fetch(sln_tissue.(task_type) & thisGenericTask, '*');
              key.task_type = task_type;
              key.tissue_id = tissue_id;
              key
              sln_tissue.add_task(key,task_type);
              app.load_tissue_table();
              app.update_task_tree();
        end

        % Callback function
        function TaskTableSelectionChanged(app, event)
            app.load_tasks();
            app.update_task_tree();
            %keyboard;
        end

        % Window button down function: TissueDBUIFigure
        function TissueDBUIFigureWindowButtonDown(app, event)
            app.reset_logout_timer();
        end

        % Key press function: TissueDBUIFigure
        function TissueDBUIFigureKeyPress(app, event)
            key = event.Key;
            app.reset_logout_timer();
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            app.init();
        end

        % Value changed function: TasktypeDropDown
        function TasktypeDropDownValueChanged(app, event)
            value = app.TasktypeDropDown.Value;
            switch value
                case 'Analyze'
                    set(app.AnalysisoutputsTextArea,'visible',true);
                    set(app.AnalysisoutputsLabel,'visible',true)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',false);
                    set(app.ScopeDropDownLabel,'visible',false)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',false);
                    set(app.ColorchannelDropDownLabel,'visible',false);
                    set(app.BrainareaDropDown,'visible',false)
                    set(app.BrainareaDropDownLabel,'visible',false)
                    set(app.CellnumberEditField,'visible',false)
                    set(app.CellnumberEditFieldLabel,'visible',false)

                case 'ImageAxonTerminals'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',true);
                    set(app.ScopeDropDownLabel,'visible',true)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',true);
                    set(app.ColorchannelDropDownLabel,'visible',true);
                    set(app.BrainareaDropDown,'visible',true)
                    set(app.BrainareaDropDownLabel,'visible',true)
                    set(app.CellnumberEditField,'visible',false)
                    set(app.CellnumberEditFieldLabel,'visible',false)

                case 'ImageCellMorphology'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',true);
                    set(app.ScopeDropDownLabel,'visible',true)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',true);
                    set(app.ColorchannelDropDownLabel,'visible',true);
                    set(app.BrainareaDropDown,'visible',false)
                    set(app.BrainareaDropDownLabel,'visible',false)
                    set(app.CellnumberEditField,'visible',true)
                    set(app.CellnumberEditFieldLabel,'visible',true)

                case 'Slice'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',true);
                    set(app.ThicknessmEditFieldLabel,'visible',true);
                    set(app.ScopeDropDown,'visible',false);
                    set(app.ScopeDropDownLabel,'visible',false)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',false);
                    set(app.ColorchannelDropDownLabel,'visible',false);
                    set(app.BrainareaDropDown,'visible',true)
                    set(app.BrainareaDropDownLabel,'visible',true)
                    set(app.CellnumberEditField,'visible',false)
                    set(app.CellnumberEditFieldLabel,'visible',false)

                case 'Stain'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',false);
                    set(app.ScopeDropDownLabel,'visible',false)
                    set(app.Reagent1primaryDropDown,'visible',true);
                    set(app.Reagent1primaryDropDownLabel,'visible',true);
                    set(app.Reagent2secondaryDropDown,'visible',true);
                    set(app.Reagent2secondaryDropDownLabel,'visible',true);
                    set(app.ColorchannelDropDown,'visible',false);
                    set(app.ColorchannelDropDownLabel,'visible',false);
                    set(app.BrainareaDropDown,'visible',false)
                    set(app.BrainareaDropDownLabel,'visible',false)
                    set(app.CellnumberEditField,'visible',false)
                    set(app.CellnumberEditFieldLabel,'visible',false)

                case 'TraceCell'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',false);
                    set(app.ScopeDropDownLabel,'visible',false)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',false);
                    set(app.ColorchannelDropDownLabel,'visible',false);
                    set(app.BrainareaDropDown,'visible',false)
                    set(app.BrainareaDropDownLabel,'visible',false)
                    set(app.CellnumberEditField,'visible',true)
                    set(app.CellnumberEditFieldLabel,'visible',true)

                case 'TraceTerminals'
                    set(app.AnalysisoutputsTextArea,'visible',false);
                    set(app.AnalysisoutputsLabel,'visible',false)
                    set(app.ThicknessmEditField,'visible',false);
                    set(app.ThicknessmEditFieldLabel,'visible',false);
                    set(app.ScopeDropDown,'visible',false);
                    set(app.ScopeDropDownLabel,'visible',false)
                    set(app.Reagent1primaryDropDown,'visible',false);
                    set(app.Reagent1primaryDropDownLabel,'visible',false);
                    set(app.Reagent2secondaryDropDown,'visible',false);
                    set(app.Reagent2secondaryDropDownLabel,'visible',false);
                    set(app.ColorchannelDropDown,'visible',false);
                    set(app.ColorchannelDropDownLabel,'visible',false);
                    set(app.BrainareaDropDown,'visible',true)
                    set(app.BrainareaDropDownLabel,'visible',true)
                    set(app.CellnumberEditField,'visible',false)
                    set(app.CellnumberEditFieldLabel,'visible',false)
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            key = struct;
            key.task_name = get(app.TasknameEditField,'value');
            tissue_type = get(app.FortissuetypeDropDown,'value');
            if strcmp(tissue_type,'Brain')
                key.tissue_id = fetch1(sln_tissue.GenericBrain,'tissue_id');
            elseif strcmp(tissue_type,'Retina')
                key.tissue_id = fetch1(sln_tissue.GenericRetina,'tissue_id');
            end
            key.task_type = get(app.TasktypeDropDown,'value');
            switch key.task_type
                case 'Analyze'
                    outputs = get(app.AnalysisoutputsTextArea, 'value');
                    key.output_list = outputs;

                case 'ImageAxonTerminals'
                    key.target = get(app.BrainareaDropDown,'value');
                    key.color_channel_name = get(app.ColorchannelDropDown,'value');
                    key.scope_name = get(app.ScopeDropDown,'value');

                case 'ImageCellMorphology'
                    key.color_channel_name = get(app.ColorchannelDropDown,'value');
                    key.scope_name = get(app.ScopeDropDown,'value');
                    key.cell_number = get(app.CellnumberEditField,'value');

                case 'Slice'
                    key.target = get(app.BrainareaDropDown,'value');
                    key.thickness = get(app.ThicknessmEditField,'value');

                case 'Stain'
                   %TODO

                case 'TraceCell'
                    key.cell_number = get(app.CellnumberEditField,'value');

                case 'TraceTerminals'
                    key.target = get(app.BrainareaDropDown,'value');

            end
            try
                sln_tissue.add_task(key,key.task_type);
                app.load_tasks();
                set(app.StatusLamp,'color','green');
                set(app.StatusEdit,'value',sprintf('Task insert success.'));
                pause(2);
                set(app.StatusLamp,'color','white');
                set(app.StatusEdit,'value','');
            catch ME
                set(app.StatusEdit,'value',sprintf('Task insert failed: %s',ME.message));
                set(app.StatusLamp,'color','red');
            end


        end

        % Button pushed function: MoveupButton
        function MoveupButtonPushed(app, event)
            selection = get(app.TaskTree,'SelectedNodes');
            if isempty(selection)
                return;
            end
            selectedNode = selection(1);
            siblings = app.get_siblings(selectedNode);
            for i=1:length(siblings)
                set(siblings(i),'parent',selectedNode);
            end
            
        end

        % Button pushed function: MovedownButton
        function MovedownButtonPushed(app, event)
            selection = get(app.TaskTree,'SelectedNodes');
            if isempty(selection)
                return;
            end
            selectedNode = selection(1);
            children = get(selectedNode,'children');
            parentNode = get(selectedNode,'parent');
            for i=1:length(children)
                set(children(i),'parent',parentNode);
            end
        end

        % Button pushed function: CompletetaskButton
        function CompletetaskButtonPushed(app, event)
            key.tissue_id = app.get_selected_tissue_id();
            selected_task = get(app.TaskTree,'SelectedNodes');
            key.task_name = get(selected_task,'Text');
            key.user_name = app.current_user;
            notes = get(app.NotesTextArea_CompleteOrAbort,'value');
            key.task_completion_notes = strcat(cell2mat(notes'));
            key
            try
                insert(sln_tissue.TaskCompleted,key);
                disp('Task complete success');
            catch ME
                fprintf('Task complete failed: %s\n', ME.message);
            end
            app.update_task_tree();
        end

        % Button pushed function: AborttaskButton
        function AborttaskButtonPushed(app, event)
            key.tissue_id = app.get_selected_tissue_id();
            selected_task = get(app.TaskTree,'SelectedNodes');
            key.task_name = get(selected_task,'Text');
            key.user_name = app.current_user;
            notes = get(app.NotesTextArea_CompleteOrAbort,'value');
            key.reason = strcat(cell2mat(notes'));
            %TODO: also abort children of this node
            key
            try
                insert(sln_tissue.TaskAborted,key);
                disp('Task abort success');
            catch ME
                fprintf('Task abort failed: %s\n', ME.message);
            end
            app.update_task_tree();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TissueDBUIFigure = uifigure('Visible', 'off');
            app.TissueDBUIFigure.Name = 'TissueDB';
            app.TissueDBUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @TissueDBUIFigureWindowButtonDown, true);
            app.TissueDBUIFigure.KeyPressFcn = createCallbackFcn(app, @TissueDBUIFigureKeyPress, true);
            app.TaskTable = uitable(app.TissueDBUIFigure);
            app.TaskTable.ColumnName = {'Entry date'; 'Type'; 'Side'; 'Animal DJ ID'; 'Owner'; 'Unfinished tasks'};
            app.TaskTable.RowName = {};
            app.TaskTable.ColumnSortable = [true true true true true false];
            app.CreatenewtaskPanel = uipanel(app.TissueDBUIFigure);
            app.CreatenewtaskPanel.Title = 'Create new task';
            app.TasknameEditFieldLabel = uilabel(app.CreatenewtaskPanel);
            app.TasknameEditFieldLabel.Text = 'Task name';
            app.TasknameEditField = uieditfield(app.CreatenewtaskPanel, 'text');
            app.TasktypeDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.TasktypeDropDownLabel.Text = 'Task type';
            app.TasktypeDropDown = uidropdown(app.CreatenewtaskPanel);
            app.TasktypeDropDown.Items = {'Analyze', 'ImageAxonTerminals', 'ImageCellMorphology', 'Slice', 'Stain', 'TraceCell', 'TraceTerminals'};
            app.TasktypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TasktypeDropDownValueChanged, true);
            app.TasktypeDropDown.Value = 'Analyze';
            app.Reagent2secondaryDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.Reagent2secondaryDropDownLabel.Text = 'Reagent 2 / secondary';
            app.Reagent2secondaryDropDown = uidropdown(app.CreatenewtaskPanel);
            app.ScopeDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.ScopeDropDownLabel.Text = 'Scope';
            app.ScopeDropDown = uidropdown(app.CreatenewtaskPanel);
            app.CreateButton = uibutton(app.CreatenewtaskPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.AnalysisoutputsLabel = uilabel(app.CreatenewtaskPanel);
            app.AnalysisoutputsLabel.Text = 'Analysis outputs:';
            app.AnalysisoutputsTextArea = uitextarea(app.CreatenewtaskPanel);
            app.FortissuetypeDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.FortissuetypeDropDownLabel.Text = 'For tissue type';
            app.FortissuetypeDropDown = uidropdown(app.CreatenewtaskPanel);
            app.FortissuetypeDropDown.Items = {'Retina', 'Brain'};
            app.FortissuetypeDropDown.Value = 'Retina';
            app.ColorchannelDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.ColorchannelDropDownLabel.Text = 'Color channel';
            app.ColorchannelDropDown = uidropdown(app.CreatenewtaskPanel);
            app.ThicknessmEditFieldLabel = uilabel(app.CreatenewtaskPanel);
            app.ThicknessmEditFieldLabel.Text = 'Thickness (μm)';
            app.ThicknessmEditField = uieditfield(app.CreatenewtaskPanel, 'numeric');
            app.ThicknessmEditField.Limits = [0 1000];
            app.Reagent1primaryDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.Reagent1primaryDropDownLabel.Text = 'Reagent 1 / primary';
            app.Reagent1primaryDropDown = uidropdown(app.CreatenewtaskPanel);
            app.BrainareaDropDownLabel = uilabel(app.CreatenewtaskPanel);
            app.BrainareaDropDownLabel.Text = 'Brain area';
            app.BrainareaDropDown = uidropdown(app.CreatenewtaskPanel);
            app.CellnumberEditFieldLabel = uilabel(app.CreatenewtaskPanel);
            app.CellnumberEditFieldLabel.Text = 'Cell number';
            app.CellnumberEditField = uieditfield(app.CreatenewtaskPanel, 'numeric');
            app.CellnumberEditField.Limits = [0 Inf];
            app.StatusLamp = uilamp(app.CreatenewtaskPanel);
            app.StatusEdit = uieditfield(app.CreatenewtaskPanel, 'text');
            app.StatusEdit.Editable = 'off';
            app.AddtaskButton = uibutton(app.TissueDBUIFigure, 'push');
            app.AddtaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddtaskButtonPushed, true);
            app.AddtaskButton.Text = 'Add task';
            app.CompletetaskButton = uibutton(app.TissueDBUIFigure, 'push');
            app.CompletetaskButton.ButtonPushedFcn = createCallbackFcn(app, @CompletetaskButtonPushed, true);
            app.CompletetaskButton.Text = 'Complete task';
            app.NotesTextAreaLabel = uilabel(app.TissueDBUIFigure);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea_CompleteOrAbort = uitextarea(app.TissueDBUIFigure);
            app.ChangeusertoDropDownLabel = uilabel(app.TissueDBUIFigure);
            app.ChangeusertoDropDownLabel.Text = 'Change user to';
            app.ChangeusertoDropDown = uidropdown(app.TissueDBUIFigure);
            app.UserEditFieldLabel = uilabel(app.TissueDBUIFigure);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.TissueDBUIFigure, 'text');
            app.UserEditField.Editable = 'off';
            app.PasswordEditFieldLabel = uilabel(app.TissueDBUIFigure);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.TissueDBUIFigure, 'text');
            app.LoginButton = uibutton(app.TissueDBUIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.BrainOrRetnaTasksDropDown = uidropdown(app.TissueDBUIFigure);
            app.BrainOrRetnaTasksDropDown.ValueChangedFcn = createCallbackFcn(app, @BrainOrRetnaTasksDropDownValueChanged, true);
            app.LogoutButton = uibutton(app.TissueDBUIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.AddtissueButton = uibutton(app.TissueDBUIFigure, 'push');
            app.AddtissueButton.ButtonPushedFcn = createCallbackFcn(app, @AddtissueButtonPushed, true);
            app.AddtissueButton.Text = 'Add tissue';
            app.TissueTypeDropDown = uidropdown(app.TissueDBUIFigure);
            app.TissueTypeDropDown.Items = {'Left retina', 'Right retina', 'Unknown retina', 'Brain'};
            app.TissueTypeDropDown.Value = 'Left retina';
            app.AnimalDropDownLabel = uilabel(app.TissueDBUIFigure);
            app.AnimalDropDownLabel.Text = 'Animal';
            app.AnimalDropDown = uidropdown(app.TissueDBUIFigure);
            app.AnimalDropDown.ValueChangedFcn = createCallbackFcn(app, @AnimalDropDownValueChanged, true);
            app.OtherAnimalEditField = uieditfield(app.TissueDBUIFigure, 'numeric');
            app.ReloadButton = uibutton(app.TissueDBUIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.TaskTree = uitree(app.TissueDBUIFigure);
            app.MoveupButton = uibutton(app.TissueDBUIFigure, 'push');
            app.MoveupButton.ButtonPushedFcn = createCallbackFcn(app, @MoveupButtonPushed, true);
            app.MoveupButton.Text = 'Move up';
            app.MovedownButton = uibutton(app.TissueDBUIFigure, 'push');
            app.MovedownButton.ButtonPushedFcn = createCallbackFcn(app, @MovedownButtonPushed, true);
            app.MovedownButton.Text = 'Move down';
            app.AborttaskButton = uibutton(app.TissueDBUIFigure, 'push');
            app.AborttaskButton.ButtonPushedFcn = createCallbackFcn(app, @AborttaskButtonPushed, true);
            app.AborttaskButton.Text = 'Abort task';
            app.OwnerDropDownLabel = uilabel(app.TissueDBUIFigure);
            app.OwnerDropDownLabel.Text = 'Owner';
            app.OwnerDropDown = uidropdown(app.TissueDBUIFigure);
            % Show the figure after all components are created
            app.TissueDBUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TissueDB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TissueDBUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TissueDBUIFigure)
        end
    end
end